classdef SLM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        CurrentregionDropDownLabel      matlab.ui.control.Label
        ActivateSLMButton               matlab.ui.control.StateButton
        ActivateSLMLamp                 matlab.ui.control.Lamp
        InitializeDAQButton             matlab.ui.control.Button
        InitializeDAQLamp               matlab.ui.control.Lamp
        ApplyXYZcalibrationButton       matlab.ui.control.StateButton
        XYZcalibrationLamp              matlab.ui.control.Lamp
        ApplyAOcorrectionButton         matlab.ui.control.StateButton
        InitializeAOLamp                matlab.ui.control.Lamp
        ApplyPWcorrectionButton         matlab.ui.control.StateButton
        PWcorrectionLamp                matlab.ui.control.Lamp
        ApplyZOsuppressionButton        matlab.ui.control.StateButton
        ZOsuppressionLamp               matlab.ui.control.Lamp
        CurrentregionDropDown           matlab.ui.control.DropDown
        BlankSLMButton                  matlab.ui.control.Button
        ViewcurrentphaseButton          matlab.ui.control.Button
        ViewcurrentAOButton             matlab.ui.control.Button
        ViewcurrentlutButton            matlab.ui.control.Button
        SavecurrentlutButton            matlab.ui.control.Button
        CloseallfigsButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        SLMtypeDropDown                 matlab.ui.control.DropDown
        LUTDropDown                     matlab.ui.control.DropDown
        SLMheightEditField              matlab.ui.control.NumericEditField
        SLMwidthEditField               matlab.ui.control.NumericEditField
        TubelengthEditFieldLabel        matlab.ui.control.Label
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        TubelengthEditField             matlab.ui.control.NumericEditField
        GenXYZpatmethodDropDown         matlab.ui.control.DropDown
        ImageGenverEditField            matlab.ui.control.EditField
        ImageGenverEditFieldLabel       matlab.ui.control.Label
        GSnumiterationsEditField        matlab.ui.control.NumericEditField
        GSzfactorEditField              matlab.ui.control.NumericEditField
        GSzfactorEditFieldLabel         matlab.ui.control.Label
        GSnumiterationsEditFieldLabel   matlab.ui.control.Label
        GenXYZpatmethodDropDownLabel    matlab.ui.control.Label
        ObjectiveDropDownLabel          matlab.ui.control.Label
        SLMtypeDropDownLabel            matlab.ui.control.Label
        NIDAQdeviceEditField            matlab.ui.control.EditField
        DAQcounterchannelEditField      matlab.ui.control.NumericEditField
        DAQAIchannelEditField           matlab.ui.control.NumericEditField
        DAQAOchannelEditField           matlab.ui.control.NumericEditField
        ObjectiveDropDown               matlab.ui.control.DropDown
        FOVsizeumEditFieldLabel         matlab.ui.control.Label
        AOcorrZtoleranceEditFieldLabel  matlab.ui.control.Label
        DAQAOchannelEditFieldLabel      matlab.ui.control.Label
        ZoomEditFieldLabel              matlab.ui.control.Label
        FOVsizeumEditField              matlab.ui.control.NumericEditField
        ZoomEditField                   matlab.ui.control.NumericEditField
        xpixelsEditField                matlab.ui.control.NumericEditField
        ypixelsEditField                matlab.ui.control.NumericEditField
        ypixelsEditFieldLabel           matlab.ui.control.Label
        xpixelsEditFieldLabel           matlab.ui.control.Label
        LUTDropDownLabel                matlab.ui.control.Label
        DAQAIchannelEditFieldLabel      matlab.ui.control.Label
        DAQcounterchannelEditFieldLabel  matlab.ui.control.Label
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        SLMheightEditFieldLabel         matlab.ui.control.Label
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        AOcorrZtoleranceEditField       matlab.ui.control.NumericEditField
        LoadcalibrationsButton          matlab.ui.control.Button
        LoadopsButton                   matlab.ui.control.Button
        SaveopsButton                   matlab.ui.control.Button
        SLMregioneditorPanel            matlab.ui.container.Panel
        SelectRegionDropDown            matlab.ui.control.DropDown
        ZOsuppWEditFieldLabel           matlab.ui.control.Label
        ZOsuppphaseEditFieldLabel       matlab.ui.control.Label
        SaveregButton                   matlab.ui.control.Button
        AddregButton                    matlab.ui.control.Button
        DeleteregButton                 matlab.ui.control.Button
        RegionnameEditField             matlab.ui.control.EditField
        regionheightLabel               matlab.ui.control.Label
        regionheightminEditField        matlab.ui.control.NumericEditField
        regionheightmaxEditField        matlab.ui.control.NumericEditField
        regionwidthLabel                matlab.ui.control.Label
        regionwidthminEditField         matlab.ui.control.NumericEditField
        regionwidthmaxEditField         matlab.ui.control.NumericEditField
        regionWavelengthnmEditField     matlab.ui.control.NumericEditField
        regionEffectiveNAEditField      matlab.ui.control.NumericEditField
        regionPhaseDiameterEditField    matlab.ui.control.NumericEditField
        PhasediameterpixLabel           matlab.ui.control.Label
        BeamdumpYEditFieldLabel         matlab.ui.control.Label
        BeamdumpXEditFieldLabel         matlab.ui.control.Label
        regionZerooutsidephasediameterCheckBox  matlab.ui.control.CheckBox
        regionBeamDiameterEditField     matlab.ui.control.NumericEditField
        LUTcorrectionDropDown           matlab.ui.control.DropDown
        XYZaffinetransformDropDown      matlab.ui.control.DropDown
        AOcorrectionDropDown            matlab.ui.control.DropDown
        PointweightcorrectionDropDown   matlab.ui.control.DropDown
        PointweightcorrectionDropDownLabel  matlab.ui.control.Label
        BeamxyzandaxialcorrectionsLabel  matlab.ui.control.Label
        XoffsetEditField                matlab.ui.control.NumericEditField
        YoffsetEditField                matlab.ui.control.NumericEditField
        ZoffsetEditField                matlab.ui.control.NumericEditField
        XZcorrEditField                 matlab.ui.control.NumericEditField
        YZcorrEditField                 matlab.ui.control.NumericEditField
        YZcorrEditFieldLabel            matlab.ui.control.Label
        XZcorrEditFieldLabel            matlab.ui.control.Label
        ZoffsetEditFieldLabel           matlab.ui.control.Label
        YoffsetEditFieldLabel           matlab.ui.control.Label
        XoffsetEditFieldLabel           matlab.ui.control.Label
        BeamdiameterpixLabel            matlab.ui.control.Label
        EffectiveNALabel                matlab.ui.control.Label
        AOcorrectionDropDownLabel       matlab.ui.control.Label
        XYZaffinetransformLabel         matlab.ui.control.Label
        maxEditField_2Label             matlab.ui.control.Label
        minEditField_2Label             matlab.ui.control.Label
        maxEditFieldLabel               matlab.ui.control.Label
        minEditFieldLabel               matlab.ui.control.Label
        LUTcorrectionLabel              matlab.ui.control.Label
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        RegionnameEditFieldLabel        matlab.ui.control.Label
        SelectregionDropDownLabel       matlab.ui.control.Label
        ZOsuppphaseEditField            matlab.ui.control.NumericEditField
        ZOsuppWEditField                matlab.ui.control.NumericEditField
        BeamdumpXEditField              matlab.ui.control.NumericEditField
        BeamdumpYEditField              matlab.ui.control.NumericEditField
        XYZpatternsTab                  matlab.ui.container.Tab
        XYsmoothstdpixEditFieldLabel    matlab.ui.control.Label
        pointsizepixEditFieldLabel      matlab.ui.control.Label
        GroupnameEditFieldLabel         matlab.ui.control.Label
        PatterngroupDropDownLabel       matlab.ui.control.Label
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        PatternSpinnerLabel             matlab.ui.control.Label
        PatterngroupDropDown            matlab.ui.control.DropDown
        GroupnameEditField              matlab.ui.control.EditField
        AddgroupButton                  matlab.ui.control.Button
        DeletegroupButton               matlab.ui.control.Button
        SavegroupButton                 matlab.ui.control.Button
        CreatecustompointhologramPanel  matlab.ui.container.Panel
        PatternnumberEditField          matlab.ui.control.EditField
        WeightEditFieldLabel            matlab.ui.control.Label
        ZoffsetumEditFieldLabel         matlab.ui.control.Label
        XYdisplacementLabel             matlab.ui.control.Label
        XdisplacementEditField          matlab.ui.control.EditField
        YdisplacementEditField          matlab.ui.control.EditField
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        PatternnumberEditFieldLabel     matlab.ui.control.Label
        DefocusLabel                    matlab.ui.control.Label
        ZoffsetumEditField              matlab.ui.control.EditField
        PointweightLabel                matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.EditField
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        UploadHologramButton            matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        UploadselectedButton            matlab.ui.control.Button
        RemoveselectedButton            matlab.ui.control.Button
        ViewselectedphaseButton         matlab.ui.control.Button
        ViewselectedfftButton           matlab.ui.control.Button
        ClearallButton                  matlab.ui.control.Button
        PatternSpinner                  matlab.ui.control.Spinner
        UploadpatternButton             matlab.ui.control.Button
        ViewpatternphaseButton          matlab.ui.control.Button
        ViewpatternfftButton            matlab.ui.control.Button
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        ViewpredictedPSFButton          matlab.ui.control.Button
        SimulatepixelcrosstalkCheckBox  matlab.ui.control.CheckBox
        XYsmoothstdpixEditField         matlab.ui.control.EditField
        UpdatepowerButton               matlab.ui.control.Button
        correctweightwithinpatternButton  matlab.ui.control.Button
        pointsizepixEditField           matlab.ui.control.NumericEditField
        correctweightacrosspatternsButton  matlab.ui.control.Button
        patterneditorButton             matlab.ui.control.Button
        SavecurrentXYZButton            matlab.ui.control.Button
        UIImagePhaseTable               matlab.ui.control.Table
        ScanTab                         matlab.ui.container.Tab
        GroupscanPanel                  matlab.ui.container.Panel
        CustomstimprotocolPanel         matlab.ui.container.Panel
        patternorderDropDown            matlab.ui.control.DropDown
        PlotexamplestimtraceButton      matlab.ui.control.Button
        initialdelayEditField           matlab.ui.control.NumericEditField
        initialdelayEditFieldLabel      matlab.ui.control.Label
        patterndurationEditField        matlab.ui.control.NumericEditField
        patternintervalEditField        matlab.ui.control.NumericEditField
        patternrepeatsEditField         matlab.ui.control.NumericEditField
        patternintervaljitterEditField  matlab.ui.control.NumericEditField
        patternintervaljitterEditFieldLabel  matlab.ui.control.Label
        patternintervalEditFieldLabel   matlab.ui.control.Label
        patterndurationEditFieldLabel   matlab.ui.control.Label
        patternorderDropDownLabel       matlab.ui.control.Label
        patternrepeatsEditFieldLabel    matlab.ui.control.Label
        StimulationAIinputVpatternnumPanel  matlab.ui.container.Panel
        stimcontrolsourceButtonGroup    matlab.ui.container.ButtonGroup
        AIinputButton                   matlab.ui.control.ToggleButton
        CustomstimButton                matlab.ui.control.ToggleButton
        PatternDropDownAI               matlab.ui.control.DropDown
        PatterngroupLabel_2             matlab.ui.control.Label
        ImagingEOFcounterchantriggeredPanel  matlab.ui.container.Panel
        NumVolumesEditFieldLabel        matlab.ui.control.Label
        PatternDropDownCtr              matlab.ui.control.DropDown
        PatterngroupLabel               matlab.ui.control.Label
        NumVolumesEditField             matlab.ui.control.NumericEditField
        InitializeimagingButton         matlab.ui.control.StateButton
        ImagingReadyLamp                matlab.ui.control.Lamp
        ScanwithSLMtriggersCheckBox     matlab.ui.control.CheckBox
        PlotSLMupdateratesCheckBox      matlab.ui.control.CheckBox
        GenerateHologramTab             matlab.ui.container.Tab
        UploadHologramButtonGenerate    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        PistonTab                       matlab.ui.container.Tab
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        PixelValueLabel                 matlab.ui.control.Label
        GeneratepistonButton            matlab.ui.control.Button
        BlazedGratingTab                matlab.ui.container.Tab
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        BlazReverseCheckBox             matlab.ui.control.CheckBox
        PeriodEditFieldLabel            matlab.ui.control.Label
        GenerateBlazedGratingButton     matlab.ui.control.Button
        StripesTab                      matlab.ui.container.Tab
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        StripeGrayEditField             matlab.ui.control.NumericEditField
        GrayEditFieldLabel              matlab.ui.control.Label
        PixelValueEditFieldLabel        matlab.ui.control.Label
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        HorizontalstripeCheckBox        matlab.ui.control.CheckBox
        PixelsPerStripeLabel            matlab.ui.control.Label
        GenerateStripesButton           matlab.ui.control.Button
        DefocusTab                      matlab.ui.container.Tab
        DeficusWeightEditField          matlab.ui.control.NumericEditField
        DefocusdistumLabel              matlab.ui.control.Label
        GeneratedefocusButton           matlab.ui.control.Button
        CrossTab                        matlab.ui.container.Tab
        CrossPixelValueEditField        matlab.ui.control.NumericEditField
        CrossGrayEditField              matlab.ui.control.NumericEditField
        CrossAngleEditField             matlab.ui.control.NumericEditField
        AngleradLabel                   matlab.ui.control.Label
        GrayEditFieldLabel_2            matlab.ui.control.Label
        PixelValueEditFieldLabel_2      matlab.ui.control.Label
        GenerateCrossButton             matlab.ui.control.Button
        FresnelLensTab                  matlab.ui.container.Tab
        FresCenterXEditField            matlab.ui.control.NumericEditField
        FresCenterYEditField            matlab.ui.control.NumericEditField
        FresRadiusEditField             matlab.ui.control.NumericEditField
        FresPowerEditField              matlab.ui.control.NumericEditField
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        PowerEditFieldLabel             matlab.ui.control.Label
        RadiusEditFieldLabel            matlab.ui.control.Label
        CenterYEditFieldLabel           matlab.ui.control.Label
        CenterXEditFieldLabel           matlab.ui.control.Label
        GenerateFresnelLensButton       matlab.ui.control.Button
        ZernikeTab                      matlab.ui.container.Tab
        CenterXEditField                matlab.ui.control.NumericEditField
        CenterYEditField                matlab.ui.control.NumericEditField
        RadiusEditField                 matlab.ui.control.NumericEditField
        PistonEditField                 matlab.ui.control.NumericEditField
        TiltXEditField                  matlab.ui.control.NumericEditField
        TiltYEditField                  matlab.ui.control.NumericEditField
        PowerEditField                  matlab.ui.control.NumericEditField
        AstigXEditField                 matlab.ui.control.NumericEditField
        AstigYEditField                 matlab.ui.control.NumericEditField
        ComaXEditField                  matlab.ui.control.NumericEditField
        ComaYEditField                  matlab.ui.control.NumericEditField
        PrimarySphericalEditField       matlab.ui.control.NumericEditField
        TrefoilXEditField               matlab.ui.control.NumericEditField
        TrefoilYEditField               matlab.ui.control.NumericEditField
        SecondaryAstigXEditField        matlab.ui.control.NumericEditField
        SecondaryAstigYEditField        matlab.ui.control.NumericEditField
        SecondaryComaXEditField         matlab.ui.control.NumericEditField
        SecondaryComaYEditField         matlab.ui.control.NumericEditField
        SecondarySphericalEditField     matlab.ui.control.NumericEditField
        TetrafoilXEditField             matlab.ui.control.NumericEditField
        TetrafoilYEditField             matlab.ui.control.NumericEditField
        TertiarySphericalEditField      matlab.ui.control.NumericEditField
        QuaternarySphericalEditField    matlab.ui.control.NumericEditField
        QuaternarySphericalEditFieldLabel  matlab.ui.control.Label
        TertiarySphericalEditFieldLabel  matlab.ui.control.Label
        TetrafoilYEditFieldLabel        matlab.ui.control.Label
        TetrafoilXEditFieldLabel        matlab.ui.control.Label
        SecondarySphericalEditFieldLabel  matlab.ui.control.Label
        SecondaryComaYEditFieldLabel    matlab.ui.control.Label
        SecondaryComaXEditFieldLabel    matlab.ui.control.Label
        SecondaryAstigYEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigXEditFieldLabel   matlab.ui.control.Label
        TrefoilYEditFieldLabel          matlab.ui.control.Label
        TrefoilXEditFieldLabel          matlab.ui.control.Label
        PrimarySphericalEditFieldLabel  matlab.ui.control.Label
        ComaYEditFieldLabel             matlab.ui.control.Label
        ComaXEditFieldLabel             matlab.ui.control.Label
        AstigYEditFieldLabel            matlab.ui.control.Label
        AstigXEditFieldLabel            matlab.ui.control.Label
        PowerEditFieldLabel_2           matlab.ui.control.Label
        TiltYEditFieldLabel             matlab.ui.control.Label
        TiltXEditFieldLabel             matlab.ui.control.Label
        PistonEditFieldLabel            matlab.ui.control.Label
        RadiusEditFieldLabel_2          matlab.ui.control.Label
        CenterYEditField_5Label         matlab.ui.control.Label
        CenterXEditField_2Label         matlab.ui.control.Label
        GenerateZernikeButton           matlab.ui.control.Button
        CustomphaseTab                  matlab.ui.container.Tab
        ImagepathEditField              matlab.ui.control.EditField
        BrowseimageButton               matlab.ui.control.Button
        LoadimageButton                 matlab.ui.control.Button
        PhaseformatDropDown             matlab.ui.control.DropDown
        PhaseformatDropDownLabel        matlab.ui.control.Label
        ImagepathEditFieldLabel         matlab.ui.control.Label
        UIAxesColorLimits               matlab.ui.control.UIAxes
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        AdaptiveOpticsmodalTab          matlab.ui.container.Tab
        TestHologramandweightsPanel     matlab.ui.container.Panel
        ModeindexEditField              matlab.ui.control.NumericEditField
        weightEditField                 matlab.ui.control.NumericEditField
        weightEditFieldLabel            matlab.ui.control.Label
        ModeindexEditFieldLabel         matlab.ui.control.Label
        UploadHologramButton_2          matlab.ui.control.Button
        PlotHologramButton              matlab.ui.control.Button
        FillzerniketablePanel           matlab.ui.container.Panel
        MinZnEditField                  matlab.ui.control.NumericEditField
        MinZnEditFieldLabel             matlab.ui.control.Label
        MaxZnEditField                  matlab.ui.control.NumericEditField
        WeightlimitEditField            matlab.ui.control.NumericEditField
        WeightstepEditField             matlab.ui.control.NumericEditField
        AOignoresphericalmodeCheckBox   matlab.ui.control.CheckBox
        WeightstepEditFieldLabel        matlab.ui.control.Label
        BeadwindowsizeEditField         matlab.ui.control.NumericEditField
        PostscandelayEditField          matlab.ui.control.NumericEditField
        StartoptimizationscanButton     matlab.ui.control.Button
        NumscanframesOpEditField        matlab.ui.control.NumericEditField
        FillmodestableButton            matlab.ui.control.Button
        ZernikeModestoscanadjustweightsifneededLabel  matlab.ui.control.Label
        SavefiletagEditField            matlab.ui.control.EditField
        ShufflemodesCheckBox            matlab.ui.control.CheckBox
        NumiterationsSpinner            matlab.ui.control.Spinner
        PlotprogressCheckBox            matlab.ui.control.CheckBox
        PlotextradeetsCheckBox          matlab.ui.control.CheckBox
        ZernikeListTable                matlab.ui.control.Table
        ParamsPanel                     matlab.ui.container.Panel
        ScanspermodeEditField           matlab.ui.control.NumericEditField
        ScanspermodeEditFieldLabel      matlab.ui.control.Label
        SavefiletagEditFieldLabel       matlab.ui.control.Label
        WeightlimitEditFieldLabel       matlab.ui.control.Label
        MaxZnEditFieldLabel             matlab.ui.control.Label
        AOautooptimizationscanPanel     matlab.ui.container.Panel
        DecresegradntimesEditFieldLabel  matlab.ui.control.Label
        ScanallcorreverynframesEditFieldLabel  matlab.ui.control.Label
        AOregularscanzernikepolPanel    matlab.ui.container.Panel
        ScanZernikeButton               matlab.ui.control.StateButton
        ZernikeReadyLamp                matlab.ui.control.Lamp
        NumscanframesEditField          matlab.ui.control.NumericEditField
        NumscanframesLabel              matlab.ui.control.Label
        NumscanvolumesEditField         matlab.ui.control.NumericEditField
        NumscanvolumesEditFieldLabel    matlab.ui.control.Label
        RefocuseverynframesEditFieldLabel  matlab.ui.control.Label
        OptimizationmethodDropDownLabel  matlab.ui.control.Label
        PostscandelayEditFieldLabel     matlab.ui.control.Label
        BeadwindowsizeEditFieldLabel    matlab.ui.control.Label
        NumscanframesLabel_2            matlab.ui.control.Label
        ScanframesdirpathEditFieldLabel  matlab.ui.control.Label
        NumiterationsSpinnerLabel       matlab.ui.control.Label
        ScanframesdirpathEditField      matlab.ui.control.EditField
        OptimizationmethodDropDown      matlab.ui.control.DropDown
        RefocuseverynframesEditField    matlab.ui.control.NumericEditField
        ScanallcorreverynframesEditField  matlab.ui.control.NumericEditField
        DecresegradntimesEditField      matlab.ui.control.NumericEditField
        CalibrationTab                  matlab.ui.container.Tab
        AOPanel                         matlab.ui.container.Panel
        PlotAOcorretionsButton          matlab.ui.control.Button
        modestofitEditField             matlab.ui.control.EditField
        modestofitEditFieldLabel        matlab.ui.control.Label
        constrainz0CheckBox             matlab.ui.control.CheckBox
        save_fit_weightsCheckBox        matlab.ui.control.CheckBox
        PointWeightcorrectionPanel      matlab.ui.container.Panel
        PWsmoothstdEditField            matlab.ui.control.NumericEditField
        PWmincorrthreshEditField        matlab.ui.control.NumericEditField
        MincorrthreshEditFieldLabel     matlab.ui.control.Label
        PWsqrt2pCheckBox                matlab.ui.control.CheckBox
        smoothstdEditFieldLabel         matlab.ui.control.Label
        PlotpwcorrectionButton          matlab.ui.control.Button
        LateralcalibrationButton        matlab.ui.control.Button
        LUTcalibrationdataPanel         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        TLDCcamButton                   matlab.ui.control.ToggleButton
        EndofframeButton                matlab.ui.control.ToggleButton
        TotalframesEditField            matlab.ui.control.NumericEditField
        TotalframesEditFieldLabel       matlab.ui.control.Label
        BaselinepixelvalueEditField     matlab.ui.control.NumericEditField
        BaselinepixelvalueEditFieldLabel  matlab.ui.control.Label
        RunLUTcalibReadyLamp            matlab.ui.control.Lamp
        AnalyzeLUTdataButton            matlab.ui.control.Button
        RunLUTcalibrationButton         matlab.ui.control.StateButton
        AnalysissavefilenameEditField   matlab.ui.control.EditField
        AnalysissavefilenameEditFieldLabel  matlab.ui.control.Label
        PixelsPerStripeEditField        matlab.ui.control.NumericEditField
        PixelsPerStripeEditFieldLabel   matlab.ui.control.Label
        NumRegionsEditField             matlab.ui.control.NumericEditField
        NumRegionsEditFieldLabel        matlab.ui.control.Label
        BitDepthEditField               matlab.ui.control.NumericEditField
        BitDepthEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        SLM_ops; % Description
        region_list;
        xyz_patterns;
        region_obj_params;
        lut_list;
        lut_corrections_list;
        GUI_buffer;
        SLM_phase;
        SLM_phase_corr;
        SLM_phase_corr_lut;
        SLM_image_pointer;
        SLM_gh_phase_preview;
        SLM_image_gh_climits;
        GUI_ops;
        current_SLM_coord;
        current_SLM_AO_Image = [];
        SLM_blank_phase;
        SLM_blank_pointer;
        SLM_phase_plot;
        hologram_fig_plot;
        UIImagePhaseTableSelection;
        DAQ_session;
        DAQ_ai_chan;
        AO_ignore_modes_list = [1, 2, 3];
        AO_last_zernike_data = cell(2,1);
        AO_last_LUT_data = cell(2,1);
        AO_correction_data = [];
        fov_im;
        pattern_editor_data;
        pp_ops;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GUI_dir = fileparts(mfilename('fullpath'));
            %pwd2 = fileparts(which('SLM_control_GUI.mlapp'));
            
            addpath(GUI_dir);
            if exist([GUI_dir '\SLM_GUI_funcions'], 'dir')
                addpath(genpath([GUI_dir '\SLM_GUI_funcions']));
                %addpath([pwd2 '\SLM_GUI_funcions']);
            else
                error('RAFA: You need to move to SLM_microscope_GUI directory and reopen GUI!!!');
            end 
            f_sg_gui_startup(app, GUI_dir);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f_sg_gui_close(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            f_sg_button_activate_SLM(app);
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            f_sg_initialize_DAQ(app);
        end

        % Value changed function: ApplyXYZcalibrationButton
        function ApplyXYZcalibrationButtonValueChanged(app, event)
            f_sg_apply_xyz_calibration(app);
        end

        % Value changed function: ApplyAOcorrectionButton
        function ApplyAOcorrectionButtonValueChanged(app, event)
            f_sg_apply_AO_correction_button(app);
        end

        % Value changed function: ApplyPWcorrectionButton
        function ApplyPWcorrectionButtonValueChanged(app, event)
            f_sg_apply_PW_correction_button(app);
        end

        % Value changed function: ApplyZOsuppressionButton
        function ApplyZOsuppressionButtonValueChanged(app, event)
            f_sg_apply_ZO_suppression_button(app);
        end

        % Button pushed function: BlankSLMButton
        function BlankSLMButtonPushed(app, event)
            f_sg_button_blank_display(app);
        end

        % Button pushed function: GeneratepistonButton
        function GenerateBlankLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'piston');
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'fresnel');
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'blazed');
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'stripes');
        end

        % Button pushed function: GenerateZernikeButton
        function GenerateZernikeButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'zernike');
        end

        % Button pushed function: GeneratedefocusButton
        function GeneratedefocusButtonPushed(app, event)
            f_sg_gh_defocus(app);
        end

        % Button pushed function: GenerateCrossButton
        function GenerateCrossButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'cross');
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            f_sg_gh_upload_holo(app);
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_view(app, 'custom', 'phase')
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_upload(app, 'custom')
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            f_sg_xyz_button_add_to_holo(app);
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'table_selection', 'phase')
        end

        % Button pushed function: ViewselectedfftButton
        function ViewselectedfftButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'table_selection', 'fft')
        end

        % Button pushed function: UploadselectedButton
        function UploadselectedButtonPushed(app, event)
            f_sg_xyz_button_upload(app, 'table_selection')
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            f_sg_xyz_button_remove_selected(app);
        end

        % Button pushed function: ViewpatternphaseButton
        function ViewpatternphaseButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'pattern', 'phase')
        end

        % Button pushed function: ViewpatternfftButton
        function ViewpatternfftButtonPushed(app, event)
            f_sg_xyz_button_view(app, 'pattern', 'fft')
        end

        % Button pushed function: UploadpatternButton
        function UploadpatternButtonPushed(app, event)
            f_sg_xyz_button_upload(app, 'pattern')
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            f_sg_scan_initialize_imaging(app);
        end

        % Callback function
        function ScanZernikeButtonPushed(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Button pushed function: FillmodestableButton
        function FillmodestableButtonPushed(app, event)
            f_sg_AO_fill_modes_table(app);
        end

        % Value changed function: ScanspermodeEditField
        function ScanspermodeEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Callback function
        function ScansperVolZEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: PlotHologramButton
        function PlotHologramButtonPushed(app, event)
            [phase, n, m] = f_sg_AO_gen_test_hologram(app);
            f_sg_plot_phase_figure(phase, sprintf('Zernike Hologram %d, w=%d; Znm n=%d; m=%d',app.ModeindexEditField.Value,app.weightEditField.Value, n, m));
        end

        % Button pushed function: UploadHologramButton_2
        function UploadHologramButton_2Pushed(app, event)
            phase = f_sg_AO_gen_test_hologram(app);
            app.SLM_image_pointer.Value = f_sg_im_to_pointer(phase);
            f_SLM_update(app.SLM_ops, app.SLM_image_pointer);
        end

        % Value changed function: ScanZernikeButton
        function ScanZernikeButtonValueChanged(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Cell edit callback: ZernikeListTable
        function ZernikeListTableCellEdit(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Callback function
        function ViewAOphaseButtonPushed(app, event)
            f_sg_view_hologram_phase(app, app.SLM_AO_Image);
        end

        % Value changed function: RunLUTcalibrationButton
        function RunLUTcalibrationButtonValueChanged(app, event)
            f_sg_LUT_run_calibration(app);
        end

        % Value changed function: BitDepthEditField
        function BitDepthEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: NumRegionsEditField
        function NumRegionsEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: LUTcorrectionDropDown
        function LUTcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            f_sg_reg_update(app);
        end

        % Button pushed function: SaveregButton
        function SaveregButtonPushed(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: AddregButton
        function AddregButtonPushed(app, event)
            f_sg_reg_add(app);
        end

        % Button pushed function: DeleteregButton
        function DeleteregButtonPushed(app, event)
            f_sg_reg_delete(app);
        end

        % Button pushed function: SavegroupButton
        function SavegroupButtonPushed(app, event)
            f_sg_pat_save(app);
        end

        % Value changed function: PatterngroupDropDown
        function PatterngroupDropDownValueChanged(app, event)
            f_sg_pat_update(app);
        end

        % Button pushed function: DeletegroupButton
        function DeletegroupButtonPushed(app, event)
            f_sg_pat_delete(app);
        end

        % Button pushed function: AddgroupButton
        function AddgroupButtonPushed(app, event)
            f_sg_pat_add(app);
        end

        % Callback function
        function GroupRegionDropDownValueChanged(app, event)
            f_sg_xyz_pat_update_reg(app);
        end

        % Button pushed function: LoadopsButton
        function LoadopsButtonPushed(app, event)
            f_sg_ops_load(app);
        end

        % Button pushed function: SaveopsButton
        function SaveopsButtonPushed(app, event)
            f_sg_ops_save(app);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            f_sg_lut_update_list(app);
        end

        % Value changed function: AOcorrectionDropDown
        function AOcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: ViewcurrentphaseButton
        function ViewcurrentphaseButtonPushed(app, event)
            f_sg_view_current_holo(app);
        end

        % Button pushed function: ViewcurrentAOButton
        function ViewcurrentAOButtonPushed(app, event)
            f_sg_AO_plot_current_correction(app);
        end

        % Button pushed function: ViewcurrentlutButton
        function ViewcurrentlutButtonPushed(app, event)
            f_sg_view_current_lut(app);
        end

        % Button pushed function: LoadcalibrationsButton
        function LoadcalibrationsButtonPushed(app, event)
            f_sg_load_calibration(app);
        end

        % Button pushed function: StartoptimizationscanButton
        function StartoptimizationscanButtonPushed(app, event)
            f_sg_AO_scan_optimization(app);
        end

        % Value changed function: NumiterationsSpinner
        function NumiterationsSpinnerValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: LateralcalibrationButton
        function LateralcalibrationButtonPushed(app, event)
            sg_lateral_calibration(app);
        end

        % Button pushed function: patterneditorButton
        function patterneditorButtonPushed(app, event)
            sg_pattern_plot(app);
        end

        % Button pushed function: SavecurrentlutButton
        function SavecurrentlutButtonPushed(app, event)
            f_sg_save_current_holo(app);
        end

        % Button pushed function: BrowseimageButton
        function BrowseimageButtonPushed(app, event)
            f_sg_gh_custom_phase_browse(app);
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            f_sg_gh_custom_phase_load(app);
        end

        % Button pushed function: SavecurrentXYZButton
        function SavecurrentXYZButtonPushed(app, event)
            f_sg_save_current_XYZ(app);
        end

        % Value changed function: SLMtypeDropDown
        function SLMtypeDropDownValueChanged(app, event)
            f_sg_update_params_SLM(app);
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            f_sg_update_params_obj(app);
        end

        % Button pushed function: PlotexamplestimtraceButton
        function PlotexamplestimtraceButtonPushed(app, event)
            f_sg_plot_stim_pattern_trace(app);
        end

        % Button pushed function: PlotpwcorrectionButton
        function PlotpwcorrectionButtonPushed(app, event)
            f_sg_pw_plot_correction(app);
        end

        % Button pushed function: correctweightwithinpatternButton
        function correctweightwithinpatternButtonPushed(app, event)
            f_sg_pw_correct_weights_within_pat(app);
        end

        % Button pushed function: correctweightacrosspatternsButton
        function correctweightacrosspatternsButtonPushed(app, event)
            f_sg_pw_correct_weights_across_pat(app);
        end

        % Cell edit callback: UIImagePhaseTable
        function UIImagePhaseTableCellEdit(app, event)
            f_sg_tab_val_changed(app, event);
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            f_sg_clear_table(app);
        end

        % Button pushed function: UpdatepowerButton
        function UpdatepowerButtonPushed(app, event)
            f_sg_update_table_power(app);
        end

        % Button pushed function: CloseallfigsButton
        function CloseallfigsButtonPushed(app, event)
            f_sg_close_all_figs();
        end

        % Callback function
        function StartoptimizationscanbymodeButtonPushed(app, event)
            f_sg_AO_scan_optimization_bymode(app);
        end

        % Callback function
        function GSfromimageButtonPushed(app, event)
            f_sg_GS_from_image(app);
        end

        % Button pushed function: PlotAOcorretionsButton
        function PlotAOcorretionsButtonPushed(app, event)
            f_sg_AO_plot_corrections(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMregioneditorPanel = uipanel(app.ParametersTab);
            app.SLMregioneditorPanel.Title = 'SLM region editor';
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.SelectregionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.SelectregionDropDownLabel.Text = 'Select region';
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.RegionnameEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.RegionnameEditFieldLabel.Text = 'Region name';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.WavelengthnmEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.LUTcorrectionLabel = uilabel(app.SLMregioneditorPanel);
            app.LUTcorrectionLabel.Text = 'LUT correction';
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.minEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.minEditFieldLabel.Text = 'min';
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.maxEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.minEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.minEditField_2Label.Text = 'min';
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.maxEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.maxEditField_2Label.Text = 'max';
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.XYZaffinetransformLabel = uilabel(app.SLMregioneditorPanel);
            app.XYZaffinetransformLabel.Text = 'XYZ affine transform';
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.AOcorrectionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.AOcorrectionDropDownLabel.Text = 'AO correction';
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.EffectiveNALabel = uilabel(app.SLMregioneditorPanel);
            app.EffectiveNALabel.Text = 'Effective NA';
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdiameterpixLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdiameterpixLabel.Text = 'Beam diameter (pix)';
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.XoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.YoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.ZoffsetEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.XZcorrEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.XZcorrEditFieldLabel.Text = 'X/Z corr';
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.YZcorrEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.YZcorrEditFieldLabel.Text = 'Y/Z corr';
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.YZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YZcorrEditField.Tooltip = {'axial Y/Z offset correction'};
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.XZcorrEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XZcorrEditField.Tooltip = {'axial X/Z offset correction'};
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.ZoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZoffsetEditField.Tooltip = {'baseline Z offset of zero coord'};
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.YoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.YoffsetEditField.Tooltip = {'baseline Y offset of zero coord'};
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.XoffsetEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.XoffsetEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.BeamxyzandaxialcorrectionsLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamxyzandaxialcorrectionsLabel.Text = 'Beam xyz and axial corrections';
            app.PointweightcorrectionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDownLabel.Text = 'Point weight correction';
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.PointweightcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.PointweightcorrectionDropDown.Items = {};
            app.PointweightcorrectionDropDown.Value = {};
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.XYZaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.XYZaffinetransformDropDown.Items = {};
            app.XYZaffinetransformDropDown.Value = {};
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionBeamDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.regionZerooutsidephasediameterCheckBox = uicheckbox(app.SLMregioneditorPanel);
            app.regionZerooutsidephasediameterCheckBox.Text = 'Zero outside phase diameter';
            app.regionZerooutsidephasediameterCheckBox.Value = true;
            app.BeamdumpXEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdumpXEditFieldLabel.Text = 'Beam dump X';
            app.BeamdumpXEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpXEditField.Tooltip = {'baseline X offset of zero coord'};
            app.BeamdumpYEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.BeamdumpYEditFieldLabel.Text = 'Beam dump Y';
            app.BeamdumpYEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.BeamdumpYEditField.Tooltip = {'baseline X offset of zero coord'};
            app.PhasediameterpixLabel = uilabel(app.SLMregioneditorPanel);
            app.PhasediameterpixLabel.Text = 'Phase diameter (pix)';
            app.regionPhaseDiameterEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = ' ';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.ZOsuppphaseEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZOsuppphaseEditFieldLabel.Text = 'ZO supp phase';
            app.ZOsuppphaseEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.ZOsuppWEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.ZOsuppWEditFieldLabel.Text = 'ZO supp W';
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = {};
            app.ZOsuppWEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.SaveopsButton = uibutton(app.ParametersTab, 'push');
            app.SaveopsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveopsButtonPushed, true);
            app.SaveopsButton.Text = 'Save ops';
            app.LoadopsButton = uibutton(app.ParametersTab, 'push');
            app.LoadopsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadopsButtonPushed, true);
            app.LoadopsButton.Text = 'Load ops';
            app.LoadcalibrationsButton = uibutton(app.ParametersTab, 'push');
            app.LoadcalibrationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationsButtonPushed, true);
            app.LoadcalibrationsButton.Text = 'Load calibrations';
            app.AOcorrZtoleranceEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AOcorrZtoleranceEditField.Value = 21;
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.DAQcounterchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQcounterchannelEditFieldLabel.Text = 'DAQ counter channel';
            app.DAQAIchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAIchannelEditFieldLabel.Text = 'DAQ AI channel';
            app.LUTDropDownLabel = uilabel(app.ParametersTab);
            app.LUTDropDownLabel.Text = 'LUT';
            app.xpixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.xpixelsEditFieldLabel.Text = 'x pixels';
            app.ypixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.ypixelsEditFieldLabel.Text = 'y pixels';
            app.ypixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ypixelsEditField.Limits = [2 4096];
            app.ypixelsEditField.RoundFractionalValues = 'on';
            app.ypixelsEditField.Value = 256;
            app.xpixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.xpixelsEditField.Limits = [2 4096];
            app.xpixelsEditField.RoundFractionalValues = 'on';
            app.xpixelsEditField.Value = 256;
            app.ZoomEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ZoomEditField.Limits = [0.1 100];
            app.ZoomEditField.Value = 1;
            app.FOVsizeumEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FOVsizeumEditField.Limits = [1 Inf];
            app.FOVsizeumEditField.Value = 500;
            app.ZoomEditFieldLabel = uilabel(app.ParametersTab);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.DAQAOchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAOchannelEditFieldLabel.Text = 'DAQ AO channel';
            app.AOcorrZtoleranceEditFieldLabel = uilabel(app.ParametersTab);
            app.AOcorrZtoleranceEditFieldLabel.Text = 'AO corr Z tolerance';
            app.FOVsizeumEditFieldLabel = uilabel(app.ParametersTab);
            app.FOVsizeumEditFieldLabel.Text = 'FOV size (um)';
            app.ObjectiveDropDown = uidropdown(app.ParametersTab);
            app.ObjectiveDropDown.Items = {};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Value = {};
            app.DAQAOchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAOchannelEditField.Limits = [0 1];
            app.DAQAOchannelEditField.RoundFractionalValues = 'on';
            app.DAQAIchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAIchannelEditField.Limits = [0 1];
            app.DAQAIchannelEditField.RoundFractionalValues = 'on';
            app.DAQcounterchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQcounterchannelEditField.Limits = [0 1];
            app.DAQcounterchannelEditField.RoundFractionalValues = 'on';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.SLMtypeDropDownLabel = uilabel(app.ParametersTab);
            app.SLMtypeDropDownLabel.Text = 'SLM type';
            app.ObjectiveDropDownLabel = uilabel(app.ParametersTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.GenXYZpatmethodDropDownLabel = uilabel(app.ParametersTab);
            app.GenXYZpatmethodDropDownLabel.Text = 'Gen XYZ pat method';
            app.GSnumiterationsEditFieldLabel = uilabel(app.ParametersTab);
            app.GSnumiterationsEditFieldLabel.Text = 'GS num iterations';
            app.GSzfactorEditFieldLabel = uilabel(app.ParametersTab);
            app.GSzfactorEditFieldLabel.Text = 'GS z factor';
            app.GSzfactorEditField = uieditfield(app.ParametersTab, 'numeric');
            app.GSzfactorEditField.Limits = [0 Inf];
            app.GSzfactorEditField.Value = 1;
            app.GSnumiterationsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.GSnumiterationsEditField.Value = 50;
            app.ImageGenverEditFieldLabel = uilabel(app.ParametersTab);
            app.ImageGenverEditFieldLabel.Text = 'ImageGen ver';
            app.ImageGenverEditField = uieditfield(app.ParametersTab, 'text');
            app.ImageGenverEditField.Editable = 'off';
            app.GenXYZpatmethodDropDown = uidropdown(app.ParametersTab);
            app.GenXYZpatmethodDropDown.Items = {};
            app.GenXYZpatmethodDropDown.Value = {};
            app.TubelengthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TubelengthEditField.Value = 0.18;
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.TubelengthEditFieldLabel = uilabel(app.ParametersTab);
            app.TubelengthEditFieldLabel.Text = 'Tube length';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.LUTDropDown = uidropdown(app.ParametersTab);
            app.LUTDropDown.Items = {};
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Tooltip = {'For 1920x1152 model bot global .lut and regional .txt files are treated the same and can be upload with lut update functiond. For the old 512x512 with overdrive regional .txt needs to be uploaded during SDK creation '};
            app.LUTDropDown.Value = {};
            app.SLMtypeDropDown = uidropdown(app.ParametersTab);
            app.SLMtypeDropDown.Items = {};
            app.SLMtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SLMtypeDropDownValueChanged, true);
            app.SLMtypeDropDown.Value = {};
            app.XYZpatternsTab = uitab(app.TabGroup);
            app.XYZpatternsTab.Title = 'XYZ patterns';
            app.UIImagePhaseTable = uitable(app.XYZpatternsTab);
            app.UIImagePhaseTable.ColumnName = {'Idx'; 'Pattern'; 'X'; 'Y'; 'Z'; 'W_set'; 'W_est'; 'Power'};
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = [true true true true true true false];
            app.UIImagePhaseTable.CellEditCallback = createCallbackFcn(app, @UIImagePhaseTableCellEdit, true);
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.SavecurrentXYZButton = uibutton(app.XYZpatternsTab, 'push');
            app.SavecurrentXYZButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurrentXYZButtonPushed, true);
            app.SavecurrentXYZButton.Text = 'Save current XYZ';
            app.patterneditorButton = uibutton(app.XYZpatternsTab, 'push');
            app.patterneditorButton.ButtonPushedFcn = createCallbackFcn(app, @patterneditorButtonPushed, true);
            app.patterneditorButton.Text = 'pattern editor';
            app.correctweightacrosspatternsButton = uibutton(app.XYZpatternsTab, 'push');
            app.correctweightacrosspatternsButton.ButtonPushedFcn = createCallbackFcn(app, @correctweightacrosspatternsButtonPushed, true);
            app.correctweightacrosspatternsButton.Text = 'correct weight across patterns';
            app.pointsizepixEditField = uieditfield(app.XYZpatternsTab, 'numeric');
            app.pointsizepixEditField.Limits = [1 Inf];
            app.pointsizepixEditField.Value = 3;
            app.correctweightwithinpatternButton = uibutton(app.XYZpatternsTab, 'push');
            app.correctweightwithinpatternButton.ButtonPushedFcn = createCallbackFcn(app, @correctweightwithinpatternButtonPushed, true);
            app.correctweightwithinpatternButton.Text = 'correct weight within pattern';
            app.UpdatepowerButton = uibutton(app.XYZpatternsTab, 'push');
            app.UpdatepowerButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatepowerButtonPushed, true);
            app.UpdatepowerButton.Text = 'Update power';
            app.XYsmoothstdpixEditField = uieditfield(app.XYZpatternsTab, 'text');
            app.XYsmoothstdpixEditField.Value = '0.7, 0.7';
            app.SimulatepixelcrosstalkCheckBox = uicheckbox(app.XYZpatternsTab);
            app.SimulatepixelcrosstalkCheckBox.Text = 'Simulate pixel crosstalk';
            app.ViewpredictedPSFButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.fftzoomed20xCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.fftampsquaredCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftdefocusumEditField = uieditfield(app.XYZpatternsTab, 'numeric');
            app.ViewpatternfftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpatternfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpatternfftButtonPushed, true);
            app.ViewpatternfftButton.Text = 'View pattern fft';
            app.ViewpatternphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpatternphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpatternphaseButtonPushed, true);
            app.ViewpatternphaseButton.Text = 'View pattern phase';
            app.UploadpatternButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadpatternButton.ButtonPushedFcn = createCallbackFcn(app, @UploadpatternButtonPushed, true);
            app.UploadpatternButton.Text = 'Upload pattern';
            app.PatternSpinner = uispinner(app.XYZpatternsTab);
            app.PatternSpinner.Limits = [1 100];
            app.PatternSpinner.RoundFractionalValues = 'on';
            app.PatternSpinner.Value = 1;
            app.ClearallButton = uibutton(app.XYZpatternsTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ViewselectedfftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedfftButtonPushed, true);
            app.ViewselectedfftButton.Text = 'View selected fft';
            app.ViewselectedphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.RemoveselectedButton = uibutton(app.XYZpatternsTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.UploadselectedButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedButtonPushed, true);
            app.UploadselectedButton.Text = 'Upload selected';
            app.AddtohologramButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.CreatecustompointhologramPanel = uipanel(app.XYZpatternsTab);
            app.CreatecustompointhologramPanel.Title = 'Create custom point hologram';
            app.UploadHologramButton = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.ViewHologramButtonMultiplane = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.WeightEditField.Value = '1';
            app.PointweightLabel = uilabel(app.CreatecustompointhologramPanel);
            app.PointweightLabel.Text = 'Point weight';
            app.ZoffsetumEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.ZoffsetumEditField.Value = '0';
            app.DefocusLabel = uilabel(app.CreatecustompointhologramPanel);
            app.DefocusLabel.Text = 'Defocus';
            app.PatternnumberEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.PatternnumberEditFieldLabel.Text = 'Pattern number';
            app.XdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.YdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.YdisplacementEditField.Value = '0';
            app.XdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.XdisplacementEditField.Value = '0';
            app.XYdisplacementLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.ZoffsetumEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.ZoffsetumEditFieldLabel.Text = 'Z offset (um)';
            app.WeightEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.PatternnumberEditField = uieditfield(app.CreatecustompointhologramPanel, 'text');
            app.PatternnumberEditField.Value = '1';
            app.SavegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.SavegroupButton.ButtonPushedFcn = createCallbackFcn(app, @SavegroupButtonPushed, true);
            app.SavegroupButton.Text = 'Save group';
            app.DeletegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.DeletegroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeletegroupButtonPushed, true);
            app.DeletegroupButton.Text = 'Delete group';
            app.AddgroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddgroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddgroupButtonPushed, true);
            app.AddgroupButton.Text = 'Add group';
            app.GroupnameEditField = uieditfield(app.XYZpatternsTab, 'text');
            app.PatterngroupDropDown = uidropdown(app.XYZpatternsTab);
            app.PatterngroupDropDown.Items = {'Multiplane'};
            app.PatterngroupDropDown.ValueChangedFcn = createCallbackFcn(app, @PatterngroupDropDownValueChanged, true);
            app.PatterngroupDropDown.Value = 'Multiplane';
            app.PatternSpinnerLabel = uilabel(app.XYZpatternsTab);
            app.PatternSpinnerLabel.Text = 'Pattern';
            app.fftdefocusumEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.PatterngroupDropDownLabel = uilabel(app.XYZpatternsTab);
            app.PatterngroupDropDownLabel.Text = 'Pattern group';
            app.GroupnameEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.GroupnameEditFieldLabel.Text = 'Group name';
            app.pointsizepixEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.pointsizepixEditFieldLabel.Text = 'point size (pix)';
            app.XYsmoothstdpixEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.XYsmoothstdpixEditFieldLabel.Text = 'XY smooth std (pix)';
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.GroupscanPanel = uipanel(app.ScanTab);
            app.GroupscanPanel.Title = 'Group scan';
            app.ImagingEOFcounterchantriggeredPanel = uipanel(app.GroupscanPanel);
            app.ImagingEOFcounterchantriggeredPanel.Title = 'Imaging (EOF counter chan triggered)';
            app.PlotSLMupdateratesCheckBox = uicheckbox(app.ImagingEOFcounterchantriggeredPanel);
            app.PlotSLMupdateratesCheckBox.Text = 'Plot SLM update rates';
            app.PlotSLMupdateratesCheckBox.Value = true;
            app.ScanwithSLMtriggersCheckBox = uicheckbox(app.ImagingEOFcounterchantriggeredPanel);
            app.ScanwithSLMtriggersCheckBox.Tooltip = {'Send ''End of frame'' triggers directly to SLM instead of GUI DAQ. Simultaneous stimulation control does not work with this feature'};
            app.ScanwithSLMtriggersCheckBox.Text = 'Scan with SLM triggers';
            app.ImagingReadyLamp = uilamp(app.ImagingEOFcounterchantriggeredPanel);
            app.InitializeimagingButton = uibutton(app.ImagingEOFcounterchantriggeredPanel, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.NumVolumesEditField = uieditfield(app.ImagingEOFcounterchantriggeredPanel, 'numeric');
            app.NumVolumesEditField.Limits = [1 Inf];
            app.NumVolumesEditField.Value = 1;
            app.PatterngroupLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.PatterngroupLabel.Text = 'Pattern group';
            app.PatternDropDownCtr = uidropdown(app.ImagingEOFcounterchantriggeredPanel);
            app.PatternDropDownCtr.Items = {};
            app.PatternDropDownCtr.Tooltip = {'When stim pattern is not used, the default uploaded pattern on stim side stays on'};
            app.PatternDropDownCtr.Value = {};
            app.NumVolumesEditFieldLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.NumVolumesEditFieldLabel.Text = 'Num Volumes';
            app.StimulationAIinputVpatternnumPanel = uipanel(app.GroupscanPanel);
            app.StimulationAIinputVpatternnumPanel.Tooltip = {'AI 0=off'; '[n_goup * 5V / num_group] = group voltage id'};
            app.StimulationAIinputVpatternnumPanel.Title = 'Stimulation (AI input V = pattern num)';
            app.PatterngroupLabel_2 = uilabel(app.StimulationAIinputVpatternnumPanel);
            app.PatterngroupLabel_2.Text = 'Pattern group';
            app.PatternDropDownAI = uidropdown(app.StimulationAIinputVpatternnumPanel);
            app.PatternDropDownAI.Items = {};
            app.PatternDropDownAI.Tooltip = {'AI 0=off'; '[n_goup * 5V / num_group] = group voltage id'};
            app.PatternDropDownAI.Value = {};
            app.stimcontrolsourceButtonGroup = uibuttongroup(app.StimulationAIinputVpatternnumPanel);
            app.stimcontrolsourceButtonGroup.Title = 'stim control source';
            app.CustomstimButton = uitogglebutton(app.stimcontrolsourceButtonGroup);
            app.CustomstimButton.Text = 'Custom stim';
            app.AIinputButton = uitogglebutton(app.stimcontrolsourceButtonGroup);
            app.AIinputButton.Text = 'AI input';
            app.AIinputButton.Value = true;
            app.CustomstimprotocolPanel = uipanel(app.GroupscanPanel);
            app.CustomstimprotocolPanel.Title = 'Custom stim protocol';
            app.patternrepeatsEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternrepeatsEditFieldLabel.Text = 'pattern repeats';
            app.patternorderDropDownLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternorderDropDownLabel.Text = 'pattern order';
            app.patterndurationEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patterndurationEditFieldLabel.Text = 'pattern duration';
            app.patternintervalEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternintervalEditFieldLabel.Text = 'pattern interval';
            app.patternintervaljitterEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.patternintervaljitterEditFieldLabel.Text = 'pattern interval jitter';
            app.patternintervaljitterEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternintervaljitterEditField.Value = 1;
            app.patternrepeatsEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternrepeatsEditField.Value = 5;
            app.patternintervalEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patternintervalEditField.Value = 2;
            app.patterndurationEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.patterndurationEditField.Value = 1;
            app.initialdelayEditFieldLabel = uilabel(app.CustomstimprotocolPanel);
            app.initialdelayEditFieldLabel.Text = 'initial delay';
            app.initialdelayEditField = uieditfield(app.CustomstimprotocolPanel, 'numeric');
            app.initialdelayEditField.Value = 30;
            app.PlotexamplestimtraceButton = uibutton(app.CustomstimprotocolPanel, 'push');
            app.PlotexamplestimtraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotexamplestimtraceButtonPushed, true);
            app.PlotexamplestimtraceButton.Text = 'Plot example stim trace';
            app.patternorderDropDown = uidropdown(app.CustomstimprotocolPanel);
            app.patternorderDropDown.Items = {'Random', 'Sequence'};
            app.patternorderDropDown.Value = 'Random';
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram phase preview')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.UIAxesColorLimits = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesColorLimits, 'color limit (pi)')
            zlabel(app.UIAxesColorLimits, 'Z')
            app.UIAxesColorLimits.YTick = [];
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.PistonTab = uitab(app.TabGroup2);
            app.PistonTab.Title = 'Piston';
            app.GeneratepistonButton = uibutton(app.PistonTab, 'push');
            app.GeneratepistonButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GeneratepistonButton.Text = 'Generate piston';
            app.PixelValueLabel = uilabel(app.PistonTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.PistonTab, 'numeric');
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Blazed';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazReverseCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazReverseCheckBox.Text = 'Reverse direction';
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 127;
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.HorizontalstripeCheckBox = uicheckbox(app.StripesTab);
            app.HorizontalstripeCheckBox.Text = 'Horizontal stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 127;
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.GeneratedefocusButton = uibutton(app.DefocusTab, 'push');
            app.GeneratedefocusButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedefocusButtonPushed, true);
            app.GeneratedefocusButton.Text = 'Generate defocus';
            app.DefocusdistumLabel = uilabel(app.DefocusTab);
            app.DefocusdistumLabel.Text = 'Defocus dist (um)';
            app.DeficusWeightEditField = uieditfield(app.DefocusTab, 'numeric');
            app.CrossTab = uitab(app.TabGroup2);
            app.CrossTab.Title = 'Cross';
            app.GenerateCrossButton = uibutton(app.CrossTab, 'push');
            app.GenerateCrossButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCrossButtonPushed, true);
            app.GenerateCrossButton.Text = 'Generate cross';
            app.PixelValueEditFieldLabel_2 = uilabel(app.CrossTab);
            app.PixelValueEditFieldLabel_2.Text = 'Pixel Value';
            app.GrayEditFieldLabel_2 = uilabel(app.CrossTab);
            app.GrayEditFieldLabel_2.Text = 'Gray';
            app.AngleradLabel = uilabel(app.CrossTab);
            app.AngleradLabel.Text = 'Angle (rad)';
            app.CrossAngleEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossAngleEditField.Value = 0.7854;
            app.CrossGrayEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossGrayEditField.Value = 127;
            app.CrossPixelValueEditField = uieditfield(app.CrossTab, 'numeric');
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Fresnel';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');

            app.FresCenterXEditField.Value = 960;
            app.ZernikeTab = uitab(app.TabGroup2);
            app.ZernikeTab.Title = 'Zernike';
            app.GenerateZernikeButton = uibutton(app.ZernikeTab, 'push');
            app.GenerateZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateZernikeButtonPushed, true);
            app.GenerateZernikeButton.Text = 'Generate Zernike';
            app.CenterXEditField_2Label = uilabel(app.ZernikeTab);
            app.CenterXEditField_2Label.Text = 'CenterX';
            app.CenterYEditField_5Label = uilabel(app.ZernikeTab);
            app.CenterYEditField_5Label.Text = 'CenterY';
            app.RadiusEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.RadiusEditFieldLabel_2.Text = 'Radius';
            app.PistonEditFieldLabel = uilabel(app.ZernikeTab);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.TiltXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltXEditFieldLabel.Text = 'TiltX';
            app.TiltYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltYEditFieldLabel.Text = 'TiltY';
            app.PowerEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.AstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigXEditFieldLabel.Text = 'AstigX';
            app.AstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigYEditFieldLabel.Text = 'AstigY';
            app.ComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaXEditFieldLabel.Text = 'ComaX';
            app.ComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaYEditFieldLabel.Text = 'ComaY';
            app.PrimarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.PrimarySphericalEditFieldLabel.Text = 'PrimarySpherical';
            app.TrefoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilXEditFieldLabel.Text = 'TrefoilX';
            app.TrefoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilYEditFieldLabel.Text = 'TrefoilY';
            app.SecondaryAstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigXEditFieldLabel.Text = 'SecondaryAstigX';
            app.SecondaryAstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigYEditFieldLabel.Text = 'SecondaryAstigY';
            app.SecondaryComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaXEditFieldLabel.Text = 'SecondaryComaX';
            app.SecondaryComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaYEditFieldLabel.Text = 'SecondaryComaY';
            app.SecondarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondarySphericalEditFieldLabel.Text = 'SecondarySpherical';
            app.TetrafoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilXEditFieldLabel.Text = 'TetrafoilX';
            app.TetrafoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilYEditFieldLabel.Text = 'TetrafoilY';
            app.TertiarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.TertiarySphericalEditFieldLabel.Text = 'TertiarySpherical';
            app.QuaternarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.QuaternarySphericalEditFieldLabel.Text = 'QuaternarySpherical';
            app.QuaternarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TertiarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PrimarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PowerEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PistonEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.RadiusEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CustomphaseTab = uitab(app.TabGroup2);
            app.CustomphaseTab.Title = 'Custom phase';
            app.ImagepathEditFieldLabel = uilabel(app.CustomphaseTab);
            app.ImagepathEditFieldLabel.Text = 'Image path';
            app.PhaseformatDropDownLabel = uilabel(app.CustomphaseTab);
            app.PhaseformatDropDownLabel.Text = 'Phase format';
            app.PhaseformatDropDown = uidropdown(app.CustomphaseTab);
            app.PhaseformatDropDown.Items = {'Preprocessed to uint8', 'Radians'};
            app.PhaseformatDropDown.Value = 'Preprocessed to uint8';
            app.LoadimageButton = uibutton(app.CustomphaseTab, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.BrowseimageButton = uibutton(app.CustomphaseTab, 'push');
            app.BrowseimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseimageButtonPushed, true);
            app.BrowseimageButton.Text = 'Browse image';
            app.ImagepathEditField = uieditfield(app.CustomphaseTab, 'text');
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.AdaptiveOpticsmodalTab = uitab(app.TabGroup);
            app.AdaptiveOpticsmodalTab.Title = 'Adaptive Optics modal';
            app.ZernikeListTable = uitable(app.AdaptiveOpticsmodalTab);
            app.ZernikeListTable.ColumnName = {'idx'; 'Zn'; 'Zm'; 'W - lim'; 'W step'; 'W + lim'; 'Include'};
            app.ZernikeListTable.RowName = {};
            app.ZernikeListTable.ColumnEditable = [false false false true true true true];
            app.ZernikeListTable.CellEditCallback = createCallbackFcn(app, @ZernikeListTableCellEdit, true);
            app.ZernikeModestoscanadjustweightsifneededLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.ZernikeModestoscanadjustweightsifneededLabel.Text = 'Zernike Modes to scan (adjust weights if needed)';
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.AOregularscanzernikepolPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOregularscanzernikepolPanel.Title = 'AO regular scan zernike pol';
            app.NumscanvolumesEditFieldLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanvolumesEditFieldLabel.Text = 'Num scan volumes';
            app.NumscanvolumesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanvolumesEditField.Limits = [0 Inf];
            app.NumscanvolumesEditField.RoundFractionalValues = 'on';
            app.NumscanvolumesEditField.Editable = 'off';
            app.NumscanframesLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanframesLabel.Text = 'Num scan frames';
            app.NumscanframesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanframesEditField.Limits = [0 Inf];
            app.NumscanframesEditField.RoundFractionalValues = 'on';
            app.NumscanframesEditField.Editable = 'off';
            app.ZernikeReadyLamp = uilamp(app.AOregularscanzernikepolPanel);
            app.ScanZernikeButton = uibutton(app.AOregularscanzernikepolPanel, 'state');
            app.ScanZernikeButton.ValueChangedFcn = createCallbackFcn(app, @ScanZernikeButtonValueChanged, true);
            app.ScanZernikeButton.Text = 'Scan Zernike';
            app.AOautooptimizationscanPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOautooptimizationscanPanel.Title = 'AO auto optimization scan';
            app.NumscanframesOpEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.NumscanframesOpEditField.Limits = [0 Inf];
            app.NumscanframesOpEditField.RoundFractionalValues = 'on';
            app.NumscanframesOpEditField.Editable = 'off';
            app.StartoptimizationscanButton = uibutton(app.AOautooptimizationscanPanel, 'push');
            app.StartoptimizationscanButton.ButtonPushedFcn = createCallbackFcn(app, @StartoptimizationscanButtonPushed, true);
            app.StartoptimizationscanButton.Text = 'Start optimization scan';
            app.PostscandelayEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.PostscandelayEditField.Tooltip = {'0.5 for 16fr averaging; 0.8 for 32fr ave'};
            app.PostscandelayEditField.Value = 0.8;
            app.BeadwindowsizeEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.BeadwindowsizeEditField.Value = 80;
            app.PlotextradeetsCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotextradeetsCheckBox.Text = 'Plot extra deets';
            app.PlotprogressCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotprogressCheckBox.Text = 'Plot progress';
            app.PlotprogressCheckBox.Value = true;
            app.NumiterationsSpinner = uispinner(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinner.Limits = [1 Inf];
            app.NumiterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumiterationsSpinnerValueChanged, true);
            app.NumiterationsSpinner.Value = 30;
            app.DecresegradntimesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.DecresegradntimesEditField.Limits = [0 Inf];
            app.DecresegradntimesEditField.Value = 1;
            app.ScanallcorreverynframesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.ScanallcorreverynframesEditField.Limits = [1 Inf];
            app.ScanallcorreverynframesEditField.Value = 200;
            app.RefocuseverynframesEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.RefocuseverynframesEditField.Limits = [1 Inf];
            app.RefocuseverynframesEditField.Value = 100;
            app.OptimizationmethodDropDown = uidropdown(app.AOautooptimizationscanPanel);
            app.OptimizationmethodDropDown.Items = {};
            app.OptimizationmethodDropDown.Value = {};
            app.ScanframesdirpathEditField = uieditfield(app.AOautooptimizationscanPanel, 'text');
            app.ScanframesdirpathEditField.Value = '\\PRAIRIE2000\p2f\Yuriy\AO\';
            app.NumiterationsSpinnerLabel = uilabel(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinnerLabel.Text = 'Num iterations';
            app.ScanframesdirpathEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.ScanframesdirpathEditFieldLabel.Text = 'Scan frames dir path';
            app.NumscanframesLabel_2 = uilabel(app.AOautooptimizationscanPanel);
            app.NumscanframesLabel_2.Text = 'Num scan frames';
            app.BeadwindowsizeEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.BeadwindowsizeEditFieldLabel.Text = 'Bead window size';
            app.PostscandelayEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.PostscandelayEditFieldLabel.Text = 'Post scan delay';
            app.OptimizationmethodDropDownLabel = uilabel(app.AOautooptimizationscanPanel);
            app.OptimizationmethodDropDownLabel.Text = 'Optimization method';
            app.RefocuseverynframesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.RefocuseverynframesEditFieldLabel.Text = 'Refocus every n frames';
            app.ScanallcorreverynframesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.ScanallcorreverynframesEditFieldLabel.Text = 'Scan all corr every n frames';
            app.DecresegradntimesEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.DecresegradntimesEditFieldLabel.Text = 'Decrese grad n times';
            app.ParamsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.ParamsPanel.Title = 'Params';
            app.SavefiletagEditFieldLabel = uilabel(app.ParamsPanel);
            app.SavefiletagEditFieldLabel.Text = 'Save file tag';
            app.ScanspermodeEditFieldLabel = uilabel(app.ParamsPanel);
            app.ScanspermodeEditFieldLabel.Text = 'Scans per mode';
            app.ScanspermodeEditField = uieditfield(app.ParamsPanel, 'numeric');
            app.ScanspermodeEditField.Limits = [1 Inf];
            app.ScanspermodeEditField.ValueChangedFcn = createCallbackFcn(app, @ScanspermodeEditFieldValueChanged, true);
            app.ScanspermodeEditField.Value = 2;
            app.ShufflemodesCheckBox = uicheckbox(app.ParamsPanel);
            app.ShufflemodesCheckBox.Text = 'Shuffle modes';
            app.ShufflemodesCheckBox.Value = true;
            app.SavefiletagEditField = uieditfield(app.ParamsPanel, 'text');
            app.SavefiletagEditField.Value = 'zernike_scan_data';
            app.FillzerniketablePanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.FillzerniketablePanel.Title = 'Fill zernike table';
            app.FillmodestableButton = uibutton(app.FillzerniketablePanel, 'push');
            app.FillmodestableButton.ButtonPushedFcn = createCallbackFcn(app, @FillmodestableButtonPushed, true);
            app.FillmodestableButton.Text = 'Fill modes table';
            app.MaxZnEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MaxZnEditFieldLabel.Text = 'Max Zn';
            app.WeightlimitEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightlimitEditFieldLabel.Text = 'Weight limit';
            app.WeightstepEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightstepEditFieldLabel.Text = 'Weight step';
            app.AOignoresphericalmodeCheckBox = uicheckbox(app.FillzerniketablePanel);
            app.AOignoresphericalmodeCheckBox.Text = 'AO ignore spherical mode';
            app.AOignoresphericalmodeCheckBox.Value = true;
            app.WeightstepEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightstepEditField.Value = 0.25;
            app.WeightlimitEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightlimitEditField.Value = 1.5;
            app.MaxZnEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.MaxZnEditField.Value = 4;
            app.MinZnEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MinZnEditFieldLabel.Text = 'Min Zn';
            app.MinZnEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.LUTcalibrationdataPanel = uipanel(app.CalibrationTab);
            app.LUTcalibrationdataPanel.Enable = 'off';
            app.LUTcalibrationdataPanel.Title = 'LUT calibration data';
            app.LUTcalibrationdataPanel.Visible = 'off';
            app.BitDepthEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BitDepthEditFieldLabel.Text = 'Bit Depth';
            app.BitDepthEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BitDepthEditField.Limits = [1 256];
            app.BitDepthEditField.RoundFractionalValues = 'on';
            app.BitDepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitDepthEditFieldValueChanged, true);
            app.BitDepthEditField.Value = 256;
            app.NumRegionsEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.NumRegionsEditFieldLabel.Text = 'Num Regions';
            app.NumRegionsEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.NumRegionsEditField.Limits = [1 Inf];
            app.NumRegionsEditField.RoundFractionalValues = 'on';
            app.NumRegionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumRegionsEditFieldValueChanged, true);
            app.NumRegionsEditField.Value = 1;
            app.PixelsPerStripeEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.PixelsPerStripeEditFieldLabel.Text = 'Pixels Per Stripe';
            app.PixelsPerStripeEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.PixelsPerStripeEditField.Limits = [1 Inf];
            app.PixelsPerStripeEditField.Value = 8;
            app.AnalysissavefilenameEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.AnalysissavefilenameEditFieldLabel.Text = 'Analysis save file name';
            app.AnalysissavefilenameEditField = uieditfield(app.LUTcalibrationdataPanel, 'text');
            app.AnalysissavefilenameEditField.Value = '940_slm5221_maitai2';
            app.RunLUTcalibrationButton = uibutton(app.LUTcalibrationdataPanel, 'state');
            app.RunLUTcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunLUTcalibrationButtonValueChanged, true);
            app.RunLUTcalibrationButton.Text = 'Run LUT calibration';
            app.AnalyzeLUTdataButton = uibutton(app.LUTcalibrationdataPanel, 'push');
            app.AnalyzeLUTdataButton.Text = 'Analyze LUT data';
            app.RunLUTcalibReadyLamp = uilamp(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel.Text = 'Baseline pixel value';
            app.BaselinepixelvalueEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BaselinepixelvalueEditField.Limits = [0 255];
            app.BaselinepixelvalueEditField.RoundFractionalValues = 'on';
            app.TotalframesEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.TotalframesEditFieldLabel.Text = 'Total frames';
            app.TotalframesEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.TotalframesEditField.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.LUTcalibrationdataPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.EndofframeButton = uitogglebutton(app.ButtonGroup);
            app.EndofframeButton.Text = 'End of frame';
            app.EndofframeButton.Value = true;
            app.TLDCcamButton = uitogglebutton(app.ButtonGroup);
            app.TLDCcamButton.Text = 'TLDC cam';
            app.LateralcalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.LateralcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LateralcalibrationButtonPushed, true);
            app.LateralcalibrationButton.Text = 'Lateral calibration';
            app.PointWeightcorrectionPanel = uipanel(app.CalibrationTab);
            app.PointWeightcorrectionPanel.Title = 'Point Weight correction';
            app.PlotpwcorrectionButton = uibutton(app.PointWeightcorrectionPanel, 'push');
            app.PlotpwcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpwcorrectionButtonPushed, true);
            app.PlotpwcorrectionButton.Text = 'Plot pw correction';
            app.smoothstdEditFieldLabel = uilabel(app.PointWeightcorrectionPanel);
            app.smoothstdEditFieldLabel.Text = 'smooth std';
            app.PWsqrt2pCheckBox = uicheckbox(app.PointWeightcorrectionPanel);
            app.PWsqrt2pCheckBox.Text = 'sqrt (2p)';
            app.MincorrthreshEditFieldLabel = uilabel(app.PointWeightcorrectionPanel);
            app.MincorrthreshEditFieldLabel.Text = 'Min corr thresh';
            app.PWmincorrthreshEditField = uieditfield(app.PointWeightcorrectionPanel, 'numeric');
            app.PWsmoothstdEditField = uieditfield(app.PointWeightcorrectionPanel, 'numeric');
            app.PWsmoothstdEditField.Value = 1;
            app.AOPanel = uipanel(app.CalibrationTab);
            app.AOPanel.Title = 'AO';
            app.save_fit_weightsCheckBox = uicheckbox(app.AOPanel);
            app.save_fit_weightsCheckBox.Text = 'save_fit_weights';
            app.constrainz0CheckBox = uicheckbox(app.AOPanel);
            app.constrainz0CheckBox.Text = 'constrain z0';
            app.modestofitEditFieldLabel = uilabel(app.AOPanel);
            app.modestofitEditFieldLabel.Text = 'modes to fit';
            app.modestofitEditField = uieditfield(app.AOPanel, 'text');
            app.PlotAOcorretionsButton = uibutton(app.AOPanel, 'push');
            app.PlotAOcorretionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAOcorretionsButtonPushed, true);
            app.PlotAOcorretionsButton.Text = 'Plot AO corretions';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.CloseallfigsButton = uibutton(app.Panel, 'push');
            app.CloseallfigsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfigsButtonPushed, true);
            app.CloseallfigsButton.Text = 'Close all figs';
            app.SavecurrentlutButton = uibutton(app.Panel, 'push');
            app.SavecurrentlutButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurrentlutButtonPushed, true);
            app.SavecurrentlutButton.Text = 'Save current lut';
            app.ViewcurrentlutButton = uibutton(app.Panel, 'push');
            app.ViewcurrentlutButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentlutButtonPushed, true);
            app.ViewcurrentlutButton.Text = 'View current lut';
            app.ViewcurrentAOButton = uibutton(app.Panel, 'push');
            app.ViewcurrentAOButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentAOButtonPushed, true);
            app.ViewcurrentAOButton.Text = 'View current AO';
            app.ViewcurrentphaseButton = uibutton(app.Panel, 'push');
            app.ViewcurrentphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentphaseButtonPushed, true);
            app.ViewcurrentphaseButton.Text = 'View current phase';
            app.BlankSLMButton = uibutton(app.Panel, 'push');
            app.BlankSLMButton.ButtonPushedFcn = createCallbackFcn(app, @BlankSLMButtonPushed, true);
            app.BlankSLMButton.Tooltip = {'during blank corrections are not applied (LUT or AO)'};
            app.BlankSLMButton.Text = 'Blank SLM';
            app.CurrentregionDropDown = uidropdown(app.Panel);
            app.CurrentregionDropDown.Items = {};
            app.CurrentregionDropDown.Value = {};
            app.ZOsuppressionLamp = uilamp(app.Panel);
            app.ApplyZOsuppressionButton = uibutton(app.Panel, 'state');
            app.ApplyZOsuppressionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyZOsuppressionButtonValueChanged, true);
            app.ApplyZOsuppressionButton.Text = {'Apply ZO'; 'suppression'};
            app.PWcorrectionLamp = uilamp(app.Panel);
            app.ApplyPWcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyPWcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyPWcorrectionButtonValueChanged, true);
            app.ApplyPWcorrectionButton.Text = {'Apply PW'; 'correction'};
            app.InitializeAOLamp = uilamp(app.Panel);
            app.ApplyAOcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyAOcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyAOcorrectionButtonValueChanged, true);
            app.ApplyAOcorrectionButton.Text = {'Apply AO'; 'correction'};
            app.XYZcalibrationLamp = uilamp(app.Panel);
            app.ApplyXYZcalibrationButton = uibutton(app.Panel, 'state');
            app.ApplyXYZcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @ApplyXYZcalibrationButtonValueChanged, true);
            app.ApplyXYZcalibrationButton.Text = {'Apply XYZ'; 'calibration'};
            app.InitializeDAQLamp = uilamp(app.Panel);
            app.InitializeDAQButton = uibutton(app.Panel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.CurrentregionDropDownLabel = uilabel(app.Panel);
            app.CurrentregionDropDownLabel.Text = 'Current region';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end