classdef SLM_control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GenerateHologramTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        BlankTab                        matlab.ui.container.Tab
        GenerateBlankButton             matlab.ui.control.Button
        PixelValueLabel                 matlab.ui.control.Label
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        FresnelLensTab                  matlab.ui.container.Tab
        GenerateFresnelLensButton       matlab.ui.control.Button
        CenterXEditFieldLabel           matlab.ui.control.Label
        FresCenterXEditField            matlab.ui.control.NumericEditField
        CenterYEditFieldLabel           matlab.ui.control.Label
        FresCenterYEditField            matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        FresRadiusEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        FresPowerEditField              matlab.ui.control.NumericEditField
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        BlazedGratingTab                matlab.ui.container.Tab
        GenerateBlazedGratingButton     matlab.ui.control.Button
        PeriodEditFieldLabel            matlab.ui.control.Label
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        StripesTab                      matlab.ui.container.Tab
        PixelsPerStripeLabel            matlab.ui.control.Label
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        GenerateStripesButton           matlab.ui.control.Button
        PixelValueEditFieldLabel        matlab.ui.control.Label
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        GrayEditFieldLabel              matlab.ui.control.Label
        StripeGrayEditField             matlab.ui.control.NumericEditField
        DefocusTab                      matlab.ui.container.Tab
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        UploadHologramButtonGenerate    matlab.ui.control.Button
        MultiplaneImagingTab            matlab.ui.container.Tab
        InitializeimagingButton         matlab.ui.control.StateButton
        ListofhologramsLabel            matlab.ui.control.Label
        CreateimagingphasePanel         matlab.ui.container.Panel
        UploadHologramButton            matlab.ui.control.Button
        ZOffsetumEditFieldLabel         matlab.ui.control.Label
        ZOffsetumEditField              matlab.ui.control.NumericEditField
        ManualNAcorrectionCheckBox      matlab.ui.control.CheckBox
        ManualNAEditFieldLabel          matlab.ui.control.Label
        ManualNAEditField               matlab.ui.control.NumericEditField
        DefocusLabel                    matlab.ui.control.Label
        XYdisplacementLabel             matlab.ui.control.Label
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditField          matlab.ui.control.NumericEditField
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        YdisplacementEditField          matlab.ui.control.NumericEditField
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        PlanenumberEditFieldLabel       matlab.ui.control.Label
        PlanenumberEditField            matlab.ui.control.NumericEditField
        UploadselectedButton            matlab.ui.control.Button
        FrameswitchingmethodButtonGroup  matlab.ui.container.ButtonGroup
        EndofframetriggeringButton      matlab.ui.control.ToggleButton
        TimedelayButton                 matlab.ui.control.ToggleButton
        MoveupButton                    matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        UIImagePhaseTable               matlab.ui.control.Table
        RemoveselectedButton            matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        ViewpredictedPSFButton          matlab.ui.control.Button
        ViewselectedphaseButton         matlab.ui.control.Button
        PlaneSpinnerLabel               matlab.ui.control.Label
        PlaneSpinner                    matlab.ui.control.Spinner
        ViewplanephaseButton            matlab.ui.control.Button
        UploadPlaneButton               matlab.ui.control.Button
        ViewselectedfftButton           matlab.ui.control.Button
        ViewplanefftButton              matlab.ui.control.Button
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        VolumesEditFieldLabel           matlab.ui.control.Label
        VolumesEditField                matlab.ui.control.NumericEditField
        ImagingReadyLamp                matlab.ui.control.Lamp
        StimulationTab                  matlab.ui.container.Tab
        MakeXYZPPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        PointsLabel                     matlab.ui.control.Label
        SplitSLMTab                     matlab.ui.container.Tab
        ParametersTab                   matlab.ui.container.Tab
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        SLMwidthEditField               matlab.ui.control.NumericEditField
        SLMheightEditFieldLabel         matlab.ui.control.Label
        SLMheightEditField              matlab.ui.control.NumericEditField
        ObjectiveNAEditFieldLabel       matlab.ui.control.Label
        ObjectiveNAEditField            matlab.ui.control.NumericEditField
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        SLMpresetoffsetXLabel           matlab.ui.control.Label
        SLMpresetoffsetXEditField       matlab.ui.control.NumericEditField
        SLMpresetoffsetYEditFieldLabel  matlab.ui.control.Label
        SLMpresetoffsetYEditField       matlab.ui.control.NumericEditField
        ObjectiveMagXEditFieldLabel     matlab.ui.control.Label
        ObjectiveMagXEditField          matlab.ui.control.NumericEditField
        BeamdiameterSTDEditFieldLabel   matlab.ui.control.Label
        BeamdiameterSTDEditField        matlab.ui.control.NumericEditField
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        NIDAQdeviceEditField            matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        ActivateSLMLamp                 matlab.ui.control.Lamp
        ActivateSLMButton               matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        SLM_ops; % Description
        SLM_blank_pointer;
        SLM_Image_pointer;
        SLM_Image;
        SLM_Image_plot;
        ViewHologramImage
        ViewHologramImage_pointer;
        hologram_fig_plot;
        blank_SLM_image;
        UIImagePhaseTableSelection;
    end
    
    methods (Access = private)
        
        function if_initialize_GUI_params(app)
            app.SLMheightEditField.Value = app.SLM_ops.height;
            app.SLMwidthEditField.Value = app.SLM_ops.width;
            app.blank_SLM_image = zeros(app.SLM_ops.height, app.SLM_ops.width);
            
            % Fresnel lens
            app.FresCenterXEditField.Value = app.SLM_ops.width/2;
            app.FresCenterYEditField.Value = app.SLM_ops.height/2;
            app.FresRadiusEditField.Value = app.SLM_ops.height/2;
            app.FresPowerEditField.Value = 1;
            app.FresCylindricalCheckBox.Value = 1;
            app.FresHorizontalCheckBox.Value = 0;
            
            % Blazed grating
            app.BlazPeriodEditField.Value = 128;
            app.BlazIncreasingCheckBox.Value = 1;
            app.BlazHorizontalCheckBox.Value = 0;
            
            % Stripes
            app.StripePixelPerStripeEditField.Value = 8;
            app.StripePixelValueEditField.Value = 0;
            app.StripeGrayEditField.Value = 255;
            
            % Multiplane imaging
            app.UIImagePhaseTable.Data = table();
            
            % initialize blank image
            app.BlankPixelValueEditField.Value = 0;
            app.SLM_Image_pointer = if_initialize_pointer(app);
            app.ViewHologramImage_pointer = if_initialize_pointer(app);
            app.SLM_blank_pointer = if_initialize_pointer(app);
                        
            app.SLM_Image = single(reshape(app.SLM_Image_pointer.Value, [app.SLM_ops.width,app.SLM_ops.height]));
            app.SLM_Image = rot90(mod(app.SLM_Image, 256));
            app.SLM_Image = (app.SLM_Image/255)*(2*pi);
            app.SLM_Image_plot = imagesc(app.UIAxesGenerateHologram, app.SLM_Image);
            axis(app.UIAxesGenerateHologram, 'tight');
            caxis(app.UIAxesGenerateHologram, [0 2*pi]);
            
        end
        
        function pointer = if_initialize_pointer(app)
            pointer = libpointer('uint8Ptr', zeros(app.SLM_ops.width*app.SLM_ops.height,1));
            calllib('ImageGen', 'Generate_Solid', pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.BlankPixelValueEditField.Value);
        end
        
        function holo_pointer_value = if_convert_to_pointer(~, holo_image)
            temp_holo = uint8((rot90(holo_image, 3)/(2*pi))*255);
            holo_pointer_value = reshape(temp_holo, [],1);
        end
        
        function if_plot_SLM_image(app)
            app.SLM_Image = single(reshape(app.SLM_Image_pointer.Value, [app.SLM_ops.width,app.SLM_ops.height]));
            app.SLM_Image = rot90(mod(app.SLM_Image, 256));
            app.SLM_Image = (app.SLM_Image/255)*(2*pi);
            app.SLM_Image_plot.CData = app.SLM_Image;
        end
        
        function if_view_hologram_phase(app, holo_image)
            figure;
            app.hologram_fig_plot = newplot;
            imagesc(app.hologram_fig_plot, holo_image);
            axis(app.hologram_fig_plot, 'image');
            caxis(app.hologram_fig_plot, [0 2*pi]);
            colorbar(app.hologram_fig_plot, 'eastoutside');
        end
        
        function if_view_hologram_fft(app, holo_image, defocus_dist)
            
            dims = [app.SLMheightEditField.Value, app.SLMwidthEditField.Value];
            siz = max(dims);
            
            phase_sq = zeros(siz,siz);
            
            % beam shape
            Lx = linspace(-(siz-1)/2,(siz-1)/2,siz);
            [c_X, c_Y] = meshgrid(Lx, Lx);
            
            sigma = app.BeamdiameterSTDEditField.Value; 			% beam waist/2
            x0 = 0;                 % beam center location
            y0 = 0;                 % beam center location
            A = 1;                  % peak of the beam 
            res = ((c_X-x0).^2 + (c_Y-y0).^2)./(2*sigma^2);
            pupil_amp = A  * exp(-res);
            
            pupil_mask = phase_sq;
            pupil_mask((1 + (siz - dims(1))/2):(siz - (siz - dims(1))/2),(1 + (siz - dims(2))/2):(siz - (siz - dims(2))/2)) = 1;
            
            pupil_amp = pupil_amp.*pupil_mask;

            defocus = f_SLM_DefocusPhase_YS(siz,...
                                    siz,...
                                    app.ObjectiveNAEditField.Value,...
                                    app.ObjectiveRIEditField.Value,...
                                    app.WavelengthnmEditField.Value*10e-9);
                                
            defocus = defocus .* pupil_mask;
            
            holo_image1 = phase_sq;
            holo_image1((1 + (siz - dims(1))/2):(siz - (siz - dims(1))/2),(1 + (siz - dims(2))/2):(siz - (siz - dims(2))/2)) = holo_image;
            
            SLM_complex_wave=pupil_amp.*(exp(1i.*(holo_image1-pi))./exp(1i.*(defocus_dist.*defocus)));
           
%             phase=angle(SLM_complex_wave)+pi;
%             amp = abs(SLM_complex_wave);
            
            im1 = fftshift(fft2(SLM_complex_wave));
            im1_amp = abs(im1)/sum(abs(SLM_complex_wave(:)));
            
            if app.fftampsquaredCheckBox.Value
                im1_amp = im1_amp.^2;
            end
            
%             figure; imagesc(phase)
%             figure; imagesc(amp)
%             figure; imagesc(abs(im1))
            
            figure;
            app.hologram_fig_plot = newplot;
            imagesc(app.hologram_fig_plot,im1_amp);
            axis(app.hologram_fig_plot, 'image');
            caxis(app.hologram_fig_plot, [0 1]);
            colorbar(app.hologram_fig_plot, 'eastoutside');
            if app.fftzoomed20xCheckBox.Value
                xlim(app.hologram_fig_plot,[siz/2-siz/20 siz/2+siz/20]);
                ylim(app.hologram_fig_plot,[siz/2-siz/20 siz/2+siz/20]);
            end
        end
        
        function holo_image = if_gen_holo_multiplane_image(app, xyzp, SLMm, SLMn, weight, objectiveNA, objectiveRI, illuminationWavelength)
            if (~exist('xyzp', 'var') || isempty(xyzp))
                xyzp = [app.XdisplacementEditField.Value, app.YdisplacementEditField.Value, app.ZOffsetumEditField.Value*10e-6];
            end
            if (~exist('SLMm', 'var') || isempty(SLMm)); SLMm = app.SLMheightEditField.Value; end
            if (~exist('SLMn', 'var') || isempty(SLMn)); SLMn = app.SLMwidthEditField.Value; end
            if (~exist('weight', 'var') || isempty(weight)); weight = 1; end
            if (~exist('objectiveNA', 'var') || isempty(objectiveNA)); objectiveNA = app.ManualNAEditField.Value; end
            if (~exist('objectiveRI', 'var') || isempty(objectiveRI)); objectiveRI = app.ObjectiveRIEditField.Value; end
            if (~exist('illuminationWavelength', 'var') || isempty(illuminationWavelength)); illuminationWavelength = app.WavelengthnmEditField.Value*10e-9; end

            holo_image = f_SLM_PhaseHologram_YS(xyzp, SLMm,SLMn,weight,objectiveNA,objectiveRI,illuminationWavelength);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SLM_ops = f_SLM_initialize_ops_YS();
            app.SLM_ops = f_SLM_initialize_YS(app.SLM_ops);
            
            
            if_initialize_GUI_params(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f_SLM_update_YS(app.SLM_ops, app.SLM_blank_pointer);
            app.SLM_ops = f_SLM_close_YS(app.SLM_ops);
            delete(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            if app.ActivateSLMButton.Value
                app.SLM_ops = f_SLM_initialize_YS(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
            else
                app.SLM_ops = f_SLM_close_YS(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: GenerateBlankButton
        function GenerateBlankLensButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Solid', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.BlankPixelValueEditField.Value);
            if_plot_SLM_image(app);
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            calllib('ImageGen', 'Generate_FresnelLens', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.FresCenterXEditField.Value, app.FresCenterYEditField.Value, app.FresRadiusEditField.Value,...
                        app.FresPowerEditField.Value, app.FresCylindricalCheckBox.Value, app.FresHorizontalCheckBox.Value);
            if_plot_SLM_image(app);
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Grating', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.BlazPeriodEditField.Value, app.BlazIncreasingCheckBox.Value, app.BlazHorizontalCheckBox.Value);
            if_plot_SLM_image(app);
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Stripe', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.StripePixelValueEditField.Value, app.StripeGrayEditField.Value, app.StripePixelPerStripeEditField.Value);
            if_plot_SLM_image(app);
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            app.UIImagePhaseTable.Data = array2table([app.UIImagePhaseTable.Data.Variables;...
                                            app.PlanenumberEditField.Value,...
                                            app.ZOffsetumEditField.Value,...
                                            app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ManualNAEditField.Value,...
                                            app.WeightEditField.Value]);
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),:) = [];
                if size(app.UIImagePhaseTable.Data,1) < app.UIImagePhaseTableSelection(1)
                    app.UIImagePhaseTableSelection = [];
                end
            end
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            temp_image =  if_gen_holo_multiplane_image(app, [app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ZOffsetumEditField.Value*10e-6],...
                                            [],...
                                            [],...
                                            app.WeightEditField.Value,...
                                            app.ManualNAEditField.Value);
            app.SLM_Image_pointer.Value = if_convert_to_pointer(app, temp_image);
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            temp_image =  if_gen_holo_multiplane_image(app, [app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ZOffsetumEditField.Value*10e-6],...
                                            [],...
                                            [],...
                                            app.WeightEditField.Value,...
                                            app.ManualNAEditField.Value); 
            if_view_hologram_phase(app, temp_image);
            title(sprintf('Defocus %.1f um', app.ZOffsetumEditField.Value));
        end

        % Button pushed function: UploadselectedButton
        function UploadselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = if_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables); 
                app.SLM_Image_pointer.Value = if_convert_to_pointer(app, temp_image);
                f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
            end
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = if_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables); 
                if_view_hologram_phase(app, temp_image);
                title(sprintf('Defocus %.1f um', app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables));
            end
        end

        % Button pushed function: ViewselectedfftButton
        function ViewselectedfftButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = if_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables);
                if_view_hologram_fft(app, temp_image, app.fftdefocusumEditField.Value*10e-6);
                title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
            end
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: ViewplanephaseButton
        function ViewplanephaseButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            if_view_hologram_phase(app, holo_image);
            
            
%             planes_idx = app.UIImagePhaseTable.Data(:,1).Variables;
%             temp_weights = app.UIImagePhaseTable.Data(planes_idx==curr_plane,6).Variables;
%             
%             if sum(curr_plane == planes_idx)
%                 planes_cell = cell(sum(curr_plane == planes_idx),1);
%                 
%                 curr_indx = 1;
%                 for n_row = 1:numel(planes_idx)
%                     if planes_idx(n_row) == curr_plane
%                         planes_cell{curr_indx} = if_gen_holo_multiplane_image(app,...
%                                             [app.UIImagePhaseTable.Data(n_row,3).Variables,...
%                                             app.UIImagePhaseTable.Data(n_row,4).Variables,...
%                                             app.UIImagePhaseTable.Data(n_row,2).Variables*10e-6],...
%                                             [],...
%                                             [],...
%                                             app.UIImagePhaseTable.Data(n_row,6).Variables,...
%                                             app.UIImagePhaseTable.Data(n_row,5).Variables); 
%                         curr_indx = curr_indx + 1;
%                         
%                     end
%                 end
%                 total_phase = f_SLM_superimpose_phase(planes_cell, temp_weights);
%             end
%             if_view_hologram_phase(app, total_phase);
        end

        % Button pushed function: UploadPlaneButton
        function UploadPlaneButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            app.SLM_Image_pointer.Value = if_convert_to_pointer(app, holo_image);
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: ViewplanefftButton
        function ViewplanefftButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            if_view_hologram_fft(app, holo_image, app.fftdefocusumEditField.Value*10e-6);
            title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            if app.InitializeimagingButton.Value
                try
                    plane_table = app.UIImagePhaseTable.Data(:,:).Variables;
                    planes = unique (plane_table(:,1));
                    num_planes = numel(planes);
                    volumes = app.VolumesEditField.Value;
                    
                    %% compute patterns
                    holo_pointers = cell(num_planes,1);
                    for n_pl = 1:num_planes
                        holo_pointers{n_pl} = if_initialize_pointer(app);
                        
                        
                        curr_plane = planes(n_pl);
                        plane_subtable = plane_table(plane_table(:,1) == curr_plane,:);
            
                        holo_image = f_SLM_PhaseHologram_YS([plane_subtable(:,3:4), plane_subtable(:,2)*10e-6],...
                                                        app.SLMheightEditField.Value,...
                                                        app.SLMwidthEditField.Value,...
                                                        plane_subtable(:,6),...
                                                        plane_subtable(:,5),...
                                                        app.ObjectiveRIEditField.Value,...
                                                        app.WavelengthnmEditField.Value*10e-9);
                        holo_pointers{n_pl} = if_convert_to_pointer(app, holo_image);                
                    end
                    disp('Holograms created');
                    
                    % initialize DAQ
                    session = daq.createSession ('ni');
                    % Setup counter, PFI12
                    addCounterInputChannel(session, app.NIDAQdeviceEditField.Value, 'ctr0', 'EdgeCount');
                    resetCounters(session);
                    inputSingleScan(session);
                    
                    disp('DAQ initialized');
                    
                    app.ImagingReadyLamp.Color = [0.00,1.00,0.00];
                    pause(0.05);
                    disp('Ready');
                    f_SLM_update_YS(app.SLM_ops, holo_pointers{1}); 
                    for n_vol = 1:volumes
                        for n_pl = 1:num_planes
                            while inputSingleScan(session)==0
                            end
                            f_SLM_update_YS(app.SLM_ops, holo_pointers{rem(n_pl,num_planes)+1});      % z position changes at the end of the frame
                            resetCounters(session);
                        end
                    end
                    f_SLM_update_YS(app.SLM_ops, app.SLM_blank_pointer);
                    app.InitializeimagingButton.Value = 0;
                    app.ImagingReadyLamp.Color = [0.80,0.80,0.80];
                    
                catch
                    app.InitializeimagingButton.Value = 0;
                    app.ImagingReadyLamp.Color = [0.80,0.80,0.80];
                    disp('Imaging run failed')
                    f_SLM_update_YS(app.SLM_ops, app.SLM_blank_pointer);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.BlankTab = uitab(app.TabGroup2);
            app.BlankTab.Title = 'Blank';
            app.GenerateBlankButton = uibutton(app.BlankTab, 'push');
            app.GenerateBlankButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GenerateBlankButton.Text = 'Generate Hologram';
            app.PixelValueLabel = uilabel(app.BlankTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.BlankTab, 'numeric');
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Hologram';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterXEditField.Value = 960;
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Hologram';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 128;
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Hologram';
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 255;
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram Phase')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.MultiplaneImagingTab = uitab(app.TabGroup);
            app.MultiplaneImagingTab.Title = 'Multiplane Imaging';
            app.InitializeimagingButton = uibutton(app.MultiplaneImagingTab, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.ListofhologramsLabel = uilabel(app.MultiplaneImagingTab);
            app.ListofhologramsLabel.Text = 'List of holograms';
            app.CreateimagingphasePanel = uipanel(app.MultiplaneImagingTab);
            app.CreateimagingphasePanel.Title = 'Create imaging phase';
            app.UploadHologramButton = uibutton(app.CreateimagingphasePanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.ZOffsetumEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ZOffsetumEditFieldLabel.Text = 'Z Offset (um)';
            app.ZOffsetumEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAcorrectionCheckBox = uicheckbox(app.CreateimagingphasePanel);
            app.ManualNAcorrectionCheckBox.Text = 'Manual NA correction';
            app.ManualNAEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ManualNAEditFieldLabel.Text = 'Manual NA';
            app.ManualNAEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAEditField.Limits = [0 2];
            app.ManualNAEditField.ValueDisplayFormat = '%.2f';
            app.ManualNAEditField.Value = 0.95;
            app.DefocusLabel = uilabel(app.CreateimagingphasePanel);
            app.DefocusLabel.Text = 'Defocus';
            app.XYdisplacementLabel = uilabel(app.CreateimagingphasePanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.XdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.XdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.YdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ViewHologramButtonMultiplane = uibutton(app.CreateimagingphasePanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.WeightEditField.Value = 1;
            app.PlanenumberEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.PlanenumberEditFieldLabel.Text = 'Plane number';
            app.PlanenumberEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.PlanenumberEditField.Limits = [1 Inf];
            app.PlanenumberEditField.RoundFractionalValues = 'on';
            app.PlanenumberEditField.Value = 1;
            app.UploadselectedButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.UploadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedButtonPushed, true);
            app.UploadselectedButton.Text = 'Upload selected';
            app.FrameswitchingmethodButtonGroup = uibuttongroup(app.MultiplaneImagingTab);
            app.FrameswitchingmethodButtonGroup.Title = 'Frame switching method';
            app.EndofframetriggeringButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.EndofframetriggeringButton.Text = 'End of frame triggering';
            app.EndofframetriggeringButton.Value = true;
            app.TimedelayButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.TimedelayButton.Text = 'Time delay';
            app.MoveupButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.MovedownButton.Text = 'Move down';
            app.UIImagePhaseTable = uitable(app.MultiplaneImagingTab);
            app.UIImagePhaseTable.ColumnName = {'Plane #'; 'Z (um)'; 'X'; 'Y'; 'NA'; 'Weight'};
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = true;
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.RemoveselectedButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.AddtohologramButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.ViewpredictedPSFButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.ViewselectedphaseButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.PlaneSpinnerLabel = uilabel(app.MultiplaneImagingTab);
            app.PlaneSpinnerLabel.Text = 'Plane';
            app.PlaneSpinner = uispinner(app.MultiplaneImagingTab);
            app.PlaneSpinner.RoundFractionalValues = 'on';
            app.PlaneSpinner.Value = 1;
            app.ViewplanephaseButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewplanephaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanephaseButtonPushed, true);
            app.ViewplanephaseButton.Text = 'View plane phase';
            app.UploadPlaneButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.UploadPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPlaneButtonPushed, true);
            app.UploadPlaneButton.Text = 'Upload Plane';
            app.ViewselectedfftButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewselectedfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedfftButtonPushed, true);
            app.ViewselectedfftButton.Text = 'View selected fft';
            app.ViewplanefftButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewplanefftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanefftButtonPushed, true);
            app.ViewplanefftButton.Text = 'View plane fft';
            app.fftdefocusumEditFieldLabel = uilabel(app.MultiplaneImagingTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.fftdefocusumEditField = uieditfield(app.MultiplaneImagingTab, 'numeric');
            app.fftampsquaredCheckBox = uicheckbox(app.MultiplaneImagingTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftzoomed20xCheckBox = uicheckbox(app.MultiplaneImagingTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.VolumesEditFieldLabel = uilabel(app.MultiplaneImagingTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.VolumesEditField = uieditfield(app.MultiplaneImagingTab, 'numeric');
            app.VolumesEditField.Limits = [1 Inf];
            app.VolumesEditField.Value = 1;
            app.ImagingReadyLamp = uilamp(app.MultiplaneImagingTab);
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.MakeXYZPPanel = uipanel(app.StimulationTab);
            app.MakeXYZPPanel.Title = 'Make XYZP';
            app.UIAxes = uiaxes(app.MakeXYZPPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.StimulationTab);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'; 'Weight'};
            app.UITable.RowName = {};
            app.PointsLabel = uilabel(app.StimulationTab);
            app.PointsLabel.Text = 'Points';
            app.SplitSLMTab = uitab(app.TabGroup);
            app.SplitSLMTab.Title = 'Split SLM';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMwidthEditField.Value = 1920;
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.SLMheightEditField.Value = 1152;
            app.ObjectiveNAEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveNAEditFieldLabel.Text = 'Objective NA';
            app.ObjectiveNAEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveNAEditField.Value = 0.95;
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.WavelengthnmEditFieldLabel = uilabel(app.ParametersTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WavelengthnmEditField.Value = 1064;
            app.SLMpresetoffsetXLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetXLabel.Text = 'SLM preset offset X';
            app.SLMpresetoffsetXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetYEditFieldLabel.Text = 'SLM preset offset Y';
            app.SLMpresetoffsetYEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditField.Value = -9;
            app.ObjectiveMagXEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveMagXEditFieldLabel.Text = 'Objective Mag (X)';
            app.ObjectiveMagXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveMagXEditField.Value = 20;
            app.BeamdiameterSTDEditFieldLabel = uilabel(app.ParametersTab);
            app.BeamdiameterSTDEditFieldLabel.Text = 'Beam diameter STD';
            app.BeamdiameterSTDEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BeamdiameterSTDEditField.Value = 1152;
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.NIDAQdeviceEditField.Value = 'dev1';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.ActivateSLMButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_control_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end