classdef SLM_control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GenerateHologramTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        BlankTab                        matlab.ui.container.Tab
        GenerateBlankButton             matlab.ui.control.Button
        PixelValueLabel                 matlab.ui.control.Label
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        FresnelLensTab                  matlab.ui.container.Tab
        GenerateFresnelLensButton       matlab.ui.control.Button
        CenterXEditFieldLabel           matlab.ui.control.Label
        FresCenterXEditField            matlab.ui.control.NumericEditField
        CenterYEditFieldLabel           matlab.ui.control.Label
        FresCenterYEditField            matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        FresRadiusEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        FresPowerEditField              matlab.ui.control.NumericEditField
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        ZernikeTab                      matlab.ui.container.Tab
        GenerateZernikeButton           matlab.ui.control.Button
        CenterXEditField_2Label         matlab.ui.control.Label
        CenterXEditField                matlab.ui.control.NumericEditField
        CenterYEditField_5Label         matlab.ui.control.Label
        CenterYEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel_2          matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        PistonEditFieldLabel            matlab.ui.control.Label
        PistonEditField                 matlab.ui.control.NumericEditField
        TiltXEditFieldLabel             matlab.ui.control.Label
        TiltXEditField                  matlab.ui.control.NumericEditField
        TiltYEditFieldLabel             matlab.ui.control.Label
        TiltYEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel_2           matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        AstigXEditFieldLabel            matlab.ui.control.Label
        AstigXEditField                 matlab.ui.control.NumericEditField
        AstigYEditFieldLabel            matlab.ui.control.Label
        AstigYEditField                 matlab.ui.control.NumericEditField
        ComaXEditFieldLabel             matlab.ui.control.Label
        ComaXEditField                  matlab.ui.control.NumericEditField
        ComaYEditFieldLabel             matlab.ui.control.Label
        ComaYEditField                  matlab.ui.control.NumericEditField
        PrimarySphericalEditFieldLabel  matlab.ui.control.Label
        PrimarySphericalEditField       matlab.ui.control.NumericEditField
        TrefoilXEditFieldLabel          matlab.ui.control.Label
        TrefoilXEditField               matlab.ui.control.NumericEditField
        TrefoilYEditFieldLabel          matlab.ui.control.Label
        TrefoilYEditField               matlab.ui.control.NumericEditField
        SecondaryAstigXEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigXEditField        matlab.ui.control.NumericEditField
        SecondaryAstigYEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigYEditField        matlab.ui.control.NumericEditField
        SecondaryComaXEditFieldLabel    matlab.ui.control.Label
        SecondaryComaXEditField         matlab.ui.control.NumericEditField
        SecondaryComaYEditFieldLabel    matlab.ui.control.Label
        SecondaryComaYEditField         matlab.ui.control.NumericEditField
        SecondarySphericalEditFieldLabel  matlab.ui.control.Label
        SecondarySphericalEditField     matlab.ui.control.NumericEditField
        TetrafoilXEditFieldLabel        matlab.ui.control.Label
        TetrafoilXEditField             matlab.ui.control.NumericEditField
        TetrafoilYEditFieldLabel        matlab.ui.control.Label
        TetrafoilYEditField             matlab.ui.control.NumericEditField
        TertiarySphericalEditFieldLabel  matlab.ui.control.Label
        TertiarySphericalEditField      matlab.ui.control.NumericEditField
        QuaternarySphericalEditFieldLabel  matlab.ui.control.Label
        QuaternarySphericalEditField    matlab.ui.control.NumericEditField
        BlazedGratingTab                matlab.ui.container.Tab
        GenerateBlazedGratingButton     matlab.ui.control.Button
        PeriodEditFieldLabel            matlab.ui.control.Label
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        StripesTab                      matlab.ui.container.Tab
        PixelsPerStripeLabel            matlab.ui.control.Label
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        GenerateStripesButton           matlab.ui.control.Button
        PixelValueEditFieldLabel        matlab.ui.control.Label
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        GrayEditFieldLabel              matlab.ui.control.Label
        StripeGrayEditField             matlab.ui.control.NumericEditField
        DefocusTab                      matlab.ui.container.Tab
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        UploadHologramButtonGenerate    matlab.ui.control.Button
        MultiplaneImagingTab            matlab.ui.container.Tab
        InitializeimagingButton         matlab.ui.control.StateButton
        ListofhologramsLabel            matlab.ui.control.Label
        CreateimagingphasePanel         matlab.ui.container.Panel
        UploadHologramButton            matlab.ui.control.Button
        ZOffsetumEditFieldLabel         matlab.ui.control.Label
        ZOffsetumEditField              matlab.ui.control.NumericEditField
        ManualNAcorrectionCheckBox      matlab.ui.control.CheckBox
        ManualNAEditFieldLabel          matlab.ui.control.Label
        ManualNAEditField               matlab.ui.control.NumericEditField
        DefocusLabel                    matlab.ui.control.Label
        XYdisplacementLabel             matlab.ui.control.Label
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditField          matlab.ui.control.NumericEditField
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        YdisplacementEditField          matlab.ui.control.NumericEditField
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        PlanenumberEditFieldLabel       matlab.ui.control.Label
        PlanenumberEditField            matlab.ui.control.NumericEditField
        UploadselectedButton            matlab.ui.control.Button
        FrameswitchingmethodButtonGroup  matlab.ui.container.ButtonGroup
        EndofframetriggeringButton      matlab.ui.control.ToggleButton
        TimedelayButton                 matlab.ui.control.ToggleButton
        MoveupButton                    matlab.ui.control.Button
        MovedownButton                  matlab.ui.control.Button
        UIImagePhaseTable               matlab.ui.control.Table
        RemoveselectedButton            matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        ViewpredictedPSFButton          matlab.ui.control.Button
        ViewselectedphaseButton         matlab.ui.control.Button
        PlaneSpinnerLabel               matlab.ui.control.Label
        PlaneSpinner                    matlab.ui.control.Spinner
        ViewplanephaseButton            matlab.ui.control.Button
        UploadPlaneButton               matlab.ui.control.Button
        ViewselectedfftButton           matlab.ui.control.Button
        ViewplanefftButton              matlab.ui.control.Button
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        VolumesEditFieldLabel           matlab.ui.control.Label
        VolumesEditField                matlab.ui.control.NumericEditField
        ImagingReadyLamp                matlab.ui.control.Lamp
        AbortImagingButton              matlab.ui.control.StateButton
        AdaptiveOpticsTab               matlab.ui.container.Tab
        ZernikeListTable                matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        MaxmodesEditFieldLabel          matlab.ui.control.Label
        MaxmodesEditField               matlab.ui.control.NumericEditField
        FillmodestableButton            matlab.ui.control.Button
        ZernikeModestoscanadjustweightsifneededLabel  matlab.ui.control.Label
        WeightlimitEditFieldLabel       matlab.ui.control.Label
        WeightlimitEditField            matlab.ui.control.NumericEditField
        WeightstepEditFieldLabel        matlab.ui.control.Label
        WeightstepEditField             matlab.ui.control.NumericEditField
        TestHologramandweightsPanel     matlab.ui.container.Panel
        PlotHologramButton              matlab.ui.control.Button
        UploadHologramButton_2          matlab.ui.control.Button
        ModeindexEditFieldLabel         matlab.ui.control.Label
        ModeindexEditField              matlab.ui.control.NumericEditField
        weightEditFieldLabel            matlab.ui.control.Label
        weightEditField                 matlab.ui.control.NumericEditField
        AOscanZernikepolynomialsPanel   matlab.ui.container.Panel
        SLMrefreshconditionSwitchLabel  matlab.ui.control.Label
        SLMrefreshconditionSwitch       matlab.ui.control.Switch
        DelaysecEditFieldLabel          matlab.ui.control.Label
        DelaysecEditField               matlab.ui.control.NumericEditField
        ZernikeReadyLamp                matlab.ui.control.Lamp
        ScanZernikeButton               matlab.ui.control.StateButton
        AnalyzeoutputimagesPanel        matlab.ui.container.Panel
        LoaddataButton                  matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        TotalmodesEditFieldLabel        matlab.ui.control.Label
        TotalmodesEditField             matlab.ui.control.NumericEditField
        StimulationTab                  matlab.ui.container.Tab
        MakeXYZPPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        PointsLabel                     matlab.ui.control.Label
        SplitSLMTab                     matlab.ui.container.Tab
        ParametersTab                   matlab.ui.container.Tab
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        SLMwidthEditField               matlab.ui.control.NumericEditField
        SLMheightEditFieldLabel         matlab.ui.control.Label
        SLMheightEditField              matlab.ui.control.NumericEditField
        ObjectiveNAEditFieldLabel       matlab.ui.control.Label
        ObjectiveNAEditField            matlab.ui.control.NumericEditField
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        SLMpresetoffsetXLabel           matlab.ui.control.Label
        SLMpresetoffsetXEditField       matlab.ui.control.NumericEditField
        SLMpresetoffsetYEditFieldLabel  matlab.ui.control.Label
        SLMpresetoffsetYEditField       matlab.ui.control.NumericEditField
        ObjectiveMagXEditFieldLabel     matlab.ui.control.Label
        ObjectiveMagXEditField          matlab.ui.control.NumericEditField
        BeamdiameterpixEditFieldLabel   matlab.ui.control.Label
        BeamdiameterpixEditField        matlab.ui.control.NumericEditField
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        NIDAQdeviceEditField            matlab.ui.control.EditField
        DAQAOchannelforTRIGEditFieldLabel  matlab.ui.control.Label
        DAQAOchannelforTRIGEditField    matlab.ui.control.NumericEditField
        DAQcounterchannelEditFieldLabel  matlab.ui.control.Label
        DAQcounterchannelEditField      matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ActivateSLMLamp                 matlab.ui.control.Lamp
        ActivateSLMButton               matlab.ui.control.StateButton
        InitializeDAQButton             matlab.ui.control.Button
        InitializeDAQLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        SLM_ops; % Description
        SLM_blank_pointer;
        SLM_Image_pointer;
        SLM_Image;
        SLM_Image_plot;
        ViewHologramImage
        ViewHologramImage_pointer;
        hologram_fig_plot;
        blank_SLM_image;
        UIImagePhaseTableSelection;
        DAQ_session;
        AO_ignore_modes_list = [1,2,3,5];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SLM_ops = f_SLM_initialize_ops_YS();
            app.SLM_ops = f_SLM_initialize_YS(app.SLM_ops);

            f_SLM_initialize_GUI_params(app);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f_SLM_update_YS(app.SLM_ops, app.SLM_blank_pointer);
            app.SLM_ops = f_SLM_close_YS(app.SLM_ops);
            delete(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            if app.ActivateSLMButton.Value
                app.SLM_ops = f_SLM_initialize_YS(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
            else
                app.SLM_ops = f_SLM_close_YS(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: GenerateBlankButton
        function GenerateBlankLensButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Solid', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.BlankPixelValueEditField.Value);
            f_SLM_plot_SLM_image(app);
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            calllib('ImageGen', 'Generate_FresnelLens', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.FresCenterXEditField.Value, app.FresCenterYEditField.Value, app.FresRadiusEditField.Value,...
                        app.FresPowerEditField.Value, app.FresCylindricalCheckBox.Value, app.FresHorizontalCheckBox.Value);
            f_SLM_plot_SLM_image(app);
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Grating', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.BlazPeriodEditField.Value, app.BlazIncreasingCheckBox.Value, app.BlazHorizontalCheckBox.Value);
            f_SLM_plot_SLM_image(app);
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Stripe', app.SLM_Image_pointer, app.SLM_ops.width, app.SLM_ops.height,...
                        app.StripePixelValueEditField.Value, app.StripeGrayEditField.Value, app.StripePixelPerStripeEditField.Value);
            f_SLM_plot_SLM_image(app);
        end

        % Button pushed function: GenerateZernikeButton
        function GenerateZernikeButtonPushed(app, event)
            f_SLM_generate_zernike(app)
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            app.UIImagePhaseTable.Data = array2table([app.UIImagePhaseTable.Data.Variables;...
                                            app.PlanenumberEditField.Value,...
                                            app.ZOffsetumEditField.Value,...
                                            app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ManualNAEditField.Value,...
                                            app.WeightEditField.Value]);
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),:) = [];
                if size(app.UIImagePhaseTable.Data,1) < app.UIImagePhaseTableSelection(1)
                    app.UIImagePhaseTableSelection = [];
                end
            end
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            temp_image =  f_SLM_gen_holo_multiplane_image(app, [app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ZOffsetumEditField.Value*10e-6],...
                                            [],...
                                            [],...
                                            app.WeightEditField.Value,...
                                            app.ManualNAEditField.Value);
            app.SLM_Image_pointer.Value = f_SLM_convert_to_pointer(app, temp_image);
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            temp_image =  f_SLM_gen_holo_multiplane_image(app, [app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ZOffsetumEditField.Value*10e-6],...
                                            [],...
                                            [],...
                                            app.WeightEditField.Value,...
                                            app.ManualNAEditField.Value); 
            f_SLM_view_hologram_phase(app, temp_image);
            title(sprintf('Defocus %.1f um', app.ZOffsetumEditField.Value));
        end

        % Button pushed function: UploadselectedButton
        function UploadselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = f_SLM_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables); 
                app.SLM_Image_pointer.Value = f_SLM_convert_to_pointer(app, temp_image);
                f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
            end
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = f_SLM_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables); 
                f_SLM_view_hologram_phase(app, temp_image);
                title(sprintf('Defocus %.1f um', app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables));
            end
        end

        % Button pushed function: ViewselectedfftButton
        function ViewselectedfftButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                temp_image = f_SLM_gen_holo_multiplane_image(app,...
                                            [app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),3).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),4).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables*10e-6],...
                                            [],...
                                            [],...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),6).Variables,...
                                            app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),5).Variables);
                f_SLM_view_hologram_fft(app, temp_image, app.fftdefocusumEditField.Value*10e-6);
                title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
            end
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: ViewplanephaseButton
        function ViewplanephaseButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            f_SLM_view_hologram_phase(app, holo_image);
        end

        % Button pushed function: UploadPlaneButton
        function UploadPlaneButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            app.SLM_Image_pointer.Value = f_SLM_convert_to_pointer(app, holo_image);
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: ViewplanefftButton
        function ViewplanefftButtonPushed(app, event)
            curr_plane = round(app.PlaneSpinner.Value);
            plane_table = app.UIImagePhaseTable.Data(app.UIImagePhaseTable.Data(:,1).Variables == curr_plane,:).Variables;

            holo_image = f_SLM_PhaseHologram_YS([plane_table(:,3:4), plane_table(:,2)*10e-6],...
                                            app.SLMheightEditField.Value,...
                                            app.SLMwidthEditField.Value,...
                                            plane_table(:,6),...
                                            plane_table(:,5),...
                                            app.ObjectiveRIEditField.Value,...
                                            app.WavelengthnmEditField.Value*10e-9);
            f_SLM_view_hologram_fft(app, holo_image, app.fftdefocusumEditField.Value*10e-6);
            title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            f_SLM_initialize_imaging(app);
        end

        % Callback function
        function ScanZernikeButtonPushed(app, event)
            f_SLM_AO_scan_zernike(app);
        end

        % Button pushed function: FillmodestableButton
        function FillmodestableButtonPushed(app, event)
            f_SLM_AO_fill_modes(app);
        end

        % Button pushed function: PlotHologramButton
        function PlotHologramButtonPushed(app, event)
            [phase, n, m] = f_SLM_AO_gen_test_hologram(app);
            f_SLM_plot_phase_figure(phase, sprintf('Zernike Hologram %d, w=%d; Znm n=%d; m=%d',app.ModeindexEditField.Value,app.weightEditField.Value, n, m));
        end

        % Button pushed function: UploadHologramButton_2
        function UploadHologramButton_2Pushed(app, event)
            phase = f_SLM_AO_gen_test_hologram(app);
            app.SLM_Image_pointer.Value = f_SLM_convert_to_pointer(app, phase);
            f_SLM_update_YS(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            f_SLM_initialize_DAQ(app);
        end

        % Value changed function: ScanZernikeButton
        function ScanZernikeButtonValueChanged(app, event)
            f_SLM_AO_scan_zernike(app);
        end

        % Cell edit callback: ZernikeListTable
        function ZernikeListTableCellEdit(app, event)
            f_SLM_AO_update_total_modes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.BlankTab = uitab(app.TabGroup2);
            app.BlankTab.Title = 'Blank';
            app.GenerateBlankButton = uibutton(app.BlankTab, 'push');
            app.GenerateBlankButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GenerateBlankButton.Text = 'Generate Blank';
            app.PixelValueLabel = uilabel(app.BlankTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.BlankTab, 'numeric');
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Fresnel';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterXEditField.Value = 960;
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.ZernikeTab = uitab(app.TabGroup2);
            app.ZernikeTab.Title = 'Zernike';
            app.GenerateZernikeButton = uibutton(app.ZernikeTab, 'push');
            app.GenerateZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateZernikeButtonPushed, true);
            app.GenerateZernikeButton.Text = 'Generate Zernike';
            app.CenterXEditField_2Label = uilabel(app.ZernikeTab);
            app.CenterXEditField_2Label.Text = 'CenterX';
            app.CenterXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterYEditField_5Label = uilabel(app.ZernikeTab);
            app.CenterYEditField_5Label.Text = 'CenterY';
            app.CenterYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.RadiusEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.RadiusEditFieldLabel_2.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PistonEditFieldLabel = uilabel(app.ZernikeTab);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.PistonEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltXEditFieldLabel.Text = 'TiltX';
            app.TiltXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltYEditFieldLabel.Text = 'TiltY';
            app.TiltYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PowerEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.PowerEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigXEditFieldLabel.Text = 'AstigX';
            app.AstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigYEditFieldLabel.Text = 'AstigY';
            app.AstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaXEditFieldLabel.Text = 'ComaX';
            app.ComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaYEditFieldLabel.Text = 'ComaY';
            app.ComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PrimarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.PrimarySphericalEditFieldLabel.Text = 'PrimarySpherical';
            app.PrimarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilXEditFieldLabel.Text = 'TrefoilX';
            app.TrefoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilYEditFieldLabel.Text = 'TrefoilY';
            app.TrefoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigXEditFieldLabel.Text = 'SecondaryAstigX';
            app.SecondaryAstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigYEditFieldLabel.Text = 'SecondaryAstigY';
            app.SecondaryAstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaXEditFieldLabel.Text = 'SecondaryComaX';
            app.SecondaryComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaYEditFieldLabel.Text = 'SecondaryComaY';
            app.SecondaryComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondarySphericalEditFieldLabel.Text = 'SecondarySpherical';
            app.SecondarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilXEditFieldLabel.Text = 'TetrafoilX';
            app.TetrafoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilYEditFieldLabel.Text = 'TetrafoilY';
            app.TetrafoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TertiarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.TertiarySphericalEditFieldLabel.Text = 'TertiarySpherical';
            app.TertiarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.QuaternarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.QuaternarySphericalEditFieldLabel.Text = 'QuaternarySpherical';
            app.QuaternarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Blazed';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 128;
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Stripes';
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 255;
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram Phase')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.MultiplaneImagingTab = uitab(app.TabGroup);
            app.MultiplaneImagingTab.Title = 'Multiplane Imaging';
            app.InitializeimagingButton = uibutton(app.MultiplaneImagingTab, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.ListofhologramsLabel = uilabel(app.MultiplaneImagingTab);
            app.ListofhologramsLabel.Text = 'List of holograms';
            app.CreateimagingphasePanel = uipanel(app.MultiplaneImagingTab);
            app.CreateimagingphasePanel.Title = 'Create imaging phase';
            app.UploadHologramButton = uibutton(app.CreateimagingphasePanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.ZOffsetumEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ZOffsetumEditFieldLabel.Text = 'Z Offset (um)';
            app.ZOffsetumEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAcorrectionCheckBox = uicheckbox(app.CreateimagingphasePanel);
            app.ManualNAcorrectionCheckBox.Text = 'Manual NA correction';
            app.ManualNAEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ManualNAEditFieldLabel.Text = 'Manual NA';
            app.ManualNAEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAEditField.Limits = [0 2];
            app.ManualNAEditField.ValueDisplayFormat = '%.2f';
            app.ManualNAEditField.Value = 0.95;
            app.DefocusLabel = uilabel(app.CreateimagingphasePanel);
            app.DefocusLabel.Text = 'Defocus';
            app.XYdisplacementLabel = uilabel(app.CreateimagingphasePanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.XdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.XdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.YdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ViewHologramButtonMultiplane = uibutton(app.CreateimagingphasePanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.WeightEditField.Value = 1;
            app.PlanenumberEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.PlanenumberEditFieldLabel.Text = 'Plane number';
            app.PlanenumberEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.PlanenumberEditField.Limits = [1 Inf];
            app.PlanenumberEditField.RoundFractionalValues = 'on';
            app.PlanenumberEditField.Value = 1;
            app.UploadselectedButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.UploadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedButtonPushed, true);
            app.UploadselectedButton.Text = 'Upload selected';
            app.FrameswitchingmethodButtonGroup = uibuttongroup(app.MultiplaneImagingTab);
            app.FrameswitchingmethodButtonGroup.Title = 'Frame switching method';
            app.EndofframetriggeringButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.EndofframetriggeringButton.Text = 'End of frame triggering';
            app.EndofframetriggeringButton.Value = true;
            app.TimedelayButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.TimedelayButton.Text = 'Time delay';
            app.MoveupButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.MovedownButton.Text = 'Move down';
            app.UIImagePhaseTable = uitable(app.MultiplaneImagingTab);
            app.UIImagePhaseTable.ColumnName = {'Plane #'; 'Z (um)'; 'X'; 'Y'; 'NA'; 'Weight'};
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = true;
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.RemoveselectedButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.AddtohologramButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.ViewpredictedPSFButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.ViewselectedphaseButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.PlaneSpinnerLabel = uilabel(app.MultiplaneImagingTab);
            app.PlaneSpinnerLabel.Text = 'Plane';
            app.PlaneSpinner = uispinner(app.MultiplaneImagingTab);
            app.PlaneSpinner.RoundFractionalValues = 'on';
            app.PlaneSpinner.Value = 1;
            app.ViewplanephaseButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewplanephaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanephaseButtonPushed, true);
            app.ViewplanephaseButton.Text = 'View plane phase';
            app.UploadPlaneButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.UploadPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPlaneButtonPushed, true);
            app.UploadPlaneButton.Text = 'Upload Plane';
            app.ViewselectedfftButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewselectedfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedfftButtonPushed, true);
            app.ViewselectedfftButton.Text = 'View selected fft';
            app.ViewplanefftButton = uibutton(app.MultiplaneImagingTab, 'push');
            app.ViewplanefftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanefftButtonPushed, true);
            app.ViewplanefftButton.Text = 'View plane fft';
            app.fftdefocusumEditFieldLabel = uilabel(app.MultiplaneImagingTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.fftdefocusumEditField = uieditfield(app.MultiplaneImagingTab, 'numeric');
            app.fftampsquaredCheckBox = uicheckbox(app.MultiplaneImagingTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftzoomed20xCheckBox = uicheckbox(app.MultiplaneImagingTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.VolumesEditFieldLabel = uilabel(app.MultiplaneImagingTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.VolumesEditField = uieditfield(app.MultiplaneImagingTab, 'numeric');
            app.VolumesEditField.Limits = [1 Inf];
            app.VolumesEditField.Value = 1;
            app.ImagingReadyLamp = uilamp(app.MultiplaneImagingTab);
            app.AbortImagingButton = uibutton(app.MultiplaneImagingTab, 'state');
            app.AbortImagingButton.Text = 'Abort Imaging';
            app.AdaptiveOpticsTab = uitab(app.TabGroup);
            app.AdaptiveOpticsTab.Title = 'Adaptive Optics';
            app.ZernikeListTable = uitable(app.AdaptiveOpticsTab);
            app.ZernikeListTable.ColumnName = {'idx'; 'Zm'; 'Zn'; 'W - lim'; 'W step'; 'W + lim'; 'Include'};
            app.ZernikeListTable.RowName = {};
            app.ZernikeListTable.ColumnEditable = [false false false true true true true];
            app.ZernikeListTable.CellEditCallback = createCallbackFcn(app, @ZernikeListTableCellEdit, true);
            app.Image = uiimage(app.AdaptiveOpticsTab);
            app.Image.ImageSource = 'Zernike_polynomials.png';
            app.MaxmodesEditFieldLabel = uilabel(app.AdaptiveOpticsTab);
            app.MaxmodesEditFieldLabel.Text = 'Max modes';
            app.MaxmodesEditField = uieditfield(app.AdaptiveOpticsTab, 'numeric');
            app.MaxmodesEditField.Value = 4;
            app.FillmodestableButton = uibutton(app.AdaptiveOpticsTab, 'push');
            app.FillmodestableButton.ButtonPushedFcn = createCallbackFcn(app, @FillmodestableButtonPushed, true);
            app.FillmodestableButton.Text = 'Fill modes table';
            app.ZernikeModestoscanadjustweightsifneededLabel = uilabel(app.AdaptiveOpticsTab);
            app.ZernikeModestoscanadjustweightsifneededLabel.Text = 'Zernike Modes to scan (adjust weights if needed)';
            app.WeightlimitEditFieldLabel = uilabel(app.AdaptiveOpticsTab);
            app.WeightlimitEditFieldLabel.Text = 'Weight limit';
            app.WeightlimitEditField = uieditfield(app.AdaptiveOpticsTab, 'numeric');
            app.WeightlimitEditField.Value = 5;
            app.WeightstepEditFieldLabel = uilabel(app.AdaptiveOpticsTab);
            app.WeightstepEditFieldLabel.Text = 'Weight step';
            app.WeightstepEditField = uieditfield(app.AdaptiveOpticsTab, 'numeric');
            app.WeightstepEditField.Value = 1;
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.AOscanZernikepolynomialsPanel = uipanel(app.AdaptiveOpticsTab);
            app.AOscanZernikepolynomialsPanel.Title = 'AO scan Zernike polynomials';
            app.SLMrefreshconditionSwitchLabel = uilabel(app.AOscanZernikepolynomialsPanel);
            app.SLMrefreshconditionSwitchLabel.Text = 'SLM refresh condition';
            app.SLMrefreshconditionSwitch = uiswitch(app.AOscanZernikepolynomialsPanel, 'slider');
            app.SLMrefreshconditionSwitch.Items = {'EoF trigger', 'Delay'};
            app.SLMrefreshconditionSwitch.Tooltip = {'End of Frame (EoF) trigger'};
            app.SLMrefreshconditionSwitch.Value = 'Delay';
            app.DelaysecEditFieldLabel = uilabel(app.AOscanZernikepolynomialsPanel);
            app.DelaysecEditFieldLabel.Text = 'Delay (sec)';
            app.DelaysecEditField = uieditfield(app.AOscanZernikepolynomialsPanel, 'numeric');
            app.DelaysecEditField.Limits = [0 Inf];
            app.DelaysecEditField.Value = 1;
            app.ZernikeReadyLamp = uilamp(app.AOscanZernikepolynomialsPanel);
            app.ScanZernikeButton = uibutton(app.AOscanZernikepolynomialsPanel, 'state');
            app.ScanZernikeButton.ValueChangedFcn = createCallbackFcn(app, @ScanZernikeButtonValueChanged, true);
            app.ScanZernikeButton.Text = 'Scan Zernike';
            app.AnalyzeoutputimagesPanel = uipanel(app.AdaptiveOpticsTab);
            app.AnalyzeoutputimagesPanel.Title = 'Analyze output images';
            app.LoaddataButton = uibutton(app.AnalyzeoutputimagesPanel, 'push');
            app.LoaddataButton.Text = 'Load data';
            app.AnalyzeButton = uibutton(app.AnalyzeoutputimagesPanel, 'push');
            app.AnalyzeButton.Text = 'Analyze';
            app.TotalmodesEditFieldLabel = uilabel(app.AdaptiveOpticsTab);
            app.TotalmodesEditFieldLabel.Text = 'Total modes';
            app.TotalmodesEditField = uieditfield(app.AdaptiveOpticsTab, 'numeric');
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.MakeXYZPPanel = uipanel(app.StimulationTab);
            app.MakeXYZPPanel.Title = 'Make XYZP';
            app.UIAxes = uiaxes(app.MakeXYZPPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.StimulationTab);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'; 'Weight'};
            app.UITable.RowName = {};
            app.PointsLabel = uilabel(app.StimulationTab);
            app.PointsLabel.Text = 'Points';
            app.SplitSLMTab = uitab(app.TabGroup);
            app.SplitSLMTab.Title = 'Split SLM';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMwidthEditField.Value = 1920;
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.SLMheightEditField.Value = 1152;
            app.ObjectiveNAEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveNAEditFieldLabel.Text = 'Objective NA';
            app.ObjectiveNAEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveNAEditField.Value = 0.95;
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.WavelengthnmEditFieldLabel = uilabel(app.ParametersTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WavelengthnmEditField.Value = 1064;
            app.SLMpresetoffsetXLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetXLabel.Text = 'SLM preset offset X';
            app.SLMpresetoffsetXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetYEditFieldLabel.Text = 'SLM preset offset Y';
            app.SLMpresetoffsetYEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditField.Value = -9;
            app.ObjectiveMagXEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveMagXEditFieldLabel.Text = 'Objective Mag (X)';
            app.ObjectiveMagXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveMagXEditField.Value = 20;
            app.BeamdiameterpixEditFieldLabel = uilabel(app.ParametersTab);
            app.BeamdiameterpixEditFieldLabel.Text = 'Beam diameter (pix)';
            app.BeamdiameterpixEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BeamdiameterpixEditField.Value = 1152;
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.NIDAQdeviceEditField.Value = 'dev2';
            app.DAQAOchannelforTRIGEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAOchannelforTRIGEditFieldLabel.Text = 'DAQ AO channel for TRIG';
            app.DAQAOchannelforTRIGEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAOchannelforTRIGEditField.Limits = [0 1];
            app.DAQAOchannelforTRIGEditField.RoundFractionalValues = 'on';
            app.DAQcounterchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQcounterchannelEditFieldLabel.Text = 'DAQ counter channel';
            app.DAQcounterchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQcounterchannelEditField.Limits = [0 1];
            app.DAQcounterchannelEditField.RoundFractionalValues = 'on';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.ActivateSLMButton.Value = true;
            app.InitializeDAQButton = uibutton(app.Panel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.InitializeDAQLamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_control_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end