classdef sg_lateral_calibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ObjmagEditField               matlab.ui.control.NumericEditField
        ObjmagEditFieldLabel          matlab.ui.control.Label
        ZoomEditField                 matlab.ui.control.NumericEditField
        ZoomEditFieldLabel            matlab.ui.control.Label
        FOVszieumEditField            matlab.ui.control.NumericEditField
        FOVszieumEditFieldLabel       matlab.ui.control.Label
        clickallaxialButton           matlab.ui.control.Button
        correctcurrentcalibCheckBox   matlab.ui.control.CheckBox
        loadcalibButton               matlab.ui.control.Button
        clickcurrentButton            matlab.ui.control.Button
        clickalllateralButton         matlab.ui.control.Button
        InvertcoordsforbeadsCheckBox  matlab.ui.control.CheckBox
        savecalibButton               matlab.ui.control.Button
        calibfilenameEditField        matlab.ui.control.EditField
        calibfilenameEditFieldLabel   matlab.ui.control.Label
        InstructionsTextArea          matlab.ui.control.TextArea
        InstructionsTextAreaLabel     matlab.ui.control.Label
        loadimagesButton              matlab.ui.control.Button
        imagedirEditField             matlab.ui.control.EditField
        imagedirEditFieldLabel        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        app_main;
        data;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_main)
            app.app_main = app_main;
            f_sg_lc_initialize(app);
        end

        % Callback function
        function updateaffinetransformButtonPushed(app, event)
            f_sg_lc_update_affine(app);
        end

        % Button pushed function: loadimagesButton
        function loadimagesButtonPushed(app, event)
            f_sg_lc_load_images(app);
        end

        % Button pushed function: loadcalibButton
        function loadcalibButtonPushed(app, event)
            f_sg_lc_load_calib(app);
        end

        % Button pushed function: savecalibButton
        function savecalibButtonPushed(app, event)
            f_sg_lc_save_calib(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            f_sg_lc_plot_image(app, indices);
        end

        % Button pushed function: clickalllateralButton
        function clickalllateralButtonPushed(app, event)
            f_sg_lc_click_points_all_lateral(app);
        end

        % Button pushed function: clickcurrentButton
        function clickcurrentButtonPushed(app, event)
            f_sg_lc_click_points_current(app);
        end

        % Button pushed function: clickallaxialButton
        function clickallaxialButtonPushed(app, event)
            f_sg_lc_click_points_all_axial(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'; 'use for lateral'; 'use for axial'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.imagedirEditFieldLabel = uilabel(app.UIFigure);
            app.imagedirEditFieldLabel.Text = 'image dir';
            app.imagedirEditField = uieditfield(app.UIFigure, 'text');
            app.loadimagesButton = uibutton(app.UIFigure, 'push');
            app.loadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @loadimagesButtonPushed, true);
            app.loadimagesButton.Text = 'load images';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Value = {'Add all calibration images to a single directory specified above. Each image should have its SLM displacement specified in title "calib_x0_y20_z0.tif" with underscore preceding the axis and displacement'};
            app.calibfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.calibfilenameEditFieldLabel.Text = 'calib file name';
            app.calibfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.savecalibButton = uibutton(app.UIFigure, 'push');
            app.savecalibButton.ButtonPushedFcn = createCallbackFcn(app, @savecalibButtonPushed, true);
            app.savecalibButton.Text = 'save calib';
            app.InvertcoordsforbeadsCheckBox = uicheckbox(app.UIFigure);
            app.InvertcoordsforbeadsCheckBox.Text = 'Invert coords (for beads)';
            app.clickalllateralButton = uibutton(app.UIFigure, 'push');
            app.clickalllateralButton.ButtonPushedFcn = createCallbackFcn(app, @clickalllateralButtonPushed, true);
            app.clickalllateralButton.Text = 'click all lateral';
            app.clickcurrentButton = uibutton(app.UIFigure, 'push');
            app.clickcurrentButton.ButtonPushedFcn = createCallbackFcn(app, @clickcurrentButtonPushed, true);
            app.clickcurrentButton.Text = 'click current';
            app.loadcalibButton = uibutton(app.UIFigure, 'push');
            app.loadcalibButton.ButtonPushedFcn = createCallbackFcn(app, @loadcalibButtonPushed, true);
            app.loadcalibButton.Text = 'load calib';
            app.correctcurrentcalibCheckBox = uicheckbox(app.UIFigure);
            app.correctcurrentcalibCheckBox.Text = 'correct current calib';
            app.clickallaxialButton = uibutton(app.UIFigure, 'push');
            app.clickallaxialButton.ButtonPushedFcn = createCallbackFcn(app, @clickallaxialButtonPushed, true);
            app.clickallaxialButton.Text = 'click all axial';
            app.FOVszieumEditFieldLabel = uilabel(app.UIFigure);
            app.FOVszieumEditFieldLabel.Text = 'FOV szie (um)';
            app.FOVszieumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVszieumEditField.Limits = [1 Inf];
            app.FOVszieumEditField.Value = 500;
            app.ZoomEditFieldLabel = uilabel(app.UIFigure);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZoomEditField.Limits = [0.5 100];
            app.ZoomEditField.Value = 1;
            app.ObjmagEditFieldLabel = uilabel(app.UIFigure);
            app.ObjmagEditFieldLabel.Text = 'Obj mag';
            app.ObjmagEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_lateral_calibration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end