classdef SLM_control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        SavecurrentholoButton           matlab.ui.control.Button
        CurrentregionDropDown           matlab.ui.control.DropDown
        CurrentregionDropDownLabel      matlab.ui.control.Label
        ViewcurrentAOwfButton           matlab.ui.control.Button
        ViewcurrentholoButton           matlab.ui.control.Button
        XYZcalibrationLamp              matlab.ui.control.Lamp
        ApplyXYZcalibrationButton       matlab.ui.control.StateButton
        XYoffsetButton                  matlab.ui.control.Button
        refimSLMButton                  matlab.ui.control.Button
        InitializeAOLamp                matlab.ui.control.Lamp
        ApplyAOcorrectionButton         matlab.ui.control.StateButton
        BlankSLMButton                  matlab.ui.control.Button
        InitializeDAQLamp               matlab.ui.control.Lamp
        InitializeDAQButton             matlab.ui.control.Button
        ActivateSLMButton               matlab.ui.control.StateButton
        ActivateSLMLamp                 matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        FOVsizeumEditField              matlab.ui.control.NumericEditField
        FOVsizeumEditFieldLabel         matlab.ui.control.Label
        AOcorrZtoleranceEditField       matlab.ui.control.NumericEditField
        AOcorrZtoleranceEditFieldLabel  matlab.ui.control.Label
        DAQAOchannelEditField           matlab.ui.control.NumericEditField
        DAQAOchannelEditFieldLabel      matlab.ui.control.Label
        LoadcalibrationsButton          matlab.ui.control.Button
        ZerooutsideunitcircCheckBox     matlab.ui.control.CheckBox
        ZoomxEditField                  matlab.ui.control.NumericEditField
        ZoomxEditFieldLabel             matlab.ui.control.Label
        ypixelsEditField                matlab.ui.control.NumericEditField
        ypixelsEditFieldLabel           matlab.ui.control.Label
        xpixelsEditField                matlab.ui.control.NumericEditField
        xpixelsEditFieldLabel           matlab.ui.control.Label
        LUTDropDown                     matlab.ui.control.DropDown
        LUTDropDownLabel                matlab.ui.control.Label
        SaveopsButton                   matlab.ui.control.Button
        DAQAIchannelEditField           matlab.ui.control.NumericEditField
        DAQAIchannelEditFieldLabel      matlab.ui.control.Label
        SLMregioneditorPanel            matlab.ui.container.Panel
        regionEffectiveNAEditField      matlab.ui.control.NumericEditField
        EffectiveNALabel                matlab.ui.control.Label
        AOcorrectionDropDown            matlab.ui.control.DropDown
        AOcorrectionDropDownLabel       matlab.ui.control.Label
        LateralaffinetransformDropDown  matlab.ui.control.DropDown
        LateralaffinetransformDropDownLabel  matlab.ui.control.Label
        regionwidthLabel                matlab.ui.control.Label
        regionwidthmaxEditField         matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        regionwidthminEditField         matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        regionheightmaxEditField        matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        regionheightLabel               matlab.ui.control.Label
        regionheightminEditField        matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        SaveregButton                   matlab.ui.control.Button
        LUTcorrectionDropDown           matlab.ui.control.DropDown
        LUTcorrectionLabel              matlab.ui.control.Label
        regionWavelengthnmEditField     matlab.ui.control.NumericEditField
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        DeleteregButton                 matlab.ui.control.Button
        AddregButton                    matlab.ui.control.Button
        RegionnameEditField             matlab.ui.control.EditField
        RegionnameEditFieldLabel        matlab.ui.control.Label
        SelectRegionDropDown            matlab.ui.control.DropDown
        SelectregionDropDownLabel       matlab.ui.control.Label
        LoadopsButton                   matlab.ui.control.Button
        InsertrefimageinscansCheckBox   matlab.ui.control.CheckBox
        PlotSLMupdateratesCheckBox      matlab.ui.control.CheckBox
        DAQcounterchannelEditField      matlab.ui.control.NumericEditField
        DAQcounterchannelEditFieldLabel  matlab.ui.control.Label
        NIDAQdeviceEditField            matlab.ui.control.EditField
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        BeamdiameterpixEditField        matlab.ui.control.NumericEditField
        BeamdiameterpixEditFieldLabel   matlab.ui.control.Label
        ObjectiveMagXEditField          matlab.ui.control.NumericEditField
        ObjectiveMagXEditFieldLabel     matlab.ui.control.Label
        SLMpresetoffsetYEditField       matlab.ui.control.NumericEditField
        SLMpresetoffsetYEditFieldLabel  matlab.ui.control.Label
        SLMpresetoffsetXEditField       matlab.ui.control.NumericEditField
        SLMpresetoffsetXLabel           matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        EffectiveNAEditField            matlab.ui.control.NumericEditField
        EffectiveNAEditFieldLabel       matlab.ui.control.Label
        SLMheightEditField              matlab.ui.control.NumericEditField
        SLMheightEditFieldLabel         matlab.ui.control.Label
        SLMwidthEditField               matlab.ui.control.NumericEditField
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        XYZpatternsTab                  matlab.ui.container.Tab
        patterneditorButton             matlab.ui.control.Button
        UIImagePhaseTable               matlab.ui.control.Table
        DeletegroupButton               matlab.ui.control.Button
        AddgroupButton                  matlab.ui.control.Button
        SavegroupButton                 matlab.ui.control.Button
        GroupnameEditField              matlab.ui.control.EditField
        GroupnameEditFieldLabel         matlab.ui.control.Label
        PatterngroupDropDown            matlab.ui.control.DropDown
        PatterngroupDropDownLabel       matlab.ui.control.Label
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        ViewpatternfftButton            matlab.ui.control.Button
        ViewselectedfftButton           matlab.ui.control.Button
        UploadpatternButton             matlab.ui.control.Button
        ViewpatternphaseButton          matlab.ui.control.Button
        PatternSpinner                  matlab.ui.control.Spinner
        PatternSpinnerLabel             matlab.ui.control.Label
        ViewselectedphaseButton         matlab.ui.control.Button
        ViewpredictedPSFButton          matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        RemoveselectedButton            matlab.ui.control.Button
        UploadselectedButton            matlab.ui.control.Button
        CreatecustompointhologramPanel  matlab.ui.container.Panel
        ManualNAEditField               matlab.ui.control.NumericEditField
        ManualNAEditFieldLabel          matlab.ui.control.Label
        ZOffsetumEditField              matlab.ui.control.NumericEditField
        ZOffsetumEditFieldLabel         matlab.ui.control.Label
        DefocusLabel                    matlab.ui.control.Label
        ManualNAcorrectionCheckBox      matlab.ui.control.CheckBox
        PatternnumberEditField          matlab.ui.control.NumericEditField
        PatternnumberEditFieldLabel     matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        YdisplacementEditField          matlab.ui.control.NumericEditField
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditField          matlab.ui.control.NumericEditField
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        XYdisplacementLabel             matlab.ui.control.Label
        UploadHologramButton            matlab.ui.control.Button
        AllpointsingroupLabel           matlab.ui.control.Label
        ScanTab                         matlab.ui.container.Tab
        GroupscanPanel                  matlab.ui.container.Panel
        StimulationAI05Vnum_groupsPanel  matlab.ui.container.Panel
        precompatterncombosButtonGroup  matlab.ui.container.ButtonGroup
        EOFonlyButton                   matlab.ui.control.ToggleButton
        AllButton                       matlab.ui.control.ToggleButton
        PatternDropDownAI               matlab.ui.control.DropDown
        PatterngroupLabel_2             matlab.ui.control.Label
        ImagingEOFcounterchantriggeredPanel  matlab.ui.container.Panel
        ImagingReadyLamp                matlab.ui.control.Lamp
        InitializeimagingButton         matlab.ui.control.StateButton
        NumVolumesEditField             matlab.ui.control.NumericEditField
        NumVolumesEditFieldLabel        matlab.ui.control.Label
        PatternDropDownCtr              matlab.ui.control.DropDown
        PatterngroupLabel               matlab.ui.control.Label
        GenerateHologramTab             matlab.ui.container.Tab
        UploadHologramButtonGenerate    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        PistonTab                       matlab.ui.container.Tab
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        PixelValueLabel                 matlab.ui.control.Label
        GeneratepistonButton            matlab.ui.control.Button
        BlazedGratingTab                matlab.ui.container.Tab
        BlazReverseCheckBox             matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        GenerateBlazedGratingButton     matlab.ui.control.Button
        StripesTab                      matlab.ui.container.Tab
        StripeGrayEditField             matlab.ui.control.NumericEditField
        GrayEditFieldLabel              matlab.ui.control.Label
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        PixelValueEditFieldLabel        matlab.ui.control.Label
        GenerateStripesButton           matlab.ui.control.Button
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        PixelsPerStripeLabel            matlab.ui.control.Label
        DefocusTab                      matlab.ui.container.Tab
        DeficusWeightEditField          matlab.ui.control.NumericEditField
        DefocusdistumLabel              matlab.ui.control.Label
        GeneratedefocusButton           matlab.ui.control.Button
        CrossTab                        matlab.ui.container.Tab
        CrossAngleEditField             matlab.ui.control.NumericEditField
        AngleradLabel                   matlab.ui.control.Label
        CrossGrayEditField              matlab.ui.control.NumericEditField
        GrayEditFieldLabel_2            matlab.ui.control.Label
        CrossPixelValueEditField        matlab.ui.control.NumericEditField
        PixelValueEditFieldLabel_2      matlab.ui.control.Label
        GenerateCrossButton             matlab.ui.control.Button
        FresnelLensTab                  matlab.ui.container.Tab
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresPowerEditField              matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        FresRadiusEditField             matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        FresCenterYEditField            matlab.ui.control.NumericEditField
        CenterYEditFieldLabel           matlab.ui.control.Label
        FresCenterXEditField            matlab.ui.control.NumericEditField
        CenterXEditFieldLabel           matlab.ui.control.Label
        GenerateFresnelLensButton       matlab.ui.control.Button
        ZernikeTab                      matlab.ui.container.Tab
        QuaternarySphericalEditField    matlab.ui.control.NumericEditField
        QuaternarySphericalEditFieldLabel  matlab.ui.control.Label
        TertiarySphericalEditField      matlab.ui.control.NumericEditField
        TertiarySphericalEditFieldLabel  matlab.ui.control.Label
        TetrafoilYEditField             matlab.ui.control.NumericEditField
        TetrafoilYEditFieldLabel        matlab.ui.control.Label
        TetrafoilXEditField             matlab.ui.control.NumericEditField
        TetrafoilXEditFieldLabel        matlab.ui.control.Label
        SecondarySphericalEditField     matlab.ui.control.NumericEditField
        SecondarySphericalEditFieldLabel  matlab.ui.control.Label
        SecondaryComaYEditField         matlab.ui.control.NumericEditField
        SecondaryComaYEditFieldLabel    matlab.ui.control.Label
        SecondaryComaXEditField         matlab.ui.control.NumericEditField
        SecondaryComaXEditFieldLabel    matlab.ui.control.Label
        SecondaryAstigYEditField        matlab.ui.control.NumericEditField
        SecondaryAstigYEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigXEditField        matlab.ui.control.NumericEditField
        SecondaryAstigXEditFieldLabel   matlab.ui.control.Label
        TrefoilYEditField               matlab.ui.control.NumericEditField
        TrefoilYEditFieldLabel          matlab.ui.control.Label
        TrefoilXEditField               matlab.ui.control.NumericEditField
        TrefoilXEditFieldLabel          matlab.ui.control.Label
        PrimarySphericalEditField       matlab.ui.control.NumericEditField
        PrimarySphericalEditFieldLabel  matlab.ui.control.Label
        ComaYEditField                  matlab.ui.control.NumericEditField
        ComaYEditFieldLabel             matlab.ui.control.Label
        ComaXEditField                  matlab.ui.control.NumericEditField
        ComaXEditFieldLabel             matlab.ui.control.Label
        AstigYEditField                 matlab.ui.control.NumericEditField
        AstigYEditFieldLabel            matlab.ui.control.Label
        AstigXEditField                 matlab.ui.control.NumericEditField
        AstigXEditFieldLabel            matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel_2           matlab.ui.control.Label
        TiltYEditField                  matlab.ui.control.NumericEditField
        TiltYEditFieldLabel             matlab.ui.control.Label
        TiltXEditField                  matlab.ui.control.NumericEditField
        TiltXEditFieldLabel             matlab.ui.control.Label
        PistonEditField                 matlab.ui.control.NumericEditField
        PistonEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel_2          matlab.ui.control.Label
        CenterYEditField                matlab.ui.control.NumericEditField
        CenterYEditField_5Label         matlab.ui.control.Label
        CenterXEditField                matlab.ui.control.NumericEditField
        CenterXEditField_2Label         matlab.ui.control.Label
        GenerateZernikeButton           matlab.ui.control.Button
        CustomphaseTab                  matlab.ui.container.Tab
        LoadimageButton                 matlab.ui.control.Button
        PhaseformatDropDown             matlab.ui.control.DropDown
        PhaseformatDropDownLabel        matlab.ui.control.Label
        ImagepathEditField              matlab.ui.control.EditField
        ImagepathEditFieldLabel         matlab.ui.control.Label
        BrowseimageButton               matlab.ui.control.Button
        UIAxesColorLimits               matlab.ui.control.UIAxes
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        AdaptiveOpticsmodalTab          matlab.ui.container.Tab
        ParamsPanel                     matlab.ui.container.Panel
        ScansperVolZEditField           matlab.ui.control.NumericEditField
        ScansperVolZEditFieldLabel      matlab.ui.control.Label
        ScanspermodeEditField           matlab.ui.control.NumericEditField
        ScanspermodeEditFieldLabel      matlab.ui.control.Label
        ShufflemodesCheckBox            matlab.ui.control.CheckBox
        SavefiletagEditField            matlab.ui.control.EditField
        SavefiletagEditFieldLabel       matlab.ui.control.Label
        AOautooptimizationscanPanel     matlab.ui.container.Panel
        PostscandelayEditField          matlab.ui.control.NumericEditField
        PostscandelayEditFieldLabel     matlab.ui.control.Label
        BeadwindowsizeEditField         matlab.ui.control.NumericEditField
        BeadwindowsizeEditFieldLabel    matlab.ui.control.Label
        PlotextradeetsCheckBox          matlab.ui.control.CheckBox
        NumscanframesOpEditField        matlab.ui.control.NumericEditField
        NumscanframesLabel_2            matlab.ui.control.Label
        ScanframesdirpathEditField      matlab.ui.control.EditField
        ScanframesdirpathEditFieldLabel  matlab.ui.control.Label
        PlotprogressCheckBox            matlab.ui.control.CheckBox
        NumiterationsSpinner            matlab.ui.control.Spinner
        NumiterationsSpinnerLabel       matlab.ui.control.Label
        StartoptimizationscanButton     matlab.ui.control.Button
        FillzerniketablePanel           matlab.ui.container.Panel
        AOignoredefocusmodesCheckBox    matlab.ui.control.CheckBox
        WeightstepEditField             matlab.ui.control.NumericEditField
        WeightstepEditFieldLabel        matlab.ui.control.Label
        WeightlimitEditField            matlab.ui.control.NumericEditField
        WeightlimitEditFieldLabel       matlab.ui.control.Label
        MaxmodesEditField               matlab.ui.control.NumericEditField
        MaxmodesEditFieldLabel          matlab.ui.control.Label
        FillmodestableButton            matlab.ui.control.Button
        AOregularscanzernikepolPanel    matlab.ui.container.Panel
        NumscanframesEditField          matlab.ui.control.NumericEditField
        NumscanframesLabel              matlab.ui.control.Label
        NumscanvolumesEditField         matlab.ui.control.NumericEditField
        NumscanvolumesEditFieldLabel    matlab.ui.control.Label
        ScanZernikeButton               matlab.ui.control.StateButton
        ZernikeReadyLamp                matlab.ui.control.Lamp
        TestHologramandweightsPanel     matlab.ui.container.Panel
        weightEditField                 matlab.ui.control.NumericEditField
        weightEditFieldLabel            matlab.ui.control.Label
        ModeindexEditField              matlab.ui.control.NumericEditField
        ModeindexEditFieldLabel         matlab.ui.control.Label
        UploadHologramButton_2          matlab.ui.control.Button
        PlotHologramButton              matlab.ui.control.Button
        ZernikeModestoscanadjustweightsifneededLabel  matlab.ui.control.Label
        ZernikeListTable                matlab.ui.control.Table
        CalibrationTab                  matlab.ui.container.Tab
        LateralcalibrationButton        matlab.ui.control.Button
        LUTcalibrationdataPanel         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        TLDCcamButton                   matlab.ui.control.ToggleButton
        EndofframeButton                matlab.ui.control.ToggleButton
        TotalframesEditField            matlab.ui.control.NumericEditField
        TotalframesEditFieldLabel       matlab.ui.control.Label
        BaselinepixelvalueEditField     matlab.ui.control.NumericEditField
        BaselinepixelvalueEditFieldLabel  matlab.ui.control.Label
        RunLUTcalibReadyLamp            matlab.ui.control.Lamp
        AnalyzeLUTdataButton            matlab.ui.control.Button
        RunLUTcalibrationButton         matlab.ui.control.StateButton
        AnalysissavefilenameEditField   matlab.ui.control.EditField
        AnalysissavefilenameEditFieldLabel  matlab.ui.control.Label
        PixelsPerStripeEditField        matlab.ui.control.NumericEditField
        PixelsPerStripeEditFieldLabel   matlab.ui.control.Label
        NumRegionsEditField             matlab.ui.control.NumericEditField
        NumRegionsEditFieldLabel        matlab.ui.control.Label
        BitDepthEditField               matlab.ui.control.NumericEditField
        BitDepthEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        SLM_ops; % Description
        region_list;
        xyz_patterns;
        lut_list;
        lut_corrections_list;
        SLM_Image;
        SLM_Image_gh_preview;
        SLM_Image_pointer;
        SLM_Image_gh_climits;
        GUI_ops;
        current_SLM_coord;
        current_SLM_AO_Image = [];
        SLM_blank_im;
        SLM_blank_pointer;
        SLM_Image_plot;
        hologram_fig_plot;
        UIImagePhaseTableSelection;
        DAQ_session;
        DAQ_ai_chan;
        AO_ignore_modes_list = [1, 2, 3];
        AO_last_zernike_data = cell(2,1);
        AO_last_LUT_data = cell(2,1);
        AO_correction_data = [];
        fov_im;
        pattern_editor_data;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            pwd2 = fileparts(which('SLM_control_GUI.mlapp'));
            app.SLM_ops.GUI_dir = pwd2;
            addpath(pwd2);
            if exist([pwd2 '\SLM_GUI_funcions'], 'dir')
                addpath([pwd2 '\SLM_GUI_funcions']);
            else
                error('RAFA: You need to move to SLM_microscope_GUI directory and reopen GUI!!!');
            end 
            f_sg_gui_startup(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.ActivateSLMButton.Value
                f_SLM_update(app.SLM_ops, app.SLM_blank_pointer);
                app.SLM_ops = f_SLM_close(app.SLM_ops);
            end
            delete(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            if app.ActivateSLMButton.Value
                app.SLM_ops = f_SLM_initialize(app.SLM_ops);
                if app.SLM_ops.SDK_created
                    app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
                else
                    app.ActivateSLMButton.Value = 0;
                end
            else
                app.SLM_ops = f_SLM_close(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            f_sg_initialize_DAQ(app);
        end

        % Value changed function: ApplyXYZcalibrationButton
        function ApplyXYZcalibrationButtonValueChanged(app, event)
            f_sg_apply_xyz_calibration(app);
        end

        % Value changed function: ApplyAOcorrectionButton
        function ApplyAOcorrectionButtonValueChanged(app, event)
            f_sg_apply_AO_correction_button(app);
        end

        % Button pushed function: BlankSLMButton
        function BlankSLMButtonPushed(app, event)
            f_sg_button_blank_display(app);
        end

        % Button pushed function: XYoffsetButton
        function XYoffsetButtonPushed(app, event)
            f_sg_button_xy_offset(app);
        end

        % Button pushed function: refimSLMButton
        function refimSLMButtonPushed(app, event)
            f_sg_button_ref_im(app);
        end

        % Button pushed function: GeneratepistonButton
        function GenerateBlankLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'piston');
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'fresnel');
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'blazed');
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'stripes');
        end

        % Button pushed function: GenerateZernikeButton
        function GenerateZernikeButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'zernike');
        end

        % Button pushed function: GeneratedefocusButton
        function GeneratedefocusButtonPushed(app, event)
            f_sg_gh_defocus(app);
        end

        % Button pushed function: GenerateCrossButton
        function GenerateCrossButtonPushed(app, event)
            f_sg_gh_gen_holo(app, 'cross');
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            app.SLM_Image = app.SLM_Image_gh_preview;
            f_sg_upload_image_to_SLM(app);
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_view_holo(app);
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            f_sg_xyz_button_upload_holo(app);
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            f_sg_xyz_button_add_to_holo(app);
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            f_sg_xyz_button_view_selected_phase(app);  
        end

        % Button pushed function: ViewselectedfftButton
        function ViewselectedfftButtonPushed(app, event)
            f_sg_xyz_button_view_selected_fft(app);
        end

        % Button pushed function: UploadselectedButton
        function UploadselectedButtonPushed(app, event)
            f_sg_xyz_button_upload_selected(app);
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            f_sg_xyz_button_remove_selected(app);
        end

        % Button pushed function: ViewpatternphaseButton
        function ViewpatternphaseButtonPushed(app, event)
            f_sg_xyz_button_view_pattern_phase(app);
        end

        % Button pushed function: ViewpatternfftButton
        function ViewpatternfftButtonPushed(app, event)
            f_sg_xyz_button_view_pattern_fft(app);
        end

        % Button pushed function: UploadpatternButton
        function UploadpatternButtonPushed(app, event)
            f_sg_xyz_button_upload_pattern(app);
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            f_sg_scan_initialize_imaging(app);
        end

        % Callback function
        function ScanZernikeButtonPushed(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Button pushed function: FillmodestableButton
        function FillmodestableButtonPushed(app, event)
            f_sg_AO_fill_modes_table(app);
        end

        % Value changed function: ScanspermodeEditField
        function ScanspermodeEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Value changed function: ScansperVolZEditField
        function ScansperVolZEditFieldValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: PlotHologramButton
        function PlotHologramButtonPushed(app, event)
            [phase, n, m] = f_sg_AO_gen_test_hologram(app);
            f_sg_plot_phase_figure(phase, sprintf('Zernike Hologram %d, w=%d; Znm n=%d; m=%d',app.ModeindexEditField.Value,app.weightEditField.Value, n, m));
        end

        % Button pushed function: UploadHologramButton_2
        function UploadHologramButton_2Pushed(app, event)
            phase = f_sg_AO_gen_test_hologram(app);
            app.SLM_Image_pointer.Value = f_sg_im_to_pointer(phase);
            f_SLM_update(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Value changed function: ScanZernikeButton
        function ScanZernikeButtonValueChanged(app, event)
            f_sg_AO_scan_zernike(app);
        end

        % Cell edit callback: ZernikeListTable
        function ZernikeListTableCellEdit(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Callback function
        function ViewAOphaseButtonPushed(app, event)
            f_sg_view_hologram_phase(app, app.SLM_AO_Image);
        end

        % Value changed function: RunLUTcalibrationButton
        function RunLUTcalibrationButtonValueChanged(app, event)
            f_sg_LUT_run_calibration(app);
        end

        % Value changed function: BitDepthEditField
        function BitDepthEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: NumRegionsEditField
        function NumRegionsEditFieldValueChanged(app, event)
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: LUTcorrectionDropDown
        function LUTcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Value changed function: InsertrefimageinscansCheckBox
        function InsertrefimageinscansCheckBoxValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
            f_sg_LUT_update_total_frames(app);
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            f_sg_reg_update(app);
        end

        % Button pushed function: SaveregButton
        function SaveregButtonPushed(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: AddregButton
        function AddregButtonPushed(app, event)
            f_sg_reg_add(app);
        end

        % Button pushed function: DeleteregButton
        function DeleteregButtonPushed(app, event)
            f_sg_reg_delete(app);
        end

        % Button pushed function: SavegroupButton
        function SavegroupButtonPushed(app, event)
            f_sg_pat_save(app);
        end

        % Value changed function: PatterngroupDropDown
        function PatterngroupDropDownValueChanged(app, event)
            f_sg_pat_update(app);
        end

        % Button pushed function: DeletegroupButton
        function DeletegroupButtonPushed(app, event)
            f_sg_pat_delete(app);
        end

        % Button pushed function: AddgroupButton
        function AddgroupButtonPushed(app, event)
            f_sg_pat_add(app);
        end

        % Callback function
        function GroupRegionDropDownValueChanged(app, event)
            f_sg_xyz_pat_update_reg(app);
        end

        % Button pushed function: LoadopsButton
        function LoadopsButtonPushed(app, event)
            f_sg_ops_load(app);
        end

        % Button pushed function: SaveopsButton
        function SaveopsButtonPushed(app, event)
            f_sg_ops_save(app);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            f_sg_lut_update_list(app);
        end

        % Button pushed function: ViewcurrentholoButton
        function ViewcurrentholoButtonPushed(app, event)
            f_sg_view_current_holo(app);
        end

        % Value changed function: AOcorrectionDropDown
        function AOcorrectionDropDownValueChanged(app, event)
            f_sg_reg_save(app);
        end

        % Button pushed function: ViewcurrentAOwfButton
        function ViewcurrentAOwfButtonPushed(app, event)
            f_sg_AO_plot_current_correction(app);
        end

        % Button pushed function: LoadcalibrationsButton
        function LoadcalibrationsButtonPushed(app, event)
            f_sg_load_calibration(app);
        end

        % Button pushed function: StartoptimizationscanButton
        function StartoptimizationscanButtonPushed(app, event)
            f_sg_AO_scan_optimization(app);
        end

        % Value changed function: NumiterationsSpinner
        function NumiterationsSpinnerValueChanged(app, event)
            f_sg_AO_update_total_modes(app);
        end

        % Button pushed function: LateralcalibrationButton
        function LateralcalibrationButtonPushed(app, event)
            sg_lateral_calibration(app);
        end

        % Button pushed function: patterneditorButton
        function patterneditorButtonPushed(app, event)
            sg_pattern_plot(app);
        end

        % Button pushed function: SavecurrentholoButton
        function SavecurrentholoButtonPushed(app, event)
            f_sg_save_current_holo(app);
        end

        % Button pushed function: BrowseimageButton
        function BrowseimageButtonPushed(app, event)
            f_sg_gh_custom_phase_browse(app);
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            f_sg_gh_custom_phase_load(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMwidthEditField.Value = 1920;
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.SLMheightEditField.Value = 1152;
            app.EffectiveNAEditFieldLabel = uilabel(app.ParametersTab);
            app.EffectiveNAEditFieldLabel.Text = 'Effective NA';
            app.EffectiveNAEditField = uieditfield(app.ParametersTab, 'numeric');
            app.EffectiveNAEditField.Value = 1.05;
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.WavelengthnmEditFieldLabel = uilabel(app.ParametersTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WavelengthnmEditField.Value = 940;
            app.SLMpresetoffsetXLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetXLabel.Text = 'SLM preset offset X';
            app.SLMpresetoffsetXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetYEditFieldLabel.Text = 'SLM preset offset Y';
            app.SLMpresetoffsetYEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditField.Value = -9;
            app.ObjectiveMagXEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveMagXEditFieldLabel.Text = 'Objective Mag (X)';
            app.ObjectiveMagXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveMagXEditField.Value = 25;
            app.BeamdiameterpixEditFieldLabel = uilabel(app.ParametersTab);
            app.BeamdiameterpixEditFieldLabel.Text = 'Beam diameter (pix)';
            app.BeamdiameterpixEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BeamdiameterpixEditField.Value = 1152;
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.NIDAQdeviceEditField.Value = 'dev2';
            app.DAQcounterchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQcounterchannelEditFieldLabel.Text = 'DAQ counter channel';
            app.DAQcounterchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQcounterchannelEditField.Limits = [0 1];
            app.DAQcounterchannelEditField.RoundFractionalValues = 'on';
            app.PlotSLMupdateratesCheckBox = uicheckbox(app.ParametersTab);
            app.PlotSLMupdateratesCheckBox.Text = 'Plot SLM update rates';
            app.PlotSLMupdateratesCheckBox.Value = true;
            app.InsertrefimageinscansCheckBox = uicheckbox(app.ParametersTab);
            app.InsertrefimageinscansCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsertrefimageinscansCheckBoxValueChanged, true);
            app.InsertrefimageinscansCheckBox.Text = 'Insert ref image in scans';
            app.InsertrefimageinscansCheckBox.Value = true;
            app.LoadopsButton = uibutton(app.ParametersTab, 'push');
            app.LoadopsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadopsButtonPushed, true);
            app.LoadopsButton.Text = 'Load ops';
            app.SLMregioneditorPanel = uipanel(app.ParametersTab);
            app.SLMregioneditorPanel.Title = 'SLM region editor';
            app.SelectregionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.SelectregionDropDownLabel.Text = 'Select region';
            app.SelectRegionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.SelectRegionDropDown.Items = {'Full SLM'};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = 'Full SLM';
            app.RegionnameEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.RegionnameEditFieldLabel.Text = 'Region name';
            app.RegionnameEditField = uieditfield(app.SLMregioneditorPanel, 'text');
            app.RegionnameEditField.Value = 'Full SLM';
            app.AddregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.AddregButton.ButtonPushedFcn = createCallbackFcn(app, @AddregButtonPushed, true);
            app.AddregButton.Text = 'Add reg';
            app.DeleteregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.DeleteregButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteregButtonPushed, true);
            app.DeleteregButton.Text = 'Delete reg';
            app.WavelengthnmEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.regionWavelengthnmEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionWavelengthnmEditField.Value = 940;
            app.LUTcorrectionLabel = uilabel(app.SLMregioneditorPanel);
            app.LUTcorrectionLabel.Text = 'LUT correction';
            app.LUTcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LUTcorrectionDropDown.Items = {};
            app.LUTcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTcorrectionDropDownValueChanged, true);
            app.LUTcorrectionDropDown.Value = {};
            app.SaveregButton = uibutton(app.SLMregioneditorPanel, 'push');
            app.SaveregButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregButtonPushed, true);
            app.SaveregButton.Text = 'Save reg';
            app.minEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.minEditFieldLabel.Text = 'min';
            app.regionheightminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionheightLabel = uilabel(app.SLMregioneditorPanel);
            app.regionheightLabel.Text = 'Region height [0,1]';
            app.maxEditFieldLabel = uilabel(app.SLMregioneditorPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.regionheightmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.minEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.minEditField_2Label.Text = 'min';
            app.regionwidthminEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.maxEditField_2Label = uilabel(app.SLMregioneditorPanel);
            app.maxEditField_2Label.Text = 'max';
            app.regionwidthmaxEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionwidthLabel = uilabel(app.SLMregioneditorPanel);
            app.regionwidthLabel.Text = 'Region width [0, 1]';
            app.LateralaffinetransformDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.LateralaffinetransformDropDownLabel.Text = 'Lateral affine transform';
            app.LateralaffinetransformDropDown = uidropdown(app.SLMregioneditorPanel);
            app.LateralaffinetransformDropDown.Items = {};
            app.LateralaffinetransformDropDown.Value = {};
            app.AOcorrectionDropDownLabel = uilabel(app.SLMregioneditorPanel);
            app.AOcorrectionDropDownLabel.Text = 'AO correction';
            app.AOcorrectionDropDown = uidropdown(app.SLMregioneditorPanel);
            app.AOcorrectionDropDown.Items = {};
            app.AOcorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionDropDownValueChanged, true);
            app.AOcorrectionDropDown.Value = {};
            app.EffectiveNALabel = uilabel(app.SLMregioneditorPanel);
            app.EffectiveNALabel.Text = 'Effective NA';
            app.regionEffectiveNAEditField = uieditfield(app.SLMregioneditorPanel, 'numeric');
            app.regionEffectiveNAEditField.Value = 1.05;
            app.DAQAIchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAIchannelEditFieldLabel.Text = 'DAQ AI channel';
            app.DAQAIchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAIchannelEditField.Limits = [0 1];
            app.DAQAIchannelEditField.RoundFractionalValues = 'on';
            app.SaveopsButton = uibutton(app.ParametersTab, 'push');
            app.SaveopsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveopsButtonPushed, true);
            app.SaveopsButton.Text = 'Save ops';
            app.LUTDropDownLabel = uilabel(app.ParametersTab);
            app.LUTDropDownLabel.Text = 'LUT';
            app.LUTDropDown = uidropdown(app.ParametersTab);
            app.LUTDropDown.Items = {};
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Tooltip = {'For 1920x1152 model bot global .lut and regional .txt files are treated the same and can be upload with lut update functiond. For the old 512x512 with overdrive regional .txt needs to be uploaded during SDK creation '};
            app.LUTDropDown.Value = {};
            app.xpixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.xpixelsEditFieldLabel.Text = 'x pixels';
            app.xpixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.xpixelsEditField.Limits = [2 4096];
            app.xpixelsEditField.RoundFractionalValues = 'on';
            app.xpixelsEditField.Value = 256;
            app.ypixelsEditFieldLabel = uilabel(app.ParametersTab);
            app.ypixelsEditFieldLabel.Text = 'y pixels';
            app.ypixelsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ypixelsEditField.Limits = [2 4096];
            app.ypixelsEditField.RoundFractionalValues = 'on';
            app.ypixelsEditField.Value = 256;
            app.ZoomxEditFieldLabel = uilabel(app.ParametersTab);
            app.ZoomxEditFieldLabel.Text = 'Zoom x';
            app.ZoomxEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ZoomxEditField.Limits = [0.1 100];
            app.ZoomxEditField.Value = 1;
            app.ZerooutsideunitcircCheckBox = uicheckbox(app.ParametersTab);
            app.ZerooutsideunitcircCheckBox.Text = 'Zero outside unit circ';
            app.ZerooutsideunitcircCheckBox.Value = true;
            app.LoadcalibrationsButton = uibutton(app.ParametersTab, 'push');
            app.LoadcalibrationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcalibrationsButtonPushed, true);
            app.LoadcalibrationsButton.Text = 'Load calibrations';
            app.DAQAOchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQAOchannelEditFieldLabel.Text = 'DAQ AO channel';
            app.DAQAOchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQAOchannelEditField.Limits = [0 1];
            app.DAQAOchannelEditField.RoundFractionalValues = 'on';
            app.AOcorrZtoleranceEditFieldLabel = uilabel(app.ParametersTab);
            app.AOcorrZtoleranceEditFieldLabel.Text = 'AO corr Z tolerance';
            app.AOcorrZtoleranceEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AOcorrZtoleranceEditField.Value = 21;
            app.FOVsizeumEditFieldLabel = uilabel(app.ParametersTab);
            app.FOVsizeumEditFieldLabel.Text = 'FOV size (um)';
            app.FOVsizeumEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FOVsizeumEditField.Limits = [1 Inf];
            app.FOVsizeumEditField.Value = 637.4;
            app.XYZpatternsTab = uitab(app.TabGroup);
            app.XYZpatternsTab.Title = 'XYZ patterns';
            app.AllpointsingroupLabel = uilabel(app.XYZpatternsTab);
            app.AllpointsingroupLabel.Text = 'All points in group';
            app.CreatecustompointhologramPanel = uipanel(app.XYZpatternsTab);
            app.CreatecustompointhologramPanel.Title = 'Create custom point hologram';
            app.UploadHologramButton = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.XYdisplacementLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.XdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.XdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.YdisplacementEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.ViewHologramButtonMultiplane = uibutton(app.CreatecustompointhologramPanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.WeightEditField.Value = 1;
            app.PatternnumberEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.PatternnumberEditFieldLabel.Text = 'Pattern number';
            app.PatternnumberEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.PatternnumberEditField.Limits = [1 Inf];
            app.PatternnumberEditField.RoundFractionalValues = 'on';
            app.PatternnumberEditField.Value = 1;
            app.ManualNAcorrectionCheckBox = uicheckbox(app.CreatecustompointhologramPanel);
            app.ManualNAcorrectionCheckBox.Text = 'Manual NA correction';
            app.DefocusLabel = uilabel(app.CreatecustompointhologramPanel);
            app.DefocusLabel.Text = 'Defocus';
            app.ZOffsetumEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.ZOffsetumEditFieldLabel.Text = 'Z Offset (um)';
            app.ZOffsetumEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.ManualNAEditFieldLabel = uilabel(app.CreatecustompointhologramPanel);
            app.ManualNAEditFieldLabel.Text = 'Manual NA';
            app.ManualNAEditField = uieditfield(app.CreatecustompointhologramPanel, 'numeric');
            app.ManualNAEditField.Limits = [0 2];
            app.ManualNAEditField.ValueDisplayFormat = '%.2f';
            app.ManualNAEditField.Value = 0.95;
            app.UploadselectedButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedButtonPushed, true);
            app.UploadselectedButton.Text = 'Upload selected';
            app.RemoveselectedButton = uibutton(app.XYZpatternsTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.AddtohologramButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.ViewpredictedPSFButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.ViewselectedphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.PatternSpinnerLabel = uilabel(app.XYZpatternsTab);
            app.PatternSpinnerLabel.Text = 'Pattern';
            app.PatternSpinner = uispinner(app.XYZpatternsTab);
            app.PatternSpinner.Limits = [1 100];
            app.PatternSpinner.RoundFractionalValues = 'on';
            app.PatternSpinner.Value = 1;
            app.ViewpatternphaseButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpatternphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpatternphaseButtonPushed, true);
            app.ViewpatternphaseButton.Text = 'View pattern phase';
            app.UploadpatternButton = uibutton(app.XYZpatternsTab, 'push');
            app.UploadpatternButton.ButtonPushedFcn = createCallbackFcn(app, @UploadpatternButtonPushed, true);
            app.UploadpatternButton.Text = 'Upload pattern';
            app.ViewselectedfftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewselectedfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedfftButtonPushed, true);
            app.ViewselectedfftButton.Text = 'View selected fft';
            app.ViewpatternfftButton = uibutton(app.XYZpatternsTab, 'push');
            app.ViewpatternfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewpatternfftButtonPushed, true);
            app.ViewpatternfftButton.Text = 'View pattern fft';
            app.fftdefocusumEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.fftdefocusumEditField = uieditfield(app.XYZpatternsTab, 'numeric');
            app.fftampsquaredCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftzoomed20xCheckBox = uicheckbox(app.XYZpatternsTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.PatterngroupDropDownLabel = uilabel(app.XYZpatternsTab);
            app.PatterngroupDropDownLabel.Text = 'Pattern group';
            app.PatterngroupDropDown = uidropdown(app.XYZpatternsTab);
            app.PatterngroupDropDown.Items = {'Multiplane'};
            app.PatterngroupDropDown.ValueChangedFcn = createCallbackFcn(app, @PatterngroupDropDownValueChanged, true);
            app.PatterngroupDropDown.Value = 'Multiplane';
            app.GroupnameEditFieldLabel = uilabel(app.XYZpatternsTab);
            app.GroupnameEditFieldLabel.Text = 'Group name';
            app.GroupnameEditField = uieditfield(app.XYZpatternsTab, 'text');
            app.SavegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.SavegroupButton.ButtonPushedFcn = createCallbackFcn(app, @SavegroupButtonPushed, true);
            app.SavegroupButton.Text = 'Save group';
            app.AddgroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.AddgroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddgroupButtonPushed, true);
            app.AddgroupButton.Text = 'Add group';
            app.DeletegroupButton = uibutton(app.XYZpatternsTab, 'push');
            app.DeletegroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeletegroupButtonPushed, true);
            app.DeletegroupButton.Text = 'Delete group';
            app.UIImagePhaseTable = uitable(app.XYZpatternsTab);
            app.UIImagePhaseTable.ColumnName = {'Idx'; 'Pattern'; 'X'; 'Y'; 'Z'; 'Weight'};
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = [false true true true true true true];
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.patterneditorButton = uibutton(app.XYZpatternsTab, 'push');
            app.patterneditorButton.ButtonPushedFcn = createCallbackFcn(app, @patterneditorButtonPushed, true);
            app.patterneditorButton.Text = 'pattern editor';
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.GroupscanPanel = uipanel(app.ScanTab);
            app.GroupscanPanel.Title = 'Group scan';
            app.ImagingEOFcounterchantriggeredPanel = uipanel(app.GroupscanPanel);
            app.ImagingEOFcounterchantriggeredPanel.Title = 'Imaging (EOF counter chan triggered)';
            app.PatterngroupLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.PatterngroupLabel.Text = 'Pattern group';
            app.PatternDropDownCtr = uidropdown(app.ImagingEOFcounterchantriggeredPanel);
            app.PatternDropDownCtr.Items = {};
            app.PatternDropDownCtr.Value = {};
            app.NumVolumesEditFieldLabel = uilabel(app.ImagingEOFcounterchantriggeredPanel);
            app.NumVolumesEditFieldLabel.Text = 'Num Volumes';
            app.NumVolumesEditField = uieditfield(app.ImagingEOFcounterchantriggeredPanel, 'numeric');
            app.NumVolumesEditField.Limits = [1 Inf];
            app.NumVolumesEditField.Value = 1;
            app.InitializeimagingButton = uibutton(app.ImagingEOFcounterchantriggeredPanel, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.ImagingReadyLamp = uilamp(app.ImagingEOFcounterchantriggeredPanel);
            app.StimulationAI05Vnum_groupsPanel = uipanel(app.GroupscanPanel);
            app.StimulationAI05Vnum_groupsPanel.Title = 'Stimulation (AI (0-5V)/num_groups)';
            app.PatterngroupLabel_2 = uilabel(app.StimulationAI05Vnum_groupsPanel);
            app.PatterngroupLabel_2.Text = 'Pattern group';
            app.PatternDropDownAI = uidropdown(app.StimulationAI05Vnum_groupsPanel);
            app.PatternDropDownAI.Items = {};
            app.PatternDropDownAI.Value = {};
            app.precompatterncombosButtonGroup = uibuttongroup(app.StimulationAI05Vnum_groupsPanel);
            app.precompatterncombosButtonGroup.Tooltip = {'All - precompute all patterns combinations, may take some time and ram if a lot of patterns'; 'EOF only - only precomputes planes and stim patterns separately, and combines them on the fly. This is less computation involved but adds ~1.5ms lag during update of AI stim patterns'};
            app.precompatterncombosButtonGroup.Title = 'precom pattern combos';
            app.AllButton = uitogglebutton(app.precompatterncombosButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.EOFonlyButton = uitogglebutton(app.precompatterncombosButtonGroup);
            app.EOFonlyButton.Text = 'EOF only';
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram phase preview')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.UIAxesColorLimits = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesColorLimits, 'color limit (pi)')
            zlabel(app.UIAxesColorLimits, 'Z')
            app.UIAxesColorLimits.YTick = [];
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.PistonTab = uitab(app.TabGroup2);
            app.PistonTab.Title = 'Piston';
            app.GeneratepistonButton = uibutton(app.PistonTab, 'push');
            app.GeneratepistonButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GeneratepistonButton.Text = 'Generate piston';
            app.PixelValueLabel = uilabel(app.PistonTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.PistonTab, 'numeric');
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Blazed';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 127;
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.BlazReverseCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazReverseCheckBox.Text = 'Reverse direction';
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Stripes';
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 127;
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.GeneratedefocusButton = uibutton(app.DefocusTab, 'push');
            app.GeneratedefocusButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedefocusButtonPushed, true);
            app.GeneratedefocusButton.Text = 'Generate defocus';
            app.DefocusdistumLabel = uilabel(app.DefocusTab);
            app.DefocusdistumLabel.Text = 'Defocus dist (um)';
            app.DeficusWeightEditField = uieditfield(app.DefocusTab, 'numeric');
            app.CrossTab = uitab(app.TabGroup2);
            app.CrossTab.Title = 'Cross';
            app.GenerateCrossButton = uibutton(app.CrossTab, 'push');
            app.GenerateCrossButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCrossButtonPushed, true);
            app.GenerateCrossButton.Text = 'Generate cross';
            app.PixelValueEditFieldLabel_2 = uilabel(app.CrossTab);
            app.PixelValueEditFieldLabel_2.Text = 'Pixel Value';
            app.CrossPixelValueEditField = uieditfield(app.CrossTab, 'numeric');
            app.GrayEditFieldLabel_2 = uilabel(app.CrossTab);
            app.GrayEditFieldLabel_2.Text = 'Gray';
            app.CrossGrayEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossGrayEditField.Value = 127;
            app.AngleradLabel = uilabel(app.CrossTab);
            app.AngleradLabel.Text = 'Angle (rad)';
            app.CrossAngleEditField = uieditfield(app.CrossTab, 'numeric');
            app.CrossAngleEditField.Value = 0.7854;
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Fresnel';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterXEditField.Value = 960;
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.ZernikeTab = uitab(app.TabGroup2);
            app.ZernikeTab.Title = 'Zernike';
            app.GenerateZernikeButton = uibutton(app.ZernikeTab, 'push');
            app.GenerateZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateZernikeButtonPushed, true);
            app.GenerateZernikeButton.Text = 'Generate Zernike';
            app.CenterXEditField_2Label = uilabel(app.ZernikeTab);
            app.CenterXEditField_2Label.Text = 'CenterX';
            app.CenterXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterYEditField_5Label = uilabel(app.ZernikeTab);
            app.CenterYEditField_5Label.Text = 'CenterY';
            app.CenterYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.RadiusEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.RadiusEditFieldLabel_2.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PistonEditFieldLabel = uilabel(app.ZernikeTab);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.PistonEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltXEditFieldLabel.Text = 'TiltX';
            app.TiltXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltYEditFieldLabel.Text = 'TiltY';
            app.TiltYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PowerEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.PowerEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigXEditFieldLabel.Text = 'AstigX';
            app.AstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigYEditFieldLabel.Text = 'AstigY';
            app.AstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaXEditFieldLabel.Text = 'ComaX';
            app.ComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaYEditFieldLabel.Text = 'ComaY';
            app.ComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PrimarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.PrimarySphericalEditFieldLabel.Text = 'PrimarySpherical';
            app.PrimarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilXEditFieldLabel.Text = 'TrefoilX';
            app.TrefoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilYEditFieldLabel.Text = 'TrefoilY';
            app.TrefoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigXEditFieldLabel.Text = 'SecondaryAstigX';
            app.SecondaryAstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigYEditFieldLabel.Text = 'SecondaryAstigY';
            app.SecondaryAstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaXEditFieldLabel.Text = 'SecondaryComaX';
            app.SecondaryComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaYEditFieldLabel.Text = 'SecondaryComaY';
            app.SecondaryComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondarySphericalEditFieldLabel.Text = 'SecondarySpherical';
            app.SecondarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilXEditFieldLabel.Text = 'TetrafoilX';
            app.TetrafoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilYEditFieldLabel.Text = 'TetrafoilY';
            app.TetrafoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TertiarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.TertiarySphericalEditFieldLabel.Text = 'TertiarySpherical';
            app.TertiarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.QuaternarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.QuaternarySphericalEditFieldLabel.Text = 'QuaternarySpherical';
            app.QuaternarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CustomphaseTab = uitab(app.TabGroup2);
            app.CustomphaseTab.Title = 'Custom phase';
            app.BrowseimageButton = uibutton(app.CustomphaseTab, 'push');
            app.BrowseimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseimageButtonPushed, true);
            app.BrowseimageButton.Text = 'Browse image';
            app.ImagepathEditFieldLabel = uilabel(app.CustomphaseTab);
            app.ImagepathEditFieldLabel.Text = 'Image path';
            app.ImagepathEditField = uieditfield(app.CustomphaseTab, 'text');
            app.PhaseformatDropDownLabel = uilabel(app.CustomphaseTab);
            app.PhaseformatDropDownLabel.Text = 'Phase format';
            app.PhaseformatDropDown = uidropdown(app.CustomphaseTab);
            app.PhaseformatDropDown.Items = {'Preprocessed to uint8', 'Radians'};
            app.PhaseformatDropDown.Value = 'Preprocessed to uint8';
            app.LoadimageButton = uibutton(app.CustomphaseTab, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.AdaptiveOpticsmodalTab = uitab(app.TabGroup);
            app.AdaptiveOpticsmodalTab.Title = 'Adaptive Optics modal';
            app.ZernikeListTable = uitable(app.AdaptiveOpticsmodalTab);
            app.ZernikeListTable.ColumnName = {'idx'; 'Zn'; 'Zm'; 'W - lim'; 'W step'; 'W + lim'; 'Include'};
            app.ZernikeListTable.RowName = {};
            app.ZernikeListTable.ColumnEditable = [false false false true true true true];
            app.ZernikeListTable.CellEditCallback = createCallbackFcn(app, @ZernikeListTableCellEdit, true);
            app.ZernikeModestoscanadjustweightsifneededLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.ZernikeModestoscanadjustweightsifneededLabel.Text = 'Zernike Modes to scan (adjust weights if needed)';
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.AOregularscanzernikepolPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOregularscanzernikepolPanel.Title = 'AO regular scan zernike pol';
            app.ZernikeReadyLamp = uilamp(app.AOregularscanzernikepolPanel);
            app.ScanZernikeButton = uibutton(app.AOregularscanzernikepolPanel, 'state');
            app.ScanZernikeButton.ValueChangedFcn = createCallbackFcn(app, @ScanZernikeButtonValueChanged, true);
            app.ScanZernikeButton.Text = 'Scan Zernike';
            app.NumscanvolumesEditFieldLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanvolumesEditFieldLabel.Text = 'Num scan volumes';
            app.NumscanvolumesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanvolumesEditField.Limits = [0 Inf];
            app.NumscanvolumesEditField.RoundFractionalValues = 'on';
            app.NumscanvolumesEditField.Editable = 'off';
            app.NumscanframesLabel = uilabel(app.AOregularscanzernikepolPanel);
            app.NumscanframesLabel.Text = 'Num scan frames';
            app.NumscanframesEditField = uieditfield(app.AOregularscanzernikepolPanel, 'numeric');
            app.NumscanframesEditField.Limits = [0 Inf];
            app.NumscanframesEditField.RoundFractionalValues = 'on';
            app.NumscanframesEditField.Editable = 'off';
            app.FillzerniketablePanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.FillzerniketablePanel.Title = 'Fill zernike table';
            app.FillmodestableButton = uibutton(app.FillzerniketablePanel, 'push');
            app.FillmodestableButton.ButtonPushedFcn = createCallbackFcn(app, @FillmodestableButtonPushed, true);
            app.FillmodestableButton.Text = 'Fill modes table';
            app.MaxmodesEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MaxmodesEditFieldLabel.Text = 'Max modes';
            app.MaxmodesEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.MaxmodesEditField.Value = 4;
            app.WeightlimitEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightlimitEditFieldLabel.Text = 'Weight limit';
            app.WeightlimitEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightlimitEditField.Value = 1.5;
            app.WeightstepEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightstepEditFieldLabel.Text = 'Weight step';
            app.WeightstepEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightstepEditField.Value = 0.25;
            app.AOignoredefocusmodesCheckBox = uicheckbox(app.FillzerniketablePanel);
            app.AOignoredefocusmodesCheckBox.Text = 'AO ignore defocus modes';
            app.AOautooptimizationscanPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOautooptimizationscanPanel.Title = 'AO auto optimization scan';
            app.StartoptimizationscanButton = uibutton(app.AOautooptimizationscanPanel, 'push');
            app.StartoptimizationscanButton.ButtonPushedFcn = createCallbackFcn(app, @StartoptimizationscanButtonPushed, true);
            app.StartoptimizationscanButton.Text = 'Start optimization scan';
            app.NumiterationsSpinnerLabel = uilabel(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinnerLabel.Text = 'Num iterations';
            app.NumiterationsSpinner = uispinner(app.AOautooptimizationscanPanel);
            app.NumiterationsSpinner.Limits = [1 Inf];
            app.NumiterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumiterationsSpinnerValueChanged, true);
            app.NumiterationsSpinner.Value = 5;
            app.PlotprogressCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotprogressCheckBox.Text = 'Plot progress';
            app.PlotprogressCheckBox.Value = true;
            app.ScanframesdirpathEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.ScanframesdirpathEditFieldLabel.Text = 'Scan frames dir path';
            app.ScanframesdirpathEditField = uieditfield(app.AOautooptimizationscanPanel, 'text');
            app.ScanframesdirpathEditField.Value = '\\PRAIRIE2000\p2f\Yuriy\AO\';
            app.NumscanframesLabel_2 = uilabel(app.AOautooptimizationscanPanel);
            app.NumscanframesLabel_2.Text = 'Num scan frames';
            app.NumscanframesOpEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.NumscanframesOpEditField.Limits = [0 Inf];
            app.NumscanframesOpEditField.RoundFractionalValues = 'on';
            app.NumscanframesOpEditField.Editable = 'off';
            app.PlotextradeetsCheckBox = uicheckbox(app.AOautooptimizationscanPanel);
            app.PlotextradeetsCheckBox.Text = 'Plot extra deets';
            app.BeadwindowsizeEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.BeadwindowsizeEditFieldLabel.Text = 'Bead window size';
            app.BeadwindowsizeEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.BeadwindowsizeEditField.Value = 80;
            app.PostscandelayEditFieldLabel = uilabel(app.AOautooptimizationscanPanel);
            app.PostscandelayEditFieldLabel.Text = 'Post scan delay';
            app.PostscandelayEditField = uieditfield(app.AOautooptimizationscanPanel, 'numeric');
            app.PostscandelayEditField.Tooltip = {'0.5 for 16fr averaging; 0.8 for 32fr ave'};
            app.PostscandelayEditField.Value = 0.8;
            app.ParamsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.ParamsPanel.Title = 'Params';
            app.SavefiletagEditFieldLabel = uilabel(app.ParamsPanel);
            app.SavefiletagEditFieldLabel.Text = 'Save file tag';
            app.SavefiletagEditField = uieditfield(app.ParamsPanel, 'text');
            app.SavefiletagEditField.Value = 'zernike_scan_data';
            app.ShufflemodesCheckBox = uicheckbox(app.ParamsPanel);
            app.ShufflemodesCheckBox.Text = 'Shuffle modes';
            app.ShufflemodesCheckBox.Value = true;
            app.ScanspermodeEditFieldLabel = uilabel(app.ParamsPanel);
            app.ScanspermodeEditFieldLabel.Text = 'Scans per mode';
            app.ScanspermodeEditField = uieditfield(app.ParamsPanel, 'numeric');
            app.ScanspermodeEditField.Limits = [1 Inf];
            app.ScanspermodeEditField.ValueChangedFcn = createCallbackFcn(app, @ScanspermodeEditFieldValueChanged, true);
            app.ScanspermodeEditField.Value = 2;
            app.ScansperVolZEditFieldLabel = uilabel(app.ParamsPanel);
            app.ScansperVolZEditFieldLabel.Text = 'Scans per Vol (Z)';
            app.ScansperVolZEditField = uieditfield(app.ParamsPanel, 'numeric');
            app.ScansperVolZEditField.Limits = [1 Inf];
            app.ScansperVolZEditField.RoundFractionalValues = 'on';
            app.ScansperVolZEditField.ValueChangedFcn = createCallbackFcn(app, @ScansperVolZEditFieldValueChanged, true);
            app.ScansperVolZEditField.Value = 1;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.LUTcalibrationdataPanel = uipanel(app.CalibrationTab);
            app.LUTcalibrationdataPanel.Enable = 'off';
            app.LUTcalibrationdataPanel.Title = 'LUT calibration data';
            app.LUTcalibrationdataPanel.Visible = 'off';
            app.BitDepthEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BitDepthEditFieldLabel.Text = 'Bit Depth';
            app.BitDepthEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BitDepthEditField.Limits = [1 256];
            app.BitDepthEditField.RoundFractionalValues = 'on';
            app.BitDepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitDepthEditFieldValueChanged, true);
            app.BitDepthEditField.Value = 256;
            app.NumRegionsEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.NumRegionsEditFieldLabel.Text = 'Num Regions';
            app.NumRegionsEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.NumRegionsEditField.Limits = [1 Inf];
            app.NumRegionsEditField.RoundFractionalValues = 'on';
            app.NumRegionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumRegionsEditFieldValueChanged, true);
            app.NumRegionsEditField.Value = 1;
            app.PixelsPerStripeEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.PixelsPerStripeEditFieldLabel.Text = 'Pixels Per Stripe';
            app.PixelsPerStripeEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.PixelsPerStripeEditField.Limits = [1 Inf];
            app.PixelsPerStripeEditField.Value = 8;
            app.AnalysissavefilenameEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.AnalysissavefilenameEditFieldLabel.Text = 'Analysis save file name';
            app.AnalysissavefilenameEditField = uieditfield(app.LUTcalibrationdataPanel, 'text');
            app.AnalysissavefilenameEditField.Value = '940_slm5221_maitai2';
            app.RunLUTcalibrationButton = uibutton(app.LUTcalibrationdataPanel, 'state');
            app.RunLUTcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunLUTcalibrationButtonValueChanged, true);
            app.RunLUTcalibrationButton.Text = 'Run LUT calibration';
            app.AnalyzeLUTdataButton = uibutton(app.LUTcalibrationdataPanel, 'push');
            app.AnalyzeLUTdataButton.Text = 'Analyze LUT data';
            app.RunLUTcalibReadyLamp = uilamp(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel.Text = 'Baseline pixel value';
            app.BaselinepixelvalueEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BaselinepixelvalueEditField.Limits = [0 255];
            app.BaselinepixelvalueEditField.RoundFractionalValues = 'on';
            app.TotalframesEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.TotalframesEditFieldLabel.Text = 'Total frames';
            app.TotalframesEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.TotalframesEditField.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.LUTcalibrationdataPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.EndofframeButton = uitogglebutton(app.ButtonGroup);
            app.EndofframeButton.Text = 'End of frame';
            app.EndofframeButton.Value = true;
            app.TLDCcamButton = uitogglebutton(app.ButtonGroup);
            app.TLDCcamButton.Text = 'TLDC cam';
            app.LateralcalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.LateralcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LateralcalibrationButtonPushed, true);
            app.LateralcalibrationButton.Text = 'Lateral calibration';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.InitializeDAQButton = uibutton(app.Panel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.InitializeDAQLamp = uilamp(app.Panel);
            app.BlankSLMButton = uibutton(app.Panel, 'push');
            app.BlankSLMButton.ButtonPushedFcn = createCallbackFcn(app, @BlankSLMButtonPushed, true);
            app.BlankSLMButton.Text = 'Blank SLM';
            app.ApplyAOcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyAOcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyAOcorrectionButtonValueChanged, true);
            app.ApplyAOcorrectionButton.Text = {'Apply AO'; 'correction'};
            app.InitializeAOLamp = uilamp(app.Panel);
            app.refimSLMButton = uibutton(app.Panel, 'push');
            app.refimSLMButton.ButtonPushedFcn = createCallbackFcn(app, @refimSLMButtonPushed, true);
            app.refimSLMButton.Text = 'ref im SLM';
            app.XYoffsetButton = uibutton(app.Panel, 'push');
            app.XYoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @XYoffsetButtonPushed, true);
            app.XYoffsetButton.Text = 'XY offset';
            app.ApplyXYZcalibrationButton = uibutton(app.Panel, 'state');
            app.ApplyXYZcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @ApplyXYZcalibrationButtonValueChanged, true);
            app.ApplyXYZcalibrationButton.Text = {'Apply XYZ'; 'calibration'};
            app.XYZcalibrationLamp = uilamp(app.Panel);
            app.ViewcurrentholoButton = uibutton(app.Panel, 'push');
            app.ViewcurrentholoButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentholoButtonPushed, true);
            app.ViewcurrentholoButton.Text = 'View current holo';
            app.ViewcurrentAOwfButton = uibutton(app.Panel, 'push');
            app.ViewcurrentAOwfButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcurrentAOwfButtonPushed, true);
            app.ViewcurrentAOwfButton.Text = 'View current AO wf';
            app.CurrentregionDropDownLabel = uilabel(app.Panel);
            app.CurrentregionDropDownLabel.Text = 'Current region';
            app.CurrentregionDropDown = uidropdown(app.Panel);
            app.CurrentregionDropDown.Items = {};
            app.CurrentregionDropDown.Value = {};
            app.SavecurrentholoButton = uibutton(app.Panel, 'push');
            app.SavecurrentholoButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurrentholoButtonPushed, true);
            app.SavecurrentholoButton.Text = 'Save current holo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_control_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end