classdef sg_pattern_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        rightclickremoveButton     matlab.ui.control.StateButton
        leftclickaddButton         matlab.ui.control.StateButton
        ZdepthSpinner              matlab.ui.control.Spinner
        ZdepthSpinnerLabel         matlab.ui.control.Label
        UpdateplotButton           matlab.ui.control.Button
        InstructionsTextArea       matlab.ui.control.TextArea
        InstructionsTextAreaLabel  matlab.ui.control.Label
        imagedirEditField          matlab.ui.control.EditField
        imagedirEditFieldLabel     matlab.ui.control.Label
        loadimagesButton           matlab.ui.control.Button
        patternDropDown            matlab.ui.control.DropDown
        patternDropDownLabel       matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        app_main; % Description
        data;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_main)
            app.app_main = app_main;
            f_sg_pp_initialize(app);
        end

        % Button pushed function: loadimagesButton
        function loadimagesButtonPushed(app, event)
            f_sg_pp_load_images(app);
        end

        % Value changed function: patternDropDown
        function patternDropDownValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Value changed function: ZdepthSpinner
        function ZdepthSpinnerValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            f_sg_pp_button_down(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.patternDropDownLabel = uilabel(app.UIFigure);
            app.patternDropDownLabel.Text = 'pattern';
            app.patternDropDown = uidropdown(app.UIFigure);
            app.patternDropDown.Items = {};
            app.patternDropDown.ValueChangedFcn = createCallbackFcn(app, @patternDropDownValueChanged, true);
            app.patternDropDown.Value = {};
            app.loadimagesButton = uibutton(app.UIFigure, 'push');
            app.loadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @loadimagesButtonPushed, true);
            app.loadimagesButton.Text = 'load images';
            app.imagedirEditFieldLabel = uilabel(app.UIFigure);
            app.imagedirEditFieldLabel.Text = 'image dir';
            app.imagedirEditField = uieditfield(app.UIFigure, 'text');
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Value = {'Can load 2p fov image to overlay with pattern'};
            app.UpdateplotButton = uibutton(app.UIFigure, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = 'Update plot';
            app.ZdepthSpinnerLabel = uilabel(app.UIFigure);
            app.ZdepthSpinnerLabel.Text = 'Z depth';
            app.ZdepthSpinner = uispinner(app.UIFigure);
            app.ZdepthSpinner.Step = 10;
            app.ZdepthSpinner.ValueChangedFcn = createCallbackFcn(app, @ZdepthSpinnerValueChanged, true);
            app.leftclickaddButton = uibutton(app.UIFigure, 'state');
            app.leftclickaddButton.Text = 'left click add';
            app.leftclickaddButton.Value = true;
            app.rightclickremoveButton = uibutton(app.UIFigure, 'state');
            app.rightclickremoveButton.Text = 'right click remove';
            app.rightclickremoveButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_pattern_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end