classdef SLM_LUT_calibration_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LUTCalibrationTab             matlab.ui.container.Tab
        LiveButton                    matlab.ui.control.StateButton
        PixelValueEditField           matlab.ui.control.NumericEditField
        PixelValueEditFieldLabel      matlab.ui.control.Label
        GrayEditField                 matlab.ui.control.NumericEditField
        GrayEditFieldLabel            matlab.ui.control.Label
        SLMupdateButton               matlab.ui.control.Button
        PixelperstripeEditField       matlab.ui.control.NumericEditField
        PixelperstripeEditFieldLabel  matlab.ui.control.Label
        NumberregionsEditField        matlab.ui.control.NumericEditField
        NumberregionsEditFieldLabel   matlab.ui.control.Label
        PixeldepthEditField           matlab.ui.control.NumericEditField
        PixeldepthEditFieldLabel      matlab.ui.control.Label
        GetframeButton                matlab.ui.control.Button
        UIAxesHologramPhase           matlab.ui.control.UIAxes
        UIAxesCameraFrame             matlab.ui.control.UIAxes
        AnalyseLUTTab                 matlab.ui.container.Tab
        ParametersTab                 matlab.ui.container.Tab
        MEXpathEditField              matlab.ui.control.EditField
        MEXpathEditFieldLabel         matlab.ui.control.Label
        CamGainEditField              matlab.ui.control.NumericEditField
        CamGainEditFieldLabel         matlab.ui.control.Label
        windowheightEditField         matlab.ui.control.NumericEditField
        windowheightEditFieldLabel    matlab.ui.control.Label
        windowwidthEditField          matlab.ui.control.NumericEditField
        windowwidthEditFieldLabel     matlab.ui.control.Label
        windowstartNEditField         matlab.ui.control.NumericEditField
        windowstartNEditFieldLabel    matlab.ui.control.Label
        windowstartMEditField         matlab.ui.control.NumericEditField
        windowstartMEditFieldLabel    matlab.ui.control.Label
        PixelclockMHzEditField        matlab.ui.control.NumericEditField
        PixelclockMHzEditFieldLabel   matlab.ui.control.Label
        FramespersecEditField         matlab.ui.control.NumericEditField
        FramespersecEditFieldLabel    matlab.ui.control.Label
        ExposuretimemsEditField       matlab.ui.control.NumericEditField
        ExposuretimemsEditFieldLabel  matlab.ui.control.Label
        ThorlabsparametersLabel       matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel    matlab.ui.control.Label
        SLMwidthEditField             matlab.ui.control.NumericEditField
        SLMwidthEditFieldLabel        matlab.ui.control.Label
        SLMheightEditField            matlab.ui.control.NumericEditField
        SLMheightEditFieldLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        InitCamButton                 matlab.ui.control.StateButton
        InitCamLamp                   matlab.ui.control.Lamp
        ActivateSLMButton             matlab.ui.control.StateButton
        ActivateSLMLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        SLM_ops; % Description
        SLM_image_pointer;
        SLM_image;
        SLM_phase_plot;
        cam_handle;
        cam_im;
        cam_act;
        cam_im_plot;
    end
    
    methods (Access = private)
        
        function if_initialize_GUI_params(app)
            app.SLMheightEditField.Value = app.SLM_ops.sdkObj.height;
            app.SLMwidthEditField.Value = app.SLM_ops.sdkObj.width;
            app.SLM_image = zeros(app.SLM_ops.sdkObj.height, app.SLM_ops.sdkObj.width, 'uint8');
            app.SLM_phase_plot = imagesc(app.UIAxesHologramPhase, app.SLM_image);
            axis(app.UIAxesHologramPhase, 'tight');
            caxis(app.UIAxesHologramPhase, [0 255]);
            
            app.cam_im_plot = imagesc(app.UIAxesCameraFrame, app.cam_im');
            axis(app.UIAxesCameraFrame, 'tight');
        end
        
        
        function if_cam_initialize(app)
            % Thorlabs Camera 1024x1280 pixels
            cam_params.TLCAM_exptm = app.ExposuretimemsEditField.Value;
            cam_params.TLCAM_fps = app.FramespersecEditField.Value;
            cam_params.TLCAM_pxlclock = app.PixelclockMHzEditField.Value;
            % select pixels
            cam_params.TLCAM_win_start_M = app.windowstartMEditField.Value;
            cam_params.TLCAM_win_start_N = app.windowstartNEditField.Value;
            cam_params.TLCAM_win_Width = app.windowwidthEditField.Value;
            cam_params.TLCAM_win_Height = app.windowheightEditField.Value;
            cam_params.TLCAM_gain = app.CamGainEditField.Value;
            cam_params.path_TLCAM_MEX = app.MEXpathEditField.Value;
            addpath(cam_params.path_TLCAM_MEX);
            
            [app.cam_handle, app.cam_im, app.cam_act] = f_TLDC_Cam_Init_YS(cam_params);
        end
        
        function if_plot_SLM_image(app)
            app.SLM_image = rot90(mod(app.SLM_image, 256));    
            app.SLM_phase_plot.CData = app.SLM_image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.SLM_ops = f_SLM_initialize_ops();
                app.SLM_ops = f_SLM_initialize(app.SLM_ops);
                app.SLM_image_pointer = libpointer('uint8Ptr', zeros(app.SLM_ops.sdkObj.width*app.SLM_ops.sdkObj.height,1));
                app.ActivateSLMButton.Value = 1;
                app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
            catch
                fprintf('Failed to initialize SLM\n');
            end
            
            try
                if_cam_initialize(app);
                app.InitCamButton.Value = 1;
                app.InitCamLamp.Color = [0.00,1.00,0.00];
                fprintf('Camera Initialized\n');
            catch
                fprintf('Failed to initialize Camera\n');
            end
            
            
            if_initialize_GUI_params(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SLM_ops = f_SLM_close(app.SLM_ops);
            
            if app.InitCamButton.Value
                try
                    TLDC_set_Cam_Close(app.cam_handle);
                    fprintf('Camera closed\n');
                catch 
                    fprintf('Camera closing error\n');
                end            
            end
            
            delete(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            if app.ActivateSLMButton.Value
                app.SLM_ops = f_SLM_initialize(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
            else
                app.SLM_ops = f_SLM_close(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Value changed function: InitCamButton
        function InitCamButtonValueChanged(app, event)
            if app.InitCamButton.Value
                try
                    if_cam_initialize(app)
                    app.InitCamLamp.Color = [0.00,1.00,0.00];
                    fprintf('Camera Initialized\n');
                catch
                    app.InitCamButton.Value = 0;
                    fprintf('Failed to initialize Camera\n');
                end
            else
                try
                    TLDC_set_Cam_Close(app.cam_handle);
                    app.InitCamLamp.Color = [0.80,0.80,0.80];
                    fprintf('Camera closed\n');
                catch
                    app.InitCamButton.Value = 1;
                    fprintf('Camera closing error\n');
                end
            end
            
        end

        % Button pushed function: GetframeButton
        function GetframeButtonPushed(app, event)
            if app.InitCamButton.Value
                TLDC_get_Cam_Im(app.cam_handle);
                app.cam_im_plot.CData = app.cam_im';
                %imagesc(app.UIAxesCameraFrame, app.cam_im');
            else
                fprintf('Initialize Camera first\n');
            end
        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
            if app.InitCamButton.Value
                while app.LiveButton.Value
                    TLDC_get_Cam_Im(app.cam_handle);
                    app.cam_im_plot.CData = app.cam_im';
                    drawnow;
                end
            else
                app.LiveButton.Value = 0;
                fprintf('Initialize Camera first\n');
            end
            
        end

        % Button pushed function: SLMupdateButton
        function SLMupdateButtonPushed(app, event)
            calllib('ImageGen', 'Generate_Stripe', app.SLM_image_pointer, app.SLM_ops.sdkObj.width, app.SLM_ops.sdkObj.height,...
                        app.PixelValueEditField.Value, app.GrayEditField.Value, app.PixelperstripeEditField.Value);
            app.SLM_Image = reshape(app.SLM_image_pointer.Value, [app.SLM_ops.sdkObj.width,app.SLM_ops.sdkObj.height]);
            if_plot_SLM_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.InitCamLamp = uilamp(app.Panel);
            app.InitCamButton = uibutton(app.Panel, 'state');
            app.InitCamButton.ValueChangedFcn = createCallbackFcn(app, @InitCamButtonValueChanged, true);
            app.InitCamButton.Text = 'Init Cam';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LUTCalibrationTab = uitab(app.TabGroup);
            app.LUTCalibrationTab.Title = 'LUT Calibration';
            app.UIAxesCameraFrame = uiaxes(app.LUTCalibrationTab);
            title(app.UIAxesCameraFrame, 'Camera frame')
            xlabel(app.UIAxesCameraFrame, 'X')
            ylabel(app.UIAxesCameraFrame, 'Y')
            app.UIAxesHologramPhase = uiaxes(app.LUTCalibrationTab);
            title(app.UIAxesHologramPhase, 'Hologram Phase')
            xlabel(app.UIAxesHologramPhase, 'X')
            ylabel(app.UIAxesHologramPhase, 'Y')
            app.GetframeButton = uibutton(app.LUTCalibrationTab, 'push');
            app.GetframeButton.ButtonPushedFcn = createCallbackFcn(app, @GetframeButtonPushed, true);
            app.GetframeButton.Text = 'Get frame';
            app.PixeldepthEditFieldLabel = uilabel(app.LUTCalibrationTab);
            app.PixeldepthEditFieldLabel.Text = 'Pixel depth';
            app.PixeldepthEditField = uieditfield(app.LUTCalibrationTab, 'numeric');
            app.PixeldepthEditField.Value = 256;
            app.NumberregionsEditFieldLabel = uilabel(app.LUTCalibrationTab);
            app.NumberregionsEditFieldLabel.Text = 'Number regions';
            app.NumberregionsEditField = uieditfield(app.LUTCalibrationTab, 'numeric');
            app.NumberregionsEditField.Value = 1;
            app.PixelperstripeEditFieldLabel = uilabel(app.LUTCalibrationTab);
            app.PixelperstripeEditFieldLabel.Text = 'Pixel per stripe';
            app.PixelperstripeEditField = uieditfield(app.LUTCalibrationTab, 'numeric');
            app.PixelperstripeEditField.Value = 8;
            app.SLMupdateButton = uibutton(app.LUTCalibrationTab, 'push');
            app.SLMupdateButton.ButtonPushedFcn = createCallbackFcn(app, @SLMupdateButtonPushed, true);
            app.SLMupdateButton.Text = 'SLM update';
            app.GrayEditFieldLabel = uilabel(app.LUTCalibrationTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.GrayEditField = uieditfield(app.LUTCalibrationTab, 'numeric');
            app.GrayEditField.Value = 255;
            app.PixelValueEditFieldLabel = uilabel(app.LUTCalibrationTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.PixelValueEditField = uieditfield(app.LUTCalibrationTab, 'numeric');
            app.LiveButton = uibutton(app.LUTCalibrationTab, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Text = 'Live';
            app.AnalyseLUTTab = uitab(app.TabGroup);
            app.AnalyseLUTTab.Title = 'Analyse LUT';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.SLMheightEditField.Value = 1152;
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMwidthEditField.Value = 1920;
            app.WavelengthnmEditFieldLabel = uilabel(app.ParametersTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WavelengthnmEditField.Value = 1064;
            app.ThorlabsparametersLabel = uilabel(app.ParametersTab);
            app.ThorlabsparametersLabel.Text = 'Thorlabs parameters';
            app.ExposuretimemsEditFieldLabel = uilabel(app.ParametersTab);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time (ms)';
            app.ExposuretimemsEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ExposuretimemsEditField.Value = 50;
            app.FramespersecEditFieldLabel = uilabel(app.ParametersTab);
            app.FramespersecEditFieldLabel.Text = 'Frames per sec';
            app.FramespersecEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FramespersecEditField.Value = 19.78;
            app.PixelclockMHzEditFieldLabel = uilabel(app.ParametersTab);
            app.PixelclockMHzEditFieldLabel.Text = 'Pixel clock (MHz)';
            app.PixelclockMHzEditField = uieditfield(app.ParametersTab, 'numeric');
            app.PixelclockMHzEditField.Value = 34;
            app.windowstartMEditFieldLabel = uilabel(app.ParametersTab);
            app.windowstartMEditFieldLabel.Text = 'window start M';
            app.windowstartMEditField = uieditfield(app.ParametersTab, 'numeric');
            app.windowstartNEditFieldLabel = uilabel(app.ParametersTab);
            app.windowstartNEditFieldLabel.Text = 'window start N';
            app.windowstartNEditField = uieditfield(app.ParametersTab, 'numeric');
            app.windowwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.windowwidthEditFieldLabel.Text = 'window width';
            app.windowwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.windowwidthEditField.Value = 1280;
            app.windowheightEditFieldLabel = uilabel(app.ParametersTab);
            app.windowheightEditFieldLabel.Text = 'window height';
            app.windowheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.windowheightEditField.Value = 1024;
            app.CamGainEditFieldLabel = uilabel(app.ParametersTab);
            app.CamGainEditFieldLabel.Text = 'Cam Gain';
            app.CamGainEditField = uieditfield(app.ParametersTab, 'numeric');
            app.CamGainEditField.Value = 1;
            app.MEXpathEditFieldLabel = uilabel(app.ParametersTab);
            app.MEXpathEditFieldLabel.Text = 'MEX path';
            app.MEXpathEditField = uieditfield(app.ParametersTab, 'text');
            app.MEXpathEditField.Value = 'C:\Users\ys2605\Desktop\SLM stuff\Prairie_2_scratch\MEX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_LUT_calibration_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end