classdef plot_z_stack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Spinner      matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        planes = [];
        z_offsets = [];
        num_planes = [];
        curr_slice = 1;
    end
    
    methods (Access = private)
        
        function update_fig(app)
            app.Spinner.Value = app.curr_slice;
            app.Slider.Value = app.curr_slice;
            imagesc(app.UIAxes, app.planes(:,:,app.curr_slice));
            axis(app.UIAxes, 'tight');
            title(app.UIAxes, sprintf('%f.0um', app.z_offsets(app.curr_slice)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, planes, z_offsets)
            app.planes = planes;
            app.num_planes = size(app.planes,3);
            
%             if exist('z_offsets', 'var')
%                 app.z_offsets = z_offsets;
%             else
%                 app.z_offsets = 1:size(app.planes,3);
%             end
             
            
            app.Spinner.Limits = [1, app.num_planes];
            app.Slider.Limits = [1, app.num_planes];
            update_fig(app);


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.curr_slice = round(app.Slider.Value);
            update_fig(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.curr_slice = round(app.Spinner.Value);
            update_fig(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_z_stack(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end